classdef FMCApp_Airbus_Performance < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FMCMatlabUIFigure          matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        MCDU                       matlab.ui.container.Tab
        DELButton                  matlab.ui.control.Button
        WButton                    matlab.ui.control.Button
        RButton                    matlab.ui.control.Button
        MButton                    matlab.ui.control.Button
        HButton                    matlab.ui.control.Button
        CButton                    matlab.ui.control.Button
        SPButton                   matlab.ui.control.Button
        VButton                    matlab.ui.control.Button
        QButton                    matlab.ui.control.Button
        LButton                    matlab.ui.control.Button
        GButton                    matlab.ui.control.Button
        BButton                    matlab.ui.control.Button
        ZButton                    matlab.ui.control.Button
        UButton                    matlab.ui.control.Button
        PButton                    matlab.ui.control.Button
        KButton                    matlab.ui.control.Button
        FButton                    matlab.ui.control.Button
        AButton                    matlab.ui.control.Button
        Button_plusminus           matlab.ui.control.Button
        Button_9                   matlab.ui.control.Button
        Button_6                   matlab.ui.control.Button
        Button_3                   matlab.ui.control.Button
        Button_0                   matlab.ui.control.Button
        Button_dot                 matlab.ui.control.Button
        Button_8                   matlab.ui.control.Button
        Button_7                   matlab.ui.control.Button
        Button_5                   matlab.ui.control.Button
        Button_4                   matlab.ui.control.Button
        Button_2                   matlab.ui.control.Button
        Button_1                   matlab.ui.control.Button
        DOWNButton                 matlab.ui.control.Button
        RIGHTButton                matlab.ui.control.Button
        Button_10                  matlab.ui.control.Button
        UPButton                   matlab.ui.control.Button
        LEFTButton                 matlab.ui.control.Button
        AIRPORTButton              matlab.ui.control.Button
        Button_FWslash             matlab.ui.control.Button
        XButton                    matlab.ui.control.Button
        SButton                    matlab.ui.control.Button
        NButton                    matlab.ui.control.Button
        IButton                    matlab.ui.control.Button
        DButton                    matlab.ui.control.Button
        CLRButton                  matlab.ui.control.Button
        YButton                    matlab.ui.control.Button
        TButton                    matlab.ui.control.Button
        OButton                    matlab.ui.control.Button
        JButton                    matlab.ui.control.Button
        EButton                    matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        MCDUMENUButton             matlab.ui.control.Button
        ATCCOMMButton              matlab.ui.control.Button
        SECFPLNButton              matlab.ui.control.Button
        FUELPREDButton             matlab.ui.control.Button
        RADNAVButton               matlab.ui.control.Button
        FPLNButton                 matlab.ui.control.Button
        DATAButton                 matlab.ui.control.Button
        INITButton                 matlab.ui.control.Button
        PERFButton                 matlab.ui.control.Button
        PROGButton_2               matlab.ui.control.Button
        DIRButton                  matlab.ui.control.Button
        Label_22                   matlab.ui.control.Label
        Label_21                   matlab.ui.control.Label
        Label_20                   matlab.ui.control.Label
        Label_19                   matlab.ui.control.Label
        Label_16                   matlab.ui.control.Label
        Label_18                   matlab.ui.control.Label
        EditField_Scratch          matlab.ui.control.EditField
        Label_10                   matlab.ui.control.Label
        EditField_R_Scratch_Upper  matlab.ui.control.EditField
        EditField_M_Sratch_Upper   matlab.ui.control.EditField
        EditField_L_Scratch_Upper  matlab.ui.control.EditField
        Label_15                   matlab.ui.control.Label
        EditField_R6               matlab.ui.control.EditField
        EditField_M6               matlab.ui.control.EditField
        EditField_L6               matlab.ui.control.EditField
        Label_9                    matlab.ui.control.Label
        Label_14                   matlab.ui.control.Label
        EditField_R6_Upper         matlab.ui.control.EditField
        EditField_M6_Upper         matlab.ui.control.EditField
        EditField_L6_Upper         matlab.ui.control.EditField
        EditField_R5               matlab.ui.control.EditField
        EditField_M5               matlab.ui.control.EditField
        EditField_L5               matlab.ui.control.EditField
        R6_Button                  matlab.ui.control.Button
        R5_Button                  matlab.ui.control.Button
        L6_Button                  matlab.ui.control.Button
        L5_Button                  matlab.ui.control.Button
        Label_13                   matlab.ui.control.Label
        Label_8                    matlab.ui.control.Label
        EditField_R5_Upper         matlab.ui.control.EditField
        EditField_M5_Upper         matlab.ui.control.EditField
        EditField_L5_Upper         matlab.ui.control.EditField
        EditField_R4               matlab.ui.control.EditField
        EditField_M4               matlab.ui.control.EditField
        EditField_L4               matlab.ui.control.EditField
        Label_7                    matlab.ui.control.Label
        EditField_R4_Upper         matlab.ui.control.EditField
        EditField_M4_Upper         matlab.ui.control.EditField
        EditField_L4_Upper         matlab.ui.control.EditField
        Label_12                   matlab.ui.control.Label
        EditField_R3               matlab.ui.control.EditField
        EditField_M3               matlab.ui.control.EditField
        EditField_L3               matlab.ui.control.EditField
        Label_6                    matlab.ui.control.Label
        Label_17                   matlab.ui.control.Label
        EditField_R3_Upper         matlab.ui.control.EditField
        EditField_M3_Upper         matlab.ui.control.EditField
        EditField_L3_Upper         matlab.ui.control.EditField
        EditField_R2               matlab.ui.control.EditField
        EditField_M2               matlab.ui.control.EditField
        EditField_L2               matlab.ui.control.EditField
        R4_Button                  matlab.ui.control.Button
        R3_Button                  matlab.ui.control.Button
        R2_Button                  matlab.ui.control.Button
        R1_Button                  matlab.ui.control.Button
        L4_Button                  matlab.ui.control.Button
        L3_Button                  matlab.ui.control.Button
        L2_Button                  matlab.ui.control.Button
        L1_Button                  matlab.ui.control.Button
        Label_4                    matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        EditField_R2_Upper         matlab.ui.control.EditField
        EditField_M2_Upper         matlab.ui.control.EditField
        EditField_L2_Upper         matlab.ui.control.EditField
        EditField_R1               matlab.ui.control.EditField
        EditField_M1               matlab.ui.control.EditField
        EditField_L1               matlab.ui.control.EditField
        Label_3                    matlab.ui.control.Label
        EditField_R1_Upper         matlab.ui.control.EditField
        EditField_M1_Upper         matlab.ui.control.EditField
        EditField_L1_Upper         matlab.ui.control.EditField
        Label_2                    matlab.ui.control.Label
        EditField_Title            matlab.ui.control.EditField
        NavDisplay                 matlab.ui.container.Tab
        NavigationDisplay_VFT      matlab.ui.control.UIAxes
        NavigationDisplay_LFT      matlab.ui.control.UIAxes
        PerfDisplay                matlab.ui.container.Tab
        WeightDisplay              matlab.ui.control.UIAxes
        SpeedDisplay               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        dotNumber;
        enableDot = 0;
        integerNumber = 0;
        PageIndex = 0;
        PhaseIndex = 0;
        FPlanIndex = 0;
        WPTIndex = 0;
        PlusMinusIndex = 0;
        MCDUMenueIndex
        DataIndex = 0;
        R1
        
        % Constants
        KTS2MPS	= (1.852/3.6);
        MPS2KTS = (3.6/1.852);
        FT2M = 0.3048;
        M2FT = (1/0.3048);
        NM2M = 1852.0;
        M2NM = (1/1852.0);
        G_CONST	= 9.80665;
        DEG2RAD = (pi/180.0);
        RAD2DEG = (180.0/pi);
        
        approaches
        transitions
        stars
        arrival_rwys % Description
        app_types_full
        
        departure_rwys
        sids_rwys
        approach_wpts
        star_wpts
        transition_wpts
        rwy_wpts
        
        sids
        sid_wpts
        origin_filename
        destination_filename
        apptr_name
        apptr_rwys
        apptr_types
        
        rwys_llh
        table_fixes
        table_navaids
        
        % EditFields
        c  % Left Side
        cu % Left Side Upper
        m  % Middle
        mu % Middle Side Upper
        r  % Right Side
        ru % Right Side Upper
        
        
        % Flight plan
        all_wpts
        anzahl_all_wpts
        anzahl_sid_wpts
        flight_plan
        a % Available RWY for Depature
        b % Available SID
        d % Available RWY for Arrival
        e % Available STAR
        f % Available TRANSITION
        anzahl_RWY
        anzahl_SID
        anzahl_RWY_arrival
        anzahl_STAR
        anzahl_TRANS
        OrgDes_counter
        depature_RWY_selected
        SID_selected
        approach_selected
        STAR_selected
        TRANS_selected
        Counter = 0;
        
        % DATA Page
        aircraft
        aircraft_bada
        ac
        aircraft_selected
        aircraft_choice_index = 0;
        
        % INIT Page
        costindex
        FL
        Temperatur
        FLTemperatur
        origin
        destination
        input
        FLnumber
        IRScounter
        CoRoute
        origin_lat
        origin_lon
        
        % PERF Page
        v1
        vR
        v2
        trans_ALT
        THR_RED_ACC
        RWY
        shift
        Flaps_THS
        Flex_to_Temp
        Eng_out_Acc


        taxi
        ZFWCG_ZFW
        Block
        
        lat_wpts
        lon_wpts
        lat
        lon
    end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %   Get tables with navaids and fixes
            app.table_fixes = readtable('Waypoints.txt');
            app.table_navaids = readtable('Navaids.txt');
            
            %   Add the paths to teh subdirectories
            %addpath Version_0.4_Draft\Tools_GUI Version_0.4_Draft\Geodesics Version_0.4_Draft\Tools_AC
            addpath Aircraft Airports Geodesics Original References Tools_GUI Tools_AC
            
            disp('ADDED PATH');
            
            % Initialize EditFields
                % Left Side
                app.c{1} = app.EditField_L1;
                app.c{2} = app.EditField_L2;
                app.c{3} = app.EditField_L3;
                app.c{4} = app.EditField_L4;
                app.c{5} = app.EditField_L5;
                app.cu{1} = app.EditField_L1_Upper;
                app.cu{2} = app.EditField_L2_Upper;
                app.cu{3} = app.EditField_L3_Upper;
                app.cu{4} = app.EditField_L4_Upper;
                app.cu{5} = app.EditField_L5_Upper;
                % Middle 
                app.m{1} = app.EditField_M1;
                app.m{2} = app.EditField_M2;
                app.m{3} = app.EditField_M3;
                app.m{4} = app.EditField_M4;
                app.m{5} = app.EditField_M5;
                app.mu{1} = app.EditField_M1_Upper;
                app.mu{2} = app.EditField_M2_Upper;
                app.mu{3} = app.EditField_M3_Upper;
                app.mu{4} = app.EditField_M4_Upper;
                app.mu{5} = app.EditField_M5_Upper;
                % Right Side
                app.r{1} = app.EditField_R1;
                app.r{2} = app.EditField_R2;
                app.r{3} = app.EditField_R3;
                app.r{4} = app.EditField_R4;
                app.r{5} = app.EditField_R5;
                app.ru{1} = app.EditField_R1_Upper;
                app.ru{2} = app.EditField_R2_Upper;
                app.ru{3} = app.EditField_R3_Upper;
                app.ru{4} = app.EditField_R4_Upper;
                app.ru{5} = app.EditField_R5_Upper;
        end

        % Callback function
        function ButtonPushed(app, event)
            
        end

        % Button pushed function: L1_Button
        function L1_ButtonPushed(app, event)
            if app.DataIndex == 2
                
                % Clear the EditFields
                for i = 1:5
                    app.c{i}.Value = '';
                end
                app.EditField_R6_Upper.Value = '';
                
                % Show selected option in the Title
                app.aircraft_selected = app.aircraft_bada{1};
                app.aircraft_choice_index = 1;
                app.EditField_Title.Value = app.aircraft_selected;
                                
                %   Load all the data for the selected aicraft
                filename = ['Aircraft' filesep app.aircraft_selected '__.OPF'];
                app.ac = load_aircraft_bada(filename);
                
                app.cu{2}.Value = ' ENG';
                app.cu{3}.Value = ' ACTIVE NAV DATA BASE';
                app.cu{4}.Value = ' SECOND NAV DATA BASE';
                app.cu{5}.Value = 'CHG CODE';
                app.EditField_L6_Upper.Value = 'IDLE PERF';
                
                app.EditField_R6.Value = 'Change A/C Type';
                
                app.DataIndex = 3;
                
            elseif app.DataIndex == 21
                
                % Clear the EditFields
                for i = 1:5
                    app.c{i}.Value = '';
                end
                app.EditField_R6_Upper.Value = '';
                
                % Show selected option in the Title
                app.aircraft_selected = app.aircraft_bada{6};
                app.aircraft_choice_index = 1;
                app.EditField_Title.Value = app.aircraft_selected;
                                
                %   Load all the data for the selected aicraft
                filename = ['Aircraft' filesep app.aircraft_selected '__.OPF'];
                app.ac = load_aircraft_bada(filename);
                
                app.cu{2}.Value = ' ENG';
                app.cu{3}.Value = ' ACTIVE NAV DATA BASE';
                app.cu{4}.Value = ' SECOND NAV DATA BASE';
                app.cu{5}.Value = 'CHG CODE';
                app.EditField_L6_Upper.Value = 'IDLE PERF';
                
                app.EditField_R6.Value = 'Change A/C Type';
                
                app.DataIndex = 3;
            end
            
            if app.FPlanIndex == 1 && app.WPTIndex == 1 % To make sure, Origin is slected in FPLAN
                
                % Background Color of the Input Parameters
                app.EditField_L1.FontColor = [1 1 1];
                
                result = ['LAT REV FROM ' app.origin];
                app.EditField_Title.Value = result;
                
                app.EditField_L1_Upper.Value = '';
                app.EditField_L1.Value = '<DEPARTURE';
                app.EditField_L2_Upper.Value = '';
                app.EditField_L2.Value = '[  ]';
                app.EditField_L3_Upper.Value = '';
                app.EditField_L3.Value = '<HOLD';
                app.EditField_L4_Upper.Value = 'ENABLE';
                app.EditField_L4.Value = '<-ALTN';
                app.EditField_L5_Upper.Value = '';
                app.EditField_L5.Value = '';
                app.EditField_L6_Upper.Value = '';
                app.EditField_L6.Value = '<RETURN';
                
                app.EditField_M1_Upper.Value = '';
                app.EditField_M1.Value = '';
                app.EditField_M2_Upper.Value = '';
                app.EditField_M2.Value = '';
                app.EditField_M3_Upper.Value = '';
                app.EditField_M3.Value = '';
                app.EditField_M4_Upper.Value = '';
                app.EditField_M4.Value = '';
                app.EditField_M5_Upper.Value = '';
                app.EditField_M5.Value = '';
                app.EditField_M6_Upper.Value = '';
                app.EditField_M6.Value = '';
                
                app.EditField_R1_Upper.Value = '';
                app.EditField_R1.Value = 'FIX INFO>';
                app.EditField_R2_Upper.Value = 'LL WING/INCR/NO';
                app.EditField_R2.Value = '[ ]°/[ ]°/[]';
                app.EditField_R3_Upper.Value = 'NEXT WPT';
                app.EditField_R3.Value = '[ ]';
                app.EditField_R4_Upper.Value = 'NEW DEST';
                app.EditField_R4.Value = '[ ]';
                app.EditField_R5_Upper.Value = '';
                app.EditField_R5.Value = 'AIRWAYS>';
                app.EditField_R6_Upper.Value = '';
                app.EditField_R6.Value = '';
                
                app.FPlanIndex = 2;
                app.WPTIndex = 0;
                
            elseif app.FPlanIndex == 2
                                
                result = ['DEPATURES FROM ' app.origin];
                app.EditField_Title.Value = result;
                
                %, Anzahl der möglichen RWYs
                app.anzahl_RWY = length(app.departure_rwys);
                
                app.EditField_L1_Upper.Value = 'RWY';
                if (isempty(app.depature_RWY_selected) == 0) && app.OrgDes_counter == 1 || (isempty(app.depature_RWY_selected) == 0) && app.OrgDes_counter == 2
                    app.EditField_L1.Value = app.depature_RWY_selected;
                else
                    app.EditField_L1.Value = '------';
                end
                
                app.EditField_L2_Upper.Value = '';
                app.EditField_L3_Upper.Value = '';
                app.EditField_L4_Upper.Value = '';
                app.EditField_L5_Upper.Value = '';
                app.EditField_L6_Upper.Value = '';
                
                % Clear the EditFields & FontColor
                for i = 2:5
                    app.c{i}.Value = '';
                    app.c{i}.FontColor = [0.3010 0.7450 0.9330];
                end
                
                for i = 1:app.anzahl_RWY
                    
                    xxx(i) = app.departure_rwys(i);
                    ooo(i) = convertCharsToStrings(xxx(i));
                    
                    app.a{i} = convertStringsToChars(ooo(i));
                    app.c{i+1}.Value = ['<' app.a{i}];
                end
                
                app.EditField_L6.Value = '<RETURN';
                
                app.EditField_M1_Upper.Value = 'SID';
                if (isempty(app.SID_selected) == 0) && app.OrgDes_counter == 2
                    app.EditField_M1.Value = app.SID_selected;
                else
                    app.EditField_M1.Value = '------';
                end
                app.EditField_M2_Upper.Value = 'AVAILABLE RUNWAYS';
                app.EditField_M2.Value = '';
                app.EditField_M3_Upper.Value = '';
                app.EditField_M3.Value = '';
                app.EditField_M4_Upper.Value = '';
                app.EditField_M4.Value = '';
                app.EditField_M5_Upper.Value = '';
                app.EditField_M5.Value = '';
                app.EditField_M6_Upper.Value = '';
                app.EditField_M6.Value = '';
                
                app.EditField_R1_Upper.Value = 'TRANS';
                app.EditField_R1.Value = '------';
                app.EditField_R2_Upper.Value = '';
                app.EditField_R2.Value = '';
                app.EditField_R3_Upper.Value = '';
                app.EditField_R3.Value = '';
                app.EditField_R4_Upper.Value = '';
                app.EditField_R4.Value = '';
                app.EditField_R5_Upper.Value = '';
                app.EditField_R5.Value = '';
                app.EditField_R6_Upper.Value = '';
                app.EditField_R6.Value = '';
                
                app.FPlanIndex = 3;
                
            else
                
                value = app.EditField_Scratch.Value;
                app.EditField_L1.Value = value;
                
                % Clear the Scratch Pad after Value has been transmitted
                app.EditField_Scratch.Value = '';
                app.integerNumber = 0;
                app.dotNumber = '';
                app.enableDot = 0; % disable dot number
                
                if app.PhaseIndex == 2
                    
                    app.v1 = value;
                    
                end
                
                if app.PageIndex == 2
                    
                    app.taxi = value;
                    
                end
                
            end
        end

        % Button pushed function: L2_Button
        function L2_ButtonPushed(app, event)
            if app.DataIndex == 2
                
                % Clear the EditFields
                for i = 1:5
                    app.c{i}.Value = '';
                end
                app.EditField_R6_Upper.Value = '';
                
                % Show selected option in the Title
                app.aircraft_selected = app.aircraft_bada{2};
                app.aircraft_choice_index = 1;
                app.EditField_Title.Value = app.aircraft_selected;
                                
                %   Load all the data for the selected aicraft
                filename = ['Aircraft' filesep app.aircraft_selected '__.OPF'];
                app.ac = load_aircraft_bada(filename);
                
                app.cu{2}.Value = ' ENG';
                app.cu{3}.Value = ' ACTIVE NAV DATA BASE';
                app.cu{4}.Value = ' SECOND NAV DATA BASE';
                app.cu{5}.Value = 'CHG CODE';
                app.EditField_L6_Upper.Value = 'IDLE PERF';
                
                app.EditField_R6.Value = 'Change A/C Type';
                
                app.DataIndex = 3;
                
            elseif app.DataIndex == 21
                
                % Clear the EditFields
                for i = 1:5
                    app.c{i}.Value = '';
                end
                app.EditField_R6_Upper.Value = '';
                
                % Show selected option in the Title
                app.aircraft_selected = app.aircraft_bada{7};
                app.aircraft_choice_index = 1;
                app.EditField_Title.Value = app.aircraft_selected;
                                
                %   Load all the data for the selected aicraft
                filename = ['Aircraft' filesep app.aircraft_selected '__.OPF'];
                app.ac = load_aircraft_bada(filename);
                
                app.cu{2}.Value = ' ENG';
                app.cu{3}.Value = ' ACTIVE NAV DATA BASE';
                app.cu{4}.Value = ' SECOND NAV DATA BASE';
                app.cu{5}.Value = 'CHG CODE';
                app.EditField_L6_Upper.Value = 'IDLE PERF';
                
                app.EditField_R6.Value = 'Change A/C Type';
                
                app.DataIndex = 3;
            end
            
            if app.FPlanIndex == 4 % Wenn SID aus 1st Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{1};
                app.SID_selected = app.b{1};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
                
            elseif app.FPlanIndex == 41 % Wenn SID aus 2nd Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{5};
                app.SID_selected = app.b{5};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
                
            elseif app.FPlanIndex == 42 % Wenn SID aus 3rd Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{9};
                app.SID_selected = app.b{9};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
                
            elseif app.FPlanIndex == 43 % Wenn SID aus 4th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{13};
                app.SID_selected = app.b{13};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
                
            elseif app.FPlanIndex == 44 % Wenn SID aus 5th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{17};
                app.SID_selected = app.b{17};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
                
            elseif app.FPlanIndex == 45 % Wenn SID aus 6th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{21};
                app.SID_selected = app.b{21};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
                
            elseif app.FPlanIndex == 46 % Wenn SID aus 7th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{25};
                app.SID_selected = app.b{25};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
                
            elseif app.FPlanIndex == 47 % Wenn SID aus 8th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{29};
                app.SID_selected = app.b{29};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
            end
            
            if app.FPlanIndex == 3 % Wenn RWY aus 1st Page ausgewählt wurde
                
                app.EditField_M2_Upper.Value = 'SIDS AVAILABLE';
                app.EditField_L1.Value = app.a{1};
                app.depature_RWY_selected = app.a{1};
                app.OrgDes_counter = 1;
                
                %   Find all SIDs that support this departure runway
                ind = find(contains(app.sids_rwys,{app.a{1}, 'ALL'}));
                
                %   Show the unique SIDs
                app.b = unique(app.sids(ind));
                last = length(app.b);
                app.b{last+1} = 'NONE';
                app.anzahl_SID = length(app.b);
                
                % Clear the EditFields & Show SIDs on 1st Page
                for i = 1:4
                    app.c{i+1}.Value = '';
                    app.c{i+1}.Value = ['<' app.b{i}];
                end
                
                app.FPlanIndex = 4;
                
            elseif app.FPlanIndex == 31 % Wenn RWY aus 2nd Page ausgewählt wurde
                
                app.EditField_M2_Upper.Value = 'SIDS AVAILABLE';
                app.EditField_L1.Value = app.a{5};
                app.depature_RWY_selected = app.a{5};
                app.OrgDes_counter = 1;
                
                %   Find all SIDs that support this departure runway
                ind = find(contains(app.sids_rwys,{app.a{5}, 'ALL'}));
                
                %   Show the unique SIDs & apply NONE as last entry
                app.b = unique(app.sids(ind));
                last = length(app.b);
                app.b{last+1} = 'NONE';
                app.anzahl_SID = length(app.b);
                
                % Clear the EditFields & Show SIDs on 1st Page
                for i = 1:4
                    app.c{i+1}.Value = '';
                    app.c{i+1}.Value = ['< ' app.b{i}];
                end
                
                app.FPlanIndex = 4;
                
            elseif app.FPlanIndex == 32 % Wenn RWY aus 3rd Page ausgewählt wurde
                
                app.EditField_M2_Upper.Value = 'SIDS AVAILABLE';
                app.EditField_L1.Value = app.a{9};
                app.depature_RWY_selected = app.a{9};
                app.OrgDes_counter = 1;
                
                %   Find all SIDs that support this departure runway
                ind = find(contains(app.sids_rwys,{app.a{9}, 'ALL'}));
                
                %   Show the unique SIDs
                app.b = unique(app.sids(ind));
                last = length(app.b);
                app.b{last+1} = 'NONE';
                app.anzahl_SID = length(app.b);
                
                % Clear the EditFields & Show SIDs on 1st Page
                for i = 1:4
                    app.c{i+1}.Value = '';
                    app.c{i+1}.Value = ['< ' app.b{i}];
                end
                
                app.FPlanIndex = 4;
                
            elseif app.FPlanIndex == 33 % Wenn RWY aus 4th Page ausgewählt wurde
                
                app.EditField_M2_Upper.Value = 'SIDS AVAILABLE';
                app.EditField_L1.Value = app.a{13};
                app.depature_RWY_selected = app.a{13};
                app.OrgDes_counter = 1;
                
                %   Find all SIDs that support this departure runway
                ind = find(contains(app.sids_rwys,{app.a{13}, 'ALL'}));
                
                %   Show the unique SIDs
                app.b = unique(app.sids(ind));
                last = length(app.b);
                app.b{last+1} = 'NONE';
                app.anzahl_SID = length(app.b);
                
                % Clear the EditFields & Show SIDs on 1st Page
                for i = 1:4
                    app.c{i+1}.Value = '';
                    app.c{i+1}.Value = ['< ' app.b{i}];
                end
                
                app.FPlanIndex = 4;
                
            elseif app.FPlanIndex == 34 % Wenn RWY aus 5th Page ausgewählt wurde
                
                app.EditField_M2_Upper.Value = 'SIDS AVAILABLE';
                app.EditField_L1.Value = app.a{17};
                app.depature_RWY_selected = app.a{17};
                app.OrgDes_counter = 1;
                
                %   Find all SIDs that support this departure runway
                ind = find(contains(app.sids_rwys,{app.a{17}, 'ALL'}));
                
                %   Show the unique SIDs
                app.b = unique(app.sids(ind));
                last = length(app.b);
                app.b{last+1} = 'NONE';
                app.anzahl_SID = length(app.b);
                
                % Clear the EditFields & Show SIDs on 1st Page
                for i = 1:4
                    app.c{i+1}.Value = '';
                    app.c{i+1}.Value = ['< ' app.b{i}];
                end
                
                app.FPlanIndex = 4;
            end
            
            if app.PageIndex == 1
                
                value = app.EditField_Scratch.Value;
                app.EditField_L2.Value = value;
                
                % Clear the Scratch Pad after Value has been transmitted
                app.EditField_Scratch.Value = '';
                app.integerNumber = 0;
                app.dotNumber = '';
                app.enableDot = 0; % disable dot number
                
                app.CoRoute = value;
            end
            
            if app.PhaseIndex == 2
                
                value = app.EditField_Scratch.Value;
                app.EditField_L2.Value = value;
                
                % Clear the Scratch Pad after Value has been transmitted
                app.EditField_Scratch.Value = '';
                app.integerNumber = 0;
                app.dotNumber = '';
                app.enableDot = 0; % disable dot number
                
                app.vR = value;
                
            end
        end

        % Button pushed function: L3_Button
        function L3_ButtonPushed(app, event)
            if app.DataIndex == 2
                
                % Clear the EditFields
                for i = 1:5
                    app.c{i}.Value = '';
                end
                app.EditField_R6_Upper.Value = '';
                
                % Show selected option in the Title
                app.aircraft_selected = app.aircraft_bada{3};
                app.aircraft_choice_index = 1;
                app.EditField_Title.Value = app.aircraft_selected;
                                
                %   Load all the data for the selected aicraft
                filename = ['Aircraft' filesep app.aircraft_selected '__.OPF'];
                app.ac = load_aircraft_bada(filename);
                
                app.cu{2}.Value = ' ENG';
                app.cu{3}.Value = ' ACTIVE NAV DATA BASE';
                app.cu{4}.Value = ' SECOND NAV DATA BASE';
                app.cu{5}.Value = 'CHG CODE';
                app.EditField_L6_Upper.Value = 'IDLE PERF';
                
                app.EditField_R6.Value = 'Change A/C Type';
                
                app.DataIndex = 3;
                
            elseif app.DataIndex == 21
                
                % Clear the EditFields
                for i = 1:5
                    app.c{i}.Value = '';
                end
                app.EditField_R6_Upper.Value = '';
                
                % Show selected option in the Title
                app.aircraft_selected = app.aircraft_bada{8};
                app.aircraft_choice_index = 1;
                app.EditField_Title.Value = app.aircraft_selected;
                                
                %   Load all the data for the selected aicraft
                filename = ['Aircraft' filesep app.aircraft_selected '__.OPF'];
                app.ac = load_aircraft_bada(filename);
                
                app.cu{2}.Value = ' ENG';
                app.cu{3}.Value = ' ACTIVE NAV DATA BASE';
                app.cu{4}.Value = ' SECOND NAV DATA BASE';
                app.cu{5}.Value = 'CHG CODE';
                app.EditField_L6_Upper.Value = 'IDLE PERF';
                
                app.EditField_R6.Value = 'Change A/C Type';
                
                app.DataIndex = 3;
            end
            
            if app.FPlanIndex == 9 % Wenn TRANS aus 1st Page ausgewählt wurde
                
                % Clear the EditFields
                for i = 3:5
                    app.c{i}.Value = '';
                end
                
                % Show selected options
                app.EditField_M3_Upper.Value = '';
                app.EditField_M1.Value = app.f{1};
                app.TRANS_selected = app.f{1};
                app.OrgDes_counter = 3;
                
                app.EditField_L6_Upper.Value = '';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = '';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 10;
                
            elseif app.FPlanIndex == 91 % Wenn TRANS aus 2nd Page ausgewählt wurde
                
                % Clear the EditFields
                for i = 3:5
                    app.c{i}.Value = '';
                end
                
                % Show selected options
                app.EditField_M3_Upper.Value = '';
                app.EditField_M1.Value = app.f{4};
                app.TRANS_selected = app.f{4};
                app.OrgDes_counter = 3;
                
                app.EditField_L6_Upper.Value = '';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = '';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 10;
                
            elseif app.FPlanIndex == 92 % Wenn TRANS aus 3rd Page ausgewählt wurde
                
                % Clear the EditFields
                for i = 3:5
                    app.c{i}.Value = '';
                end
                
                % Show selected options
                app.EditField_M3_Upper.Value = '';
                app.EditField_M1.Value = app.f{7};
                app.TRANS_selected = app.f{7};
                app.OrgDes_counter = 3;
                
                app.EditField_L6_Upper.Value = '';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = '';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 10;
                
            elseif app.FPlanIndex == 93 % Wenn TRANS aus 4th Page ausgewählt wurde
                
                % Clear the EditFields
                for i = 3:5
                    app.c{i}.Value = '';
                end
                
                % Show selected options
                app.EditField_M3_Upper.Value = '';
                app.EditField_M1.Value = app.f{10};
                app.TRANS_selected = app.f{10};
                app.OrgDes_counter = 3;
                
                app.EditField_L6_Upper.Value = '';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = '';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 10;
            end
            
            if app.FPlanIndex == 8 % Wenn STAR aus 1st Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{1};
                app.STAR_selected = app.e{1};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 81 % Wenn STAR aus 2nd Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{4};
                app.STAR_selected = app.e{4};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 2nd Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 82 % Wenn STAR aus 3rd Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{7};
                app.STAR_selected = app.e{7};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 3rd Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 83 % Wenn STAR aus 4th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{10};
                app.STAR_selected = app.e{10};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 4th Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 84 % Wenn STAR aus 5th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{13};
                app.STAR_selected = app.e{13};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 5th Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 85 % Wenn STAR aus 6th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{16};
                app.STAR_selected = app.e{16};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 6th Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 86 % Wenn STAR aus 7th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{19};
                app.STAR_selected = app.e{19};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 7th Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 87 % Wenn STAR aus 8th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{22};
                app.STAR_selected = app.e{22};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 8th Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 88 % Wenn STAR aus 9th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{25};
                app.STAR_selected = app.e{25};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 9th Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 89 % Wenn STAR aus 10th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{28};
                app.STAR_selected = app.e{28};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 10th Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 891 % Wenn STAR aus 11th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{31};
                app.STAR_selected = app.e{31};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 11th Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 892 % Wenn STAR aus 12th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{34};
                app.STAR_selected = app.e{34};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 12th Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
            end
            
            if app.FPlanIndex == 7 % Wenn Approach RWY aus 1st Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{1};
                app.approach_selected = app.d{1};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 71 % Wenn Approach RWY aus 2nd Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{4};
                app.approach_selected = app.d{4};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 72 % Wenn Approach RWY aus 3rd Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{7};
                app.approach_selected = app.d{7};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 73 % Wenn Approach RWY aus 4th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{10};
                app.approach_selected = app.d{10};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 74 % Wenn Approach RWY aus 5th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{13};
                app.approach_selected = app.d{13};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 75 % Wenn Approach RWY aus 6th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{16};
                app.approach_selected = app.d{16};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:4
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 76 % Wenn Approach RWY aus 7th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{19};
                app.approach_selected = app.d{19};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 77 % Wenn Approach RWY aus 8th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{22};
                app.approach_selected = app.d{22};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 78 % Wenn Approach RWY aus 9th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{25};
                app.approach_selected = app.d{25};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 79 % Wenn Approach RWY aus 10th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{28};
                app.approach_selected = app.d{28};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 791 % Wenn Approach RWY aus 11th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{31};
                app.approach_selected = app.d{31};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 792 % Wenn Approach RWY aus 12th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{34};
                app.approach_selected = app.d{34};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
            end
            
            if app.FPlanIndex == 4 % Wenn SID aus 1st Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{2};
                app.SID_selected = app.b{2};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
                
            elseif app.FPlanIndex == 41 % Wenn SID aus 2nd Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{6};
                app.SID_selected = app.b{6};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
                
            elseif app.FPlanIndex == 42 % Wenn SID aus 3rd Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{10};
                app.SID_selected = app.b{10};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
                
            elseif app.FPlanIndex == 43 % Wenn SID aus 4th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{14};
                app.SID_selected = app.b{14};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
                
            elseif app.FPlanIndex == 44 % Wenn SID aus 5th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{18};
                app.SID_selected = app.b{18};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
                
            elseif app.FPlanIndex == 45 % Wenn SID aus 6th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{22};
                app.SID_selected = app.b{22};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
                
            elseif app.FPlanIndex == 46 % Wenn SID aus 7th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{26};
                app.SID_selected = app.b{26};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
                
            elseif app.FPlanIndex == 47 % Wenn SID aus 8th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{30};
                app.SID_selected = app.b{30};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
            end
            
            if app.FPlanIndex == 3 % Wenn RWY aus 1st Page ausgewählt wurde
                
                app.EditField_M2_Upper.Value = 'SIDS AVAILABLE';
                app.EditField_L1.Value = app.a{2};
                app.depature_RWY_selected = app.a{2};
                app.OrgDes_counter = 1;
                
                %   Find all SIDs that support this departure runway
                ind = find(contains(app.sids_rwys,{app.a{2}, 'ALL'}));
                
                %   Show the unique SIDs
                app.b = unique(app.sids(ind));
                last = length(app.b);
                app.b{last+1} = 'NONE';
                app.anzahl_SID = length(app.b);
                
                % Clear the EditFields & Show SIDs on 1st Page
                for i = 1:4
                    app.c{i+1}.Value = '';
                    app.c{i+1}.Value = ['< ' app.b{i}];
                end
                
                app.FPlanIndex = 4;
                
            elseif app.FPlanIndex == 31 % Wenn RWY aus 2nd Page ausgewählt wurde
                
                app.EditField_M2_Upper.Value = 'SIDS AVAILABLE';
                app.EditField_L1.Value = app.a{6};
                app.depature_RWY_selected = app.a{6};
                app.OrgDes_counter = 1;
                
                %   Find all SIDs that support this departure runway
                ind = find(contains(app.sids_rwys,{app.a{6}, 'ALL'}));
                
                %   Show the unique SIDs
                app.b = unique(app.sids(ind));
                last = length(app.b);
                app.b{last+1} = 'NONE';
                app.anzahl_SID = length(app.b);
                
                % Clear the EditFields & Show SIDs on 1st Page
                for i = 1:4
                    app.c{i+1}.Value = '';
                    app.c{i+1}.Value = ['< ' app.b{i}];
                end
                
                app.FPlanIndex = 4;
                
            elseif app.FPlanIndex == 32 % Wenn RWY aus 3rd Page ausgewählt wurde
                
                app.EditField_M2_Upper.Value = 'SIDS AVAILABLE';
                app.EditField_L1.Value = app.a{10};
                app.depature_RWY_selected = app.a{10};
                app.OrgDes_counter = 1;
                
                %   Find all SIDs that support this departure runway
                ind = find(contains(app.sids_rwys,{app.a{10}, 'ALL'}));
                
                %   Show the unique SIDs
                app.b = unique(app.sids(ind));
                last = length(app.b);
                app.b{last+1} = 'NONE';
                app.anzahl_SID = length(app.b);
                
                % Clear the EditFields & Show SIDs on 1st Page
                for i = 1:4
                    app.c{i+1}.Value = '';
                    app.c{i+1}.Value = ['< ' app.b{i}];
                end
                
                app.FPlanIndex = 4;
                
            elseif app.FPlanIndex == 33 % Wenn RWY aus 4th Page ausgewählt wurde
                
                app.EditField_M2_Upper.Value = 'SIDS AVAILABLE';
                app.EditField_L1.Value = app.a{14};
                app.depature_RWY_selected = app.a{14};
                app.OrgDes_counter = 1;
                
                %   Find all SIDs that support this departure runway
                ind = find(contains(app.sids_rwys,{app.a{14}, 'ALL'}));
                
                %   Show the unique SIDs
                app.b = unique(app.sids(ind));
                last = length(app.b);
                app.b{last+1} = 'NONE';
                app.anzahl_SID = length(app.b);
                
                % Clear the EditFields & Show SIDs on 1st Page
                for i = 1:4
                    app.c{i+1}.Value = '';
                    app.c{i+1}.Value = ['< ' app.b{i}];
                end
                
                app.FPlanIndex = 4;
                
            elseif app.FPlanIndex == 34 % Wenn RWY aus 5th Page ausgewählt wurde
                
                app.EditField_M2_Upper.Value = 'SIDS AVAILABLE';
                app.EditField_L1.Value = app.a{18};
                app.depature_RWY_selected = app.a{18};
                app.OrgDes_counter = 1;
                
                %   Find all SIDs that support this departure runway
                ind = find(contains(app.sids_rwys,{app.a{18}, 'ALL'}));
                
                %   Show the unique SIDs
                app.b = unique(app.sids(ind));
                last = length(app.b);
                app.b{last+1} = 'NONE';
                app.anzahl_SID = length(app.b);
                
                % Clear the EditFields & Show SIDs on 1st Page
                for i = 1:4
                    app.c{i+1}.Value = '';
                    app.c{i+1}.Value = ['< ' app.b{i}];
                end
                
                app.FPlanIndex = 4;
            end
            
            if app.PageIndex == 1
                
                value = app.EditField_Scratch.Value;
                app.EditField_L3.Value = value;
                
                % Clear the Scratch Pad after Value has been transmitted
                app.EditField_Scratch.Value = '';
                app.integerNumber = 0;
                app.dotNumber = '';
                app.enableDot = 0; % disable dot number
                
                app.FLnumber = value;
                
            end
            
            if app.PhaseIndex == 2
                
                value = app.EditField_Scratch.Value;
                app.EditField_L3.Value = value;
                
                % Clear the Scratch Pad after Value has been transmitted
                app.EditField_Scratch.Value = '';
                app.integerNumber = 0;
                app.dotNumber = '';
                app.enableDot = 0; % disable dot number
                
                app.v2 = value;
                
            end
        end

        % Button pushed function: L4_Button
        function L4_ButtonPushed(app, event)
            if app.DataIndex == 2
                
                % Clear the EditFields
                for i = 1:5
                    app.c{i}.Value = '';
                end
                app.EditField_R6_Upper.Value = '';
                
                % Show selected option in the Title
                app.aircraft_selected = app.aircraft_bada{4};
                app.aircraft_choice_index = 1;
                app.EditField_Title.Value = app.aircraft_selected;
                                
                %   Load all the data for the selected aicraft
                filename = ['Aircraft' filesep app.aircraft_selected '__.OPF'];
                app.ac = load_aircraft_bada(filename);
                
                app.cu{2}.Value = ' ENG';
                app.cu{3}.Value = ' ACTIVE NAV DATA BASE';
                app.cu{4}.Value = ' SECOND NAV DATA BASE';
                app.cu{5}.Value = 'CHG CODE';
                app.EditField_L6_Upper.Value = 'IDLE PERF';
                
                app.EditField_R6.Value = 'Change A/C Type';
                
                app.DataIndex = 3;
                
            elseif app.DataIndex == 21
                
                % Clear the EditFields
                for i = 1:5
                    app.c{i}.Value = '';
                end
                app.EditField_R6_Upper.Value = '';
                
                % Show selected option in the Title
                app.aircraft_selected = app.aircraft_bada{9};
                app.aircraft_choice_index = 1;
                app.EditField_Title.Value = app.aircraft_selected;
                                
                %   Load all the data for the selected aicraft
                filename = ['Aircraft' filesep app.aircraft_selected '__.OPF'];
                app.ac = load_aircraft_bada(filename);
                
                app.cu{2}.Value = ' ENG';
                app.cu{3}.Value = ' ACTIVE NAV DATA BASE';
                app.cu{4}.Value = ' SECOND NAV DATA BASE';
                app.cu{5}.Value = 'CHG CODE';
                app.EditField_L6_Upper.Value = 'IDLE PERF';
                
                app.EditField_R6.Value = 'Change A/C Type';
                
                app.DataIndex = 3;
            end
            
            if app.DataIndex == 1 && app.aircraft_choice_index == 0 % Wenn DATA-Page ausgewählt ist
                
                % Reset EditFields
                for i = 1:5
                app.c{i}.Value = '';
                app.m{i}.Value = '';
                app.r{i}.Value = '';
                app.cu{i}.Value = '';
                app.mu{i}.Value = '';
                app.ru{i}.Value = '';
                end
                app.EditField_L1.Value = '';            
                app.EditField_M1.Value = '';
                app.EditField_R1.Value = '';            
                app.EditField_L6_Upper.Value = '';
                app.EditField_M6_Upper.Value = '';
                app.EditField_R6_Upper.Value = '';
                app.EditField_R6.Value = '';
                
                app.EditField_Title.Value = 'Choose the Aircraft Type';
                
                %   Load the aircraft for which BADA performance files are available
                app.aircraft_bada = load_aircraft('Aircraft');
                
                for i = 1:length(app.aircraft_bada)
                    app.c{i}.Value = ['< ' app.aircraft_bada{i}];
                end                
                app.DataIndex = 2;
                
            elseif app.DataIndex == 1 && app.aircraft_choice_index == 1
                
                % Clear the EditFields
                for i = 1:5
                    app.c{i}.Value = '';
                    app.m{i}.Value = '';
                    app.r{i}.Value = '';
                    app.cu{i}.Value = '';
                    app.mu{i}.Value = '';
                    app.ru{i}.Value = '';
                end
                
                app.EditField_R6_Upper.Value = '';
                
                % Show selected option in the Title
                app.EditField_Title.Value = app.aircraft_selected;
                
                app.cu{2}.Value = ' ENG';
                app.cu{3}.Value = ' ACTIVE NAV DATA BASE';
                app.cu{4}.Value = ' SECOND NAV DATA BASE';
                app.cu{5}.Value = 'CHG CODE';
                app.EditField_L6_Upper.Value = 'IDLE PERF';
                
                app.EditField_R6.Value = 'Change A/C Type';
                
                app.DataIndex = 3;
            end            
                        
            if app.FPlanIndex == 9 % Wenn TRANS aus 1st Page ausgewählt wurde
                
                % Clear the EditFields
                for i = 3:5
                    app.c{i}.Value = '';
                end
                
                % Show selected options
                app.EditField_M3_Upper.Value = '';
                app.EditField_M1.Value = app.f{2};
                app.TRANS_selected = app.f{2};
                app.OrgDes_counter = 3;
                
                app.EditField_L6_Upper.Value = '';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = '';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 10;
                
            elseif app.FPlanIndex == 91 % Wenn TRANS aus 2nd Page ausgewählt wurde
                
                % Clear the EditFields
                for i = 3:5
                    app.c{i}.Value = '';
                end
                
                % Show selected options
                app.EditField_M3_Upper.Value = '';
                app.EditField_M1.Value = app.f{5};
                app.TRANS_selected = app.f{5};
                app.OrgDes_counter = 3;
                
                app.EditField_L6_Upper.Value = '';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = '';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 10;
                
            elseif app.FPlanIndex == 92 % Wenn TRANS aus 3rd Page ausgewählt wurde
                
                % Clear the EditFields
                for i = 3:5
                    app.c{i}.Value = '';
                end
                
                % Show selected options
                app.EditField_M3_Upper.Value = '';
                app.EditField_M1.Value = app.f{8};
                app.TRANS_selected = app.f{8};
                app.OrgDes_counter = 3;
                
                app.EditField_L6_Upper.Value = '';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = '';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 10;
                
            elseif app.FPlanIndex == 93 % Wenn TRANS aus 4th Page ausgewählt wurde
                
                % Clear the EditFields
                for i = 3:5
                    app.c{i}.Value = '';
                end
                
                % Show selected options
                app.EditField_M3_Upper.Value = '';
                app.EditField_M1.Value = app.f{11};
                app.TRANS_selected = app.f{11};
                app.OrgDes_counter = 3;
                
                app.EditField_L6_Upper.Value = '';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = '';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 10;
            end
            
            if app.FPlanIndex == 8 % Wenn STAR aus 1st Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{2};
                app.STAR_selected = app.e{2};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 81 % Wenn STAR aus 2nd Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{5};
                app.STAR_selected = app.e{5};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 2nd Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 82 % Wenn STAR aus 3rd Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{8};
                app.STAR_selected = app.e{8};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 3rd Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 83 % Wenn STAR aus 4th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{11};
                app.STAR_selected = app.e{11};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 4th Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 84 % Wenn STAR aus 5th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{14};
                app.STAR_selected = app.e{14};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 5th Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 85 % Wenn STAR aus 6th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{17};
                app.STAR_selected = app.e{17};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 6th Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 86 % Wenn STAR aus 7th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{20};
                app.STAR_selected = app.e{20};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 7th Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 87 % Wenn STAR aus 8th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{23};
                app.STAR_selected = app.e{23};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 8th Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 88 % Wenn STAR aus 9th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{26};
                app.STAR_selected = app.e{26};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 9th Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 89 % Wenn STAR aus 10th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{29};
                app.STAR_selected = app.e{29};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 10th Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 891 % Wenn STAR aus 11th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{32};
                app.STAR_selected = app.e{32};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 11th Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 892 % Wenn STAR aus 12th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{35};
                app.STAR_selected = app.e{35};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 12th Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
            end
            
            if app.FPlanIndex == 7 % Wenn Approach RWY aus 1st Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{2};
                app.approach_selected = app.d{2};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 71 % Wenn Approach RWY aus 2nd Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{5};
                app.approach_selected = app.d{5};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 72 % Wenn Approach RWY aus 3rd Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{8};
                app.approach_selected = app.d{8};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show SIDs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 73 % Wenn Approach RWY aus 4th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{11};
                app.approach_selected = app.d{11};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 74 % Wenn Approach RWY aus 5th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{14};
                app.approach_selected = app.d{14};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 75 % Wenn Approach RWY aus 6th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{17};
                app.approach_selected = app.d{17};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 76 % Wenn Approach RWY aus 7th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{20};
                app.approach_selected = app.d{20};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 77 % Wenn Approach RWY aus 8th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{23};
                app.approach_selected = app.d{23};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 78 % Wenn Approach RWY aus 9th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{26};
                app.approach_selected = app.d{26};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 79 % Wenn Approach RWY aus 10th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{29};
                app.approach_selected = app.d{29};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 791 % Wenn Approach RWY aus 11th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{32};
                app.approach_selected = app.d{32};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 792 % Wenn Approach RWY aus 12th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{35};
                app.approach_selected = app.d{35};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
            end
            
            if app.FPlanIndex == 4 % Wenn SID aus 1st Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{3};
                app.SID_selected = app.b{3};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
                
            elseif app.FPlanIndex == 41 % Wenn SID aus 2nd Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{7};
                app.SID_selected = app.b{7};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
                
            elseif app.FPlanIndex == 42 % Wenn SID aus 3rd Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{11};
                app.SID_selected = app.b{11};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
                
            elseif app.FPlanIndex == 43 % Wenn SID aus 4th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{15};
                app.SID_selected = app.b{15};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
                
            elseif app.FPlanIndex == 44 % Wenn SID aus 5th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{19};
                app.SID_selected = app.b{19};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
                
            elseif app.FPlanIndex == 45 % Wenn SID aus 6th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{23};
                app.SID_selected = app.b{23};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
                
            elseif app.FPlanIndex == 46 % Wenn SID aus 7th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{27};
                app.SID_selected = app.b{27};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
                
            elseif app.FPlanIndex == 47 % Wenn SID aus 8th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{31};
                app.SID_selected = app.b{31};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
            end
            
            if app.FPlanIndex == 3 % Wenn RWY aus 1st Page ausgewählt wurde
                
                app.EditField_M2_Upper.Value = 'SIDS AVAILABLE';
                app.EditField_L1.Value = app.a{3};
                app.depature_RWY_selected = app.a{3};
                app.OrgDes_counter = 1;
                
                %   Find all SIDs that support this departure runway
                ind = find(contains(app.sids_rwys,{app.a{3}, 'ALL'}));
                
                %   Show the unique SIDs
                app.b = unique(app.sids(ind));
                last = length(app.b);
                app.b{last+1} = 'NONE';
                app.anzahl_SID = length(app.b);
                
                % Clear the EditFields & Show SIDs on 1st Page
                for i = 2:5
                    app.c{i}.Value = '';
                    app.c{i}.Value = ['< ' app.b{i}];
                end
                
                app.FPlanIndex = 4;
                
            elseif app.FPlanIndex == 31 % Wenn RWY aus 2nd Page ausgewählt wurde
                
                app.EditField_M2_Upper.Value = 'SIDS AVAILABLE';
                app.EditField_L1.Value = app.a{7};
                app.depature_RWY_selected = app.a{7};
                app.OrgDes_counter = 1;
                
                %   Find all SIDs that support this departure runway
                ind = find(contains(app.sids_rwys,{app.a{7}, 'ALL'}));
                
                %   Show the unique SIDs
                app.b = unique(app.sids(ind));
                last = length(app.b);
                app.b{last+1} = 'NONE';
                app.anzahl_SID = length(app.b);
                
                % Clear the EditFields & Show SIDs on 1st Page
                for i = 2:5
                    app.c{i}.Value = '';
                    app.c{i}.Value = ['< ' app.b{i}];
                end
                
                app.FPlanIndex = 4;
                
            elseif app.FPlanIndex == 32 % Wenn RWY aus 3rd Page ausgewählt wurde
                
                app.EditField_M2_Upper.Value = 'SIDS AVAILABLE';
                app.EditField_L1.Value = app.a{11};
                app.depature_RWY_selected = app.a{11};
                app.OrgDes_counter = 1;
                
                %   Find all SIDs that support this departure runway
                ind = find(contains(app.sids_rwys,{app.a{11}, 'ALL'}));
                
                %   Show the unique SIDs
                app.b = unique(app.sids(ind));
                last = length(app.b);
                app.b{last+1} = 'NONE';
                app.anzahl_SID = length(app.b);
                
                % Clear the EditFields & Show SIDs on 1st Page
                for i = 2:5
                    app.c{i}.Value = '';
                    app.c{i}.Value = ['< ' app.b{i}];
                end
                
                app.FPlanIndex = 4;
                
            elseif app.FPlanIndex == 33 % Wenn RWY aus 4th Page ausgewählt wurde
                
                app.EditField_M2_Upper.Value = 'SIDS AVAILABLE';
                app.EditField_L1.Value = app.a{15};
                app.depature_RWY_selected = app.a{15};
                app.OrgDes_counter = 1;
                
                %   Find all SIDs that support this departure runway
                ind = find(contains(app.sids_rwys,{app.a{15}, 'ALL'}));
                
                %   Show the unique SIDs
                app.b = unique(app.sids(ind));
                last = length(app.b);
                app.b{last+1} = 'NONE';
                app.anzahl_SID = length(app.b);
                
                % Clear the EditFields & Show SIDs on 1st Page
                for i = 2:5
                    app.c{i}.Value = '';
                    app.c{i}.Value = ['< ' app.b{i}];
                end
                
                app.FPlanIndex = 4;
                
            elseif app.FPlanIndex == 34 % Wenn RWY aus 5th Page ausgewählt wurde
                
                app.EditField_M2_Upper.Value = 'SIDS AVAILABLE';
                app.EditField_L1.Value = app.a{19};
                app.depature_RWY_selected = app.a{19};
                app.OrgDes_counter = 1;
                
                %   Find all SIDs that support this departure runway
                ind = find(contains(app.sids_rwys,{app.a{19}, 'ALL'}));
                
                %   Show the unique SIDs
                app.b = unique(app.sids(ind));
                last = length(app.b);
                app.b{last+1} = 'NONE';
                app.anzahl_SID = length(app.b);
                
                % Clear the EditFields & Show SIDs on 1st Page
                for i = 2:5
                    app.c{i}.Value = '';
                    app.c{i}.Value = ['< ' app.b{i}];
                end
                
                app.FPlanIndex = 4;
            end
            
            if app.PhaseIndex == 2
                
                value = app.EditField_Scratch.Value;
                app.EditField_L4.Value = value;
                
                % Clear the Scratch Pad after Value has been transmitted
                app.EditField_Scratch.Value = '';
                app.integerNumber = 0;
                app.dotNumber = '';
                app.enableDot = 0; % disable dot number
                
                app.trans_ALT = value;
                
            end
        end

        % Button pushed function: L5_Button
        function L5_ButtonPushed(app, event)
            if app.DataIndex == 2
                
                % Clear the EditFields
                for i = 1:5
                    app.c{i}.Value = '';
                end
                app.EditField_R6_Upper.Value = '';
                
                % Show selected option in the Title
                app.aircraft_selected = app.aircraft_bada{5};
                app.aircraft_choice_index = 1;
                app.EditField_Title.Value = app.aircraft_selected;
                                
                %   Load all the data for the selected aicraft
                filename = ['Aircraft' filesep app.aircraft_selected '__.OPF'];
                app.ac = load_aircraft_bada(filename);
                
                app.cu{2}.Value = '   ENG';
                app.cu{3}.Value = '   ACTIVE NAV DATA BASE';
                app.cu{4}.Value = '   SECOND NAV DATA BASE';
                app.cu{5}.Value = 'CHG CODE';
                app.EditField_L6_Upper.Value = 'IDLE PERF';
                
                app.EditField_R6.Value = 'Change A/C Type';
                
                app.DataIndex = 3;
                
            elseif app.DataIndex == 21
                
                % Clear the EditFields
                for i = 1:5
                    app.c{i}.Value = '';
                end
                app.EditField_R6_Upper.Value = '';
                
                % Show selected option in the Title
                app.aircraft_selected = app.aircraft_bada{10};
                app.aircraft_choice_index = 1;
                app.EditField_Title.Value = app.aircraft_selected;
                                
                %   Load all the data for the selected aicraft
                filename = ['Aircraft' filesep app.aircraft_selected '__.OPF'];
                app.ac = load_aircraft_bada(filename);
                
                app.cu{2}.Value = '   ENG';
                app.cu{3}.Value = '   ACTIVE NAV DATA BASE';
                app.cu{4}.Value = '   SECOND NAV DATA BASE';
                app.cu{5}.Value = 'CHG CODE';
                app.EditField_L6_Upper.Value = 'IDLE PERF';
                
                app.EditField_R6.Value = 'Change A/C Type';
                
                app.DataIndex = 3;
            end
            
            if app.FPlanIndex == 9 % Wenn TRANS aus 1st Page ausgewählt wurde
                
                % Clear the EditFields
                for i = 3:5
                    app.c{i}.Value = '';
                end
                
                % Show selected options
                app.EditField_M3_Upper.Value = '';
                app.EditField_M1.Value = app.f{3};
                app.TRANS_selected = app.f{3};
                app.OrgDes_counter = 3;
                
                app.EditField_L6_Upper.Value = '';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = '';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 10;
                
            elseif app.FPlanIndex == 91 % Wenn TRANS aus 2nd Page ausgewählt wurde
                
                % Clear the EditFields
                for i = 3:5
                    app.c{i}.Value = '';
                end
                
                % Show selected options
                app.EditField_M3_Upper.Value = '';
                app.EditField_M1.Value = app.f{6};
                app.TRANS_selected = app.f{6};
                app.OrgDes_counter = 3;
                
                app.EditField_L6_Upper.Value = '';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = '';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 10;
                
            elseif app.FPlanIndex == 92 % Wenn TRANS aus 3rd Page ausgewählt wurde
                
                % Clear the EditFields
                for i = 3:5
                    app.c{i}.Value = '';
                end
                
                % Show selected options
                app.EditField_M3_Upper.Value = '';
                app.EditField_M1.Value = app.f{9};
                app.TRANS_selected = app.f{9};
                app.OrgDes_counter = 3;
                
                app.EditField_L6_Upper.Value = '';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = '';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 10;
                
            elseif app.FPlanIndex == 93 % Wenn TRANS aus 4th Page ausgewählt wurde
                
                % Clear the EditFields
                for i = 3:5
                    app.c{i}.Value = '';
                end
                
                % Show selected options
                app.EditField_M3_Upper.Value = '';
                app.EditField_M1.Value = app.f{12};
                app.TRANS_selected = app.f{12};
                app.OrgDes_counter = 3;
                
                app.EditField_L6_Upper.Value = '';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = '';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 10;
            end
            
            if app.FPlanIndex == 8 % Wenn STAR aus 1st Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{3};
                app.STAR_selected = app.e{3};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 81 % Wenn STAR aus 2nd Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{6};
                app.STAR_selected = app.e{6};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 2nd Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 82 % Wenn STAR aus 3rd Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{9};
                app.STAR_selected = app.e{9};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 3rd Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 83 % Wenn STAR aus 4th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{12};
                app.STAR_selected = app.e{12};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 4th Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 84 % Wenn STAR aus 5th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{15};
                app.STAR_selected = app.e{15};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 5th Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 85 % Wenn STAR aus 6th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{18};
                app.STAR_selected = app.e{18};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 6th Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 86 % Wenn STAR aus 7th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{21};
                app.STAR_selected = app.e{21};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 7th Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 87 % Wenn STAR aus 8th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{24};
                app.STAR_selected = app.e{24};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 8th Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 88 % Wenn STAR aus 9th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{27};
                app.STAR_selected = app.e{27};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 9th Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 89 % Wenn STAR aus 10th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{30};
                app.STAR_selected = app.e{30};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 10th Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 891 % Wenn STAR aus 11th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{33};
                app.STAR_selected = app.e{33};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 11th Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
                
            elseif app.FPlanIndex == 892 % Wenn STAR aus 12th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected STAR
                app.EditField_M3_Upper.Value = 'APPR VIAS (TRANS)';
                app.EditField_R1.Value = app.e{36};
                app.STAR_selected = app.e{36};
                app.OrgDes_counter = 2;
                
                %   Find all TRANSs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.transitions.types,app.approaches.types(ii)));
                ind = [1 ind];
                
                % Show the unique TRANs & modify the app.transitions.name
                app.f = unique(app.transitions.name(ind));
                last = length(app.f);
                for j = 1:(last-1)
                    app.f(j) = app.f(j+1);
                end
                app.f{last} = 'NONE';
                app.anzahl_TRANS = length(app.f);
                
                % Clear the EditFields & Show TRANs on 12th Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.f{i}];
                end
                app.FPlanIndex = 9;
            end
            
            if app.FPlanIndex == 7 % Wenn Approach RWY aus 1st Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{3};
                app.approach_selected = app.d{3};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 71 % Wenn Approach RWY aus 2nd Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{6};
                app.approach_selected = app.d{6};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 72 % Wenn Approach RWY aus 3rd Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{9};
                app.approach_selected = app.d{9};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 73 % Wenn Approach RWY aus 4th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{12};
                app.approach_selected = app.d{12};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 74 % Wenn Approach RWY aus 5th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{15};
                app.approach_selected = app.d{15};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 75 % Wenn Approach RWY aus 6th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{18};
                app.approach_selected = app.d{18};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 76 % Wenn Approach RWY aus 7th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{21};
                app.approach_selected = app.d{21};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 77 % Wenn Approach RWY aus 8th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{24};
                app.approach_selected = app.d{24};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 78 % Wenn Approach RWY aus 9th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{27};
                app.approach_selected = app.d{27};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 79 % Wenn Approach RWY aus 10th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{30};
                app.approach_selected = app.d{30};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 791 % Wenn Approach RWY aus 11th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{33};
                app.approach_selected = app.d{33};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 792 % Wenn Approach RWY aus 12th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Save the selected Approach
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                app.EditField_L1.Value = app.d{36};
                app.approach_selected = app.d{36};
                app.OrgDes_counter = 1;
                
                %   Find all STARs that support this approach runway
                ii = find(contains(app.approaches.full,app.approach_selected));
                ind = find(contains(app.stars.rwys,app.approaches.rwys(ii)));
                ind = [1 ind];
                
                % Show the unique STARs & modify the app.stars.name
                app.e = unique(app.stars.name(ind));
                last = length(app.e);
                for j = 1:(last-1)
                    app.e(j) = app.e(j+1);
                end
                app.e{last} = 'NONE';
                app.anzahl_STAR = length(app.e);
                
                % Clear the EditFields & Show STARs on 1st Page
                for i = 1:3
                    app.c{i+2}.Value = '';
                    app.c{i+2}.Value = ['< ' app.e{i}];
                end
                app.FPlanIndex = 8;
            end
            
            if app.FPlanIndex == 4 % Wenn SID aus 1st Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{4};
                app.SID_selected = app.b{4};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
                
            elseif app.FPlanIndex == 41 % Wenn SID aus 2nd Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{8};
                app.SID_selected = app.b{8};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
                
            elseif app.FPlanIndex == 42 % Wenn SID aus 3rd Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{12};
                app.SID_selected = app.b{12};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
                
            elseif app.FPlanIndex == 43 % Wenn SID aus 4th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{16};
                app.SID_selected = app.b{16};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
                
            elseif app.FPlanIndex == 44 % Wenn SID aus 5th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{20};
                app.SID_selected = app.b{20};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
                
            elseif app.FPlanIndex == 45 % Wenn SID aus 6th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{24};
                app.SID_selected = app.b{24};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
                
            elseif app.FPlanIndex == 46 % Wenn SID aus 7th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{28};
                app.SID_selected = app.b{28};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
                
            elseif app.FPlanIndex == 47 % Wenn SID aus 8th Page ausgewählt wurde
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                app.EditField_M2_Upper.Value = '';
                app.EditField_M1.Value = app.b{32};
                app.SID_selected = app.b{32};
                app.OrgDes_counter = 2;
                
                app.EditField_R1.Value = 'NONE';
                
                app.EditField_L6_Upper.Value = ' TMPY';
                app.EditField_L6.Value = '<ERASE';
                app.EditField_R6_Upper.Value = 'TMPY ';
                app.EditField_R6.Value = 'INSERT*';
                
                app.FPlanIndex = 5;
            end
            
            if app.FPlanIndex == 3 % Wenn RWY aus 1st Page ausgewählt wurde
                
                app.EditField_M2_Upper.Value = 'SIDS AVAILABLE';
                app.EditField_L1.Value = app.a{4};
                app.depature_RWY_selected = app.a{4};
                app.OrgDes_counter = 1;
                %   Find all SIDs that support this departure runway
                ind = find(contains(app.sids_rwys,{app.a{4}, 'ALL'}));
                
                %   Show the unique SIDs
                app.b = unique(app.sids(ind));
                last = length(app.b);
                app.b{last+1} = 'NONE';
                app.anzahl_SID = length(app.b);
                
                % Clear the EditFields & Show SIDs on 1st Page
                for i = 2:5
                    app.c{i}.Value = '';
                    app.c{i}.Value = ['< ' app.b{i}];
                end
                
                app.FPlanIndex = 4;
                
            elseif app.FPlanIndex == 31 % Wenn RWY aus 2nd Page ausgewählt wurde
                
                app.EditField_M2_Upper.Value = 'SIDS AVAILABLE';
                app.EditField_L1.Value = app.a{8};
                app.depature_RWY_selected = app.a{8};
                app.OrgDes_counter = 1;
                
                %   Find all SIDs that support this departure runway
                ind = find(contains(app.sids_rwys,{app.a{8}, 'ALL'}));
                
                %   Show the unique SIDs
                app.b = unique(app.sids(ind));
                last = length(app.b);
                app.b{last+1} = 'NONE';
                app.anzahl_SID = length(app.b);
                
                % Clear the EditFields & Show SIDs on 1st Page
                for i = 2:5
                    app.c{i}.Value = '';
                    app.c{i}.Value = ['< ' app.b{i}];
                end
                
                app.FPlanIndex = 4;
                
            elseif app.FPlanIndex == 32 % Wenn RWY aus 3rd Page ausgewählt wurde
                
                app.EditField_M2_Upper.Value = 'SIDS AVAILABLE';
                app.EditField_L1.Value = app.a{12};
                app.depature_RWY_selected = app.a{12};
                app.OrgDes_counter = 1;
                
                %   Find all SIDs that support this departure runway
                ind = find(contains(app.sids_rwys,{app.a{12}, 'ALL'}));
                
                %   Show the unique SIDs
                app.b = unique(app.sids(ind));
                last = length(app.b);
                app.b{last+1} = 'NONE';
                app.anzahl_SID = length(app.b);
                
                % Clear the EditFields & Show SIDs on 1st Page
                for i = 2:5
                    app.c{i}.Value = '';
                    app.c{i}.Value = ['< ' app.b{i}];
                end
                
                app.FPlanIndex = 4;
                
            elseif app.FPlanIndex == 33 % Wenn RWY aus 4th Page ausgewählt wurde
                
                app.EditField_M2_Upper.Value = 'SIDS AVAILABLE';
                app.EditField_L1.Value = app.a{16};
                app.depature_RWY_selected = app.a{16};
                app.OrgDes_counter = 1;
                
                %   Find all SIDs that support this departure runway
                ind = find(contains(app.sids_rwys,{app.a{16}, 'ALL'}));
                
                %   Show the unique SIDs
                app.b = unique(app.sids(ind));
                last = length(app.b);
                app.b{last+1} = 'NONE';
                app.anzahl_SID = length(app.b);
                
                % Clear the EditFields & Show SIDs on 1st Page
                for i = 2:5
                    app.c{i}.Value = '';
                    app.c{i}.Value = ['< ' app.b{i}];
                end
                
                app.FPlanIndex = 4;
                
            elseif app.FPlanIndex == 34 % Wenn RWY aus 5th Page ausgewählt wurde
                
                app.EditField_M2_Upper.Value = 'SIDS AVAILABLE';
                app.EditField_L1.Value = app.a{20};
                app.depature_RWY_selected = app.a{20};
                app.OrgDes_counter = 1;
                
                %   Find all SIDs that support this departure runway
                ind = find(contains(app.sids_rwys,{app.a{20}, 'ALL'}));
                
                %   Show the unique SIDs
                app.b = unique(app.sids(ind));
                last = length(app.b);
                app.b{last+1} = 'NONE';
                app.anzahl_SID = length(app.b);
                
                % Clear the EditFields & Show SIDs on 1st Page
                for i = 2:5
                    app.c{i}.Value = '';
                    app.c{i}.Value = ['< ' app.b{i}];
                end
                
                app.FPlanIndex = 4;
            end
            
            if app.PageIndex == 1
                
                value = app.EditField_Scratch.Value;
                app.EditField_L5.Value = value;
                
                % Clear the Scratch Pad after Value has been transmitted
                app.EditField_Scratch.Value = '';
                app.integerNumber = 0;
                app.dotNumber = '';
                app.enableDot = 0; % disable dot number
                
                app.costindex = value;
                
            end
            
            if app.PhaseIndex == 2
                
                value = app.EditField_Scratch.Value;
                app.EditField_L5.Value = value;
                
                % Clear the Scratch Pad after Value has been transmitted
                app.EditField_Scratch.Value = '';
                app.integerNumber = 0;
                app.dotNumber = '';
                app.enableDot = 0; % disable dot number
                
                app.THR_RED_ACC = value;
                
            end
        end

        % Button pushed function: L6_Button
        function L6_ButtonPushed(app, event)
            
            % Für INIT-Button
            if app.PageIndex == 1 % Abfrage ob INIT A Page vorliegt
                
                value = app.EditField_Scratch.Value;
                app.EditField_L6.Value = value;
                
                % Clear the Scratch Pad after Value has been transmitted
                app.EditField_Scratch.Value = '';
                app.integerNumber = 0;
                app.dotNumber = '';
                app.enableDot = 0; % disable dot number
                
                % FL und dazugehörige Temperatur
                newvalue = str2double(value);
                [rho, p, temp, soundSpeed] = Atmos(newvalue*100*app.FT2M);
                app.FLTemperatur = temp - 273.15; % Temp in °C
                value_temp = round(app.FLTemperatur);
                value_temp = sprintf('%d',value_temp);
                if length(value) == 2
                    value = ['0' value];
                end
                result = ['FL' value ' /' value_temp '°'];
                app.EditField_L6.Value = result; 

            end
            
            % Für PERF-Button
            if app.PhaseIndex == 1 % Abfrage ob UPLINK DATA Page vorliegt
                
                %app.EditField_L6_Upper.Value = 'UPLINK';
                %app.EditField_L6.Value = '<TO DATA';
                
            elseif app.PhaseIndex == 2 % Abfrage on PERF Page vorliegt
                
                app.PhaseIndex = 1;
                
            elseif app.PhaseIndex == 3 % Abfrage ob CLB Page vorliegt
                
                app.EditField_Title.Value = 'TAKE OFF';
                
                app.EditField_L1_Upper.Value = 'V1';
                if (isempty(app.v1) == 0)
                    app.EditField_L1.Value = app.v1;
                else
                    app.EditField_L1.Value = '';
                end
                
                app.EditField_L2_Upper.Value = 'VR';
                if (isempty(app.vR) == 0)
                    app.EditField_L2.Value = app.vR;
                else
                    app.EditField_L2.Value = '';
                end
                
                app.EditField_L3_Upper.Value = 'V2';
                if (isempty(app.v2) == 0)
                    app.EditField_L3.Value = app.v2;
                else
                    app.EditField_L3.Value = '';
                end
                
                app.EditField_L4_Upper.Value = 'TRANS ALT';
                if (isempty(app.trans_ALT) == 0)
                    app.EditField_L4.Value = app.trans_ALT;
                else
                    app.EditField_L4.Value = '';
                end
                
                app.EditField_L5_Upper.Value = 'THR RED/ACC';
                if (isempty(app.THR_RED_ACC) == 0)
                    app.EditField_L5.Value = app.THR_RED_ACC;
                else
                    app.EditField_L5.Value = '';
                end
                
                app.EditField_L6_Upper.Value = 'UPLINK';
                app.EditField_L6.Value = '<TO DATA';
                
                app.EditField_M1_Upper.Value = 'FLP RETR';
                app.EditField_M1.Value = 'F=160';
                app.EditField_M2_Upper.Value = 'SLT RETR';
                app.EditField_M2.Value = 'F=192';
                app.EditField_M3_Upper.Value = 'CLEAN';
                app.EditField_M3.Value = 'F=216';
                app.EditField_M5_Upper.Value = '';
                app.EditField_M5.Value = '';
                
                app.EditField_R1_Upper.Value = 'RWY';
                if (isempty(app.RWY) == 0)
                    app.EditField_R1.Value = app.RWY;
                else
                    app.EditField_R1.Value = '';
                end
                
                app.EditField_R2_Upper.Value = 'TO SHIFT';
                if (isempty(app.shift) == 0)
                    app.EditField_R2.Value = app.shift;
                else
                    app.EditField_R2.Value = '';
                end
                
                app.EditField_R3_Upper.Value = 'FLAPS/THS';
                if (isempty(app.Flaps_THS) == 0)
                    app.EditField_R3.Value = app.Flaps_THS;
                else
                    app.EditField_R3.Value = '';
                end
                
                app.EditField_R4_Upper.Value = 'FLEX TO TEMP';
                if (isempty(app.Flex_to_Temp) == 0)
                    app.EditField_R4.Value = app.Flex_to_Temp;
                else
                    app.EditField_R4.Value = '';
                end
                
                app.EditField_R5_Upper.Value = 'ENG OUT ACC';
                if (isempty(app.Eng_out_Acc) == 0)
                    app.EditField_R5.Value = app.Eng_out_Acc;
                else
                    app.EditField_R5.Value = '';
                end
                
                app.EditField_R6_Upper.Value = 'NEXT';
                app.EditField_R6.Value = 'PHASE>';
                
                app.PhaseIndex = 2;
                
            elseif app.PhaseIndex == 4 % Abfrage ob CRZ Page vorliegt
                
                app.EditField_Title.Value = 'CLB';
                app.EditField_L1_Upper.Value = 'ACT MODE';
                app.EditField_L1.Value = 'MANAGED';
                app.EditField_L2_Upper.Value = 'CI';
                app.EditField_L2.Value = '';
                app.EditField_L3_Upper.Value = 'MANAGED';
                app.EditField_L3.Value = '';
                app.EditField_L4_Upper.Value = 'FRESEL';
                app.EditField_L4.Value = '';
                app.EditField_L5_Upper.Value = '';
                app.EditField_L5.Value = '';
                app.EditField_L6_Upper.Value = 'PREV';
                app.EditField_L6.Value = '<PHASE';
                
                app.EditField_M1_Upper.Value = 'TIME DEST';
                app.EditField_M1.Value = '';
                app.EditField_M2_Upper.Value = '';
                app.EditField_M2.Value = '';
                app.EditField_M3_Upper.Value = '';
                app.EditField_M3.Value = '';
                app.EditField_M5_Upper.Value = '';
                app.EditField_M5.Value = '';
                
                app.EditField_R1_Upper.Value = 'EFOB';
                app.EditField_R1.Value = '';
                app.EditField_R2_Upper.Value = '';
                app.EditField_R2.Value = '';
                app.EditField_R3_Upper.Value = '';
                app.EditField_R3.Value = '';
                app.EditField_R4_Upper.Value = '';
                app.EditField_R4.Value = '';
                app.EditField_R5_Upper.Value = '';
                app.EditField_R5.Value = '';
                app.EditField_R6_Upper.Value = 'NEXT';
                app.EditField_R6.Value = 'PHASE>';
                
                app.PhaseIndex = 3;
                
            elseif app.PhaseIndex == 5 % Abfrage ob DES Page vorliegt
                
                app.EditField_Title.Value = 'CRZ';
                app.EditField_L1_Upper.Value = 'ACT MODE';
                app.EditField_L1.Value = 'MANAGED';
                app.EditField_L2_Upper.Value = 'CI';
                app.EditField_L2.Value = '';
                app.EditField_L3_Upper.Value = 'MANAGED';
                app.EditField_L3.Value = '';
                app.EditField_L4_Upper.Value = 'FRESEL';
                app.EditField_L4.Value = '';
                app.EditField_L5_Upper.Value = '';
                app.EditField_L5.Value = '';
                app.EditField_L6_Upper.Value = 'PREV';
                app.EditField_L6.Value = '<PHASE';
                
                app.EditField_M1_Upper.Value = 'TIME DEST';
                app.EditField_M1.Value = '';
                app.EditField_M2_Upper.Value = '';
                app.EditField_M2.Value = '';
                app.EditField_M3_Upper.Value = '';
                app.EditField_M3.Value = '';
                app.EditField_M5_Upper.Value = '';
                app.EditField_M5.Value = '';
                
                app.EditField_R1_Upper.Value = 'EFOB';
                app.EditField_R1.Value = '';
                app.EditField_R2_Upper.Value = '';
                app.EditField_R2.Value = '';
                app.EditField_R3_Upper.Value = '';
                app.EditField_R3.Value = '';
                app.EditField_R4_Upper.Value = '';
                app.EditField_R4.Value = '';
                app.EditField_R5_Upper.Value = 'DES CABIN RATE';
                app.EditField_R5.Value = '-350 FT/MIN';
                app.EditField_R6_Upper.Value = 'NEXT';
                app.EditField_R6.Value = 'PHASE>';
                
                app.PhaseIndex = 4;
                
            elseif app.PhaseIndex == 6 % Abfrage ob APPR Page vorliegt
                
                app.EditField_Title.Value = 'DES';
                app.EditField_L1_Upper.Value = 'ACT MODE';
                app.EditField_L1.Value = 'MANAGED';
                app.EditField_L2_Upper.Value = 'CI';
                app.EditField_L2.Value = '';
                app.EditField_L3_Upper.Value = 'MANAGED';
                app.EditField_L3.Value = '';
                app.EditField_L4_Upper.Value = 'FRESEL';
                app.EditField_L4.Value = '';
                app.EditField_L5_Upper.Value = '';
                app.EditField_L5.Value = '';
                app.EditField_L6_Upper.Value = 'PREV';
                app.EditField_L6.Value = '<PHASE';
                
                app.EditField_M1_Upper.Value = 'TIME DEST';
                app.EditField_M1.Value = '';
                app.EditField_M2_Upper.Value = '';
                app.EditField_M2.Value = '';
                app.EditField_M3_Upper.Value = '';
                app.EditField_M3.Value = '';
                app.EditField_M5_Upper.Value = '';
                app.EditField_M5.Value = '';
                
                app.EditField_R1_Upper.Value = 'EFOB';
                app.EditField_R1.Value = '';
                app.EditField_R2_Upper.Value = '';
                app.EditField_R2.Value = '';
                app.EditField_R3_Upper.Value = '';
                app.EditField_R3.Value = '';
                app.EditField_R4_Upper.Value = '';
                app.EditField_R4.Value = '';
                app.EditField_R5_Upper.Value = '';
                app.EditField_R5.Value = '';
                app.EditField_R6_Upper.Value = 'NEXT';
                app.EditField_R6.Value = 'PHASE>';
                
                app.PhaseIndex = 5;
                
            else
                
            end
            if app.FPlanIndex == 1
                
                app.EditField_R1.FontColor = [1 1 1];
                app.EditField_R2.FontColor = [1 1 1];
                app.EditField_R3.FontColor = [1 1 1];
                app.EditField_R4.FontColor = [1 1 1];
                app.EditField_R5.FontColor = [1 1 1];
                app.EditField_R6.FontColor = [1 1 1];
                
                result = ['LAT REV FROM ' app.destination];
                app.EditField_Title.Value = result;
               
                app.EditField_L1_Upper.Value = '';
                app.EditField_L1.Value = '';
                app.EditField_L2_Upper.Value = '';
                app.EditField_L2.Value = '';
                app.EditField_L3_Upper.Value = '';
                app.EditField_L3.Value = '';
                app.EditField_L4_Upper.Value = '';
                app.EditField_L4.Value = '';
                app.EditField_L5_Upper.Value = '';
                app.EditField_L5.Value = '';
                app.EditField_L6_Upper.Value = '';
                app.EditField_L6.Value = '<RETURN';
                
                app.EditField_M1_Upper.Value = '';
                app.EditField_M1.Value = '';
                app.EditField_M2_Upper.Value = '';
                app.EditField_M2.Value = '';
                app.EditField_M3_Upper.Value = '';
                app.EditField_M3.Value = '';
                app.EditField_M4_Upper.Value = '';
                app.EditField_M4.Value = '';
                app.EditField_M5_Upper.Value = '';
                app.EditField_M5.Value = '';
                app.EditField_M6_Upper.Value = '';
                app.EditField_M6.Value = '';
                
                app.EditField_R1_Upper.Value = '';
                app.EditField_R1.Value = 'ARRIVAL>';
                app.EditField_R2_Upper.Value = '';
                app.EditField_R2.Value = '';
                app.EditField_R3_Upper.Value = 'NEXT WPT';
                app.EditField_R3.Value = '[ ]';
                app.EditField_R4_Upper.Value = '';
                app.EditField_R4.Value = '';
                app.EditField_R5_Upper.Value = '';
                app.EditField_R5.Value = '';
                app.EditField_R6_Upper.Value = '';
                app.EditField_R6.Value = '';
                
                app.FPlanIndex = 6;
                app.WPTIndex = 0;
                
            elseif app.FPlanIndex == 2
                
                app.EditField_Title.Value = 'F-PLAN       <-- -->';
                
                app.EditField_L1_Upper.Value = 'FROM';
                app.EditField_L1.Value = app.origin;
                app.EditField_L2_Upper.Value = '';
                app.EditField_L2.Value = '-------------';
                app.EditField_L3_Upper.Value = '';
                app.EditField_L3.Value = '';
                app.EditField_L4_Upper.Value = '';
                app.EditField_L4.Value = '';
                app.EditField_L5_Upper.Value = '';
                app.EditField_L5.Value = '';
                app.EditField_L6_Upper.Value = 'DEST';
                app.EditField_L6.Value = app.destination;
                
                app.EditField_M1_Upper.Value = 'TIME';
                app.EditField_M1.Value = '0000';
                app.EditField_M2_Upper.Value = '';
                app.EditField_M2.Value = 'F-PLAN DISCONTINUITY';
                app.EditField_M3_Upper.Value = '';
                app.EditField_M3.Value = '';
                app.EditField_M4_Upper.Value = '';
                app.EditField_M4.Value = '';
                app.EditField_M5_Upper.Value = '';
                app.EditField_M5.Value = '';
                app.EditField_M6_Upper.Value = 'TIME';
                app.EditField_M6.Value = '';
                
                app.EditField_R1_Upper.Value = 'SPD/ALT';
                app.EditField_R1.Value = '---/---';
                app.EditField_R2_Upper.Value = '';
                app.EditField_R2.Value = '-------------';
                app.EditField_R3_Upper.Value = '';
                app.EditField_R3.Value = '';
                app.EditField_R4_Upper.Value = '';
                app.EditField_R4.Value = '';
                app.EditField_R5_Upper.Value = '';
                app.EditField_R5.Value = '';
                app.EditField_R6_Upper.Value = 'DIST    EFOB';
                app.EditField_R6.Value = '';
                
                app.FPlanIndex = 1;
                app.WPTIndex = 1;
                
            elseif app.FPlanIndex == 3
                
                result = ['LAT REV FROM ' app.origin];
                app.EditField_Title.Value = result;
                
                app.EditField_L1_Upper.Value = '';
                app.EditField_L1.Value = '<DEPARTURE';
                app.EditField_L2_Upper.Value = '';
                app.EditField_L2.Value = '[  ]';
                app.EditField_L3_Upper.Value = '';
                app.EditField_L3.Value = '<HOLD';
                app.EditField_L4_Upper.Value = 'ENABLE';
                app.EditField_L4.Value = '<-ALTN';
                app.EditField_L5_Upper.Value = '';
                app.EditField_L5.Value = '';
                app.EditField_L6_Upper.Value = '';
                app.EditField_L6.Value = '<RETURN';
                
                app.EditField_M1_Upper.Value = '';
                app.EditField_M1.Value = '';
                app.EditField_M2_Upper.Value = '';
                app.EditField_M2.Value = '';
                app.EditField_M3_Upper.Value = '';
                app.EditField_M3.Value = '';
                app.EditField_M4_Upper.Value = '';
                app.EditField_M4.Value = '';
                app.EditField_M5_Upper.Value = '';
                app.EditField_M5.Value = '';
                app.EditField_M6_Upper.Value = '';
                app.EditField_M6.Value = '';
                
                app.EditField_R1_Upper.Value = '';
                app.EditField_R1.Value = 'FIX INFO>';
                app.EditField_R2_Upper.Value = 'LL WING/INCR/NO';
                app.EditField_R2.Value = '[ ]°/[ ]°/[]';
                app.EditField_R3_Upper.Value = 'NEXT WPT';
                app.EditField_R3.Value = '[ ]';
                app.EditField_R4_Upper.Value = 'NEW DEST';
                app.EditField_R4.Value = '[ ]';
                app.EditField_R5_Upper.Value = '';
                app.EditField_R5.Value = 'AIRWAYS>';
                app.EditField_R6_Upper.Value = '';
                app.EditField_R6.Value = '';
                
                app.FPlanIndex = 2;
                
            elseif app.FPlanIndex == 4
                
                result = ['DEPATURES FROM ' app.origin];
                app.EditField_Title.Value = result;
                
                app.EditField_L1_Upper.Value = 'RWY';
                app.EditField_L1.Value = app.depature_RWY_selected;
                
                % Clear the EditFields
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                for i = 1:app.anzahl_RWY
                    
                    app.c{i+1}.Value = ['<' app.a{i}];
                end
                
                app.EditField_L6.Value = '<RETURN';
                
                app.EditField_M1_Upper.Value = 'SID';
                app.EditField_M1.Value = '------';
                
                app.EditField_M2_Upper.Value = 'AVAILABLE RUNWAYS';
                
                app.EditField_R1_Upper.Value = 'TRANS';
                app.EditField_R1.Value = '------';
                
                app.FPlanIndex = 3;
                
                
            elseif app.FPlanIndex == 5
                
                result = ['DEPATURES FROM ' app.origin];
                app.EditField_Title.Value = result;
                
                app.EditField_L1_Upper.Value = 'RWY';
                app.EditField_L1.Value = '------';
                
                % Clear the EditFields
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                for i = 1:app.anzahl_RWY
                    
                    app.c{i+1}.Value = ['<' app.a{i}];
                end
                
                app.EditField_L6_Upper.Value = '';
                app.EditField_L6.Value = '<RETURN';
                
                app.EditField_M1_Upper.Value = 'SID';
                app.EditField_M1.Value = '------';
                
                app.EditField_M2_Upper.Value = 'AVAILABLE RUNWAYS';
                
                app.EditField_R1_Upper.Value = 'TRANS';
                app.EditField_R1.Value = '------';
                app.EditField_R6_Upper.Value = '';
                app.EditField_R6.Value = '';
                
                app.FPlanIndex = 3;
                
            elseif app.FPlanIndex == 6
                
                app.EditField_Title.Value = 'F-PLAN       <-- -->';
                
                app.EditField_L1_Upper.Value = 'FROM';
                app.EditField_L1.Value = app.origin;
                app.EditField_L2_Upper.Value = '';
                app.EditField_L2.Value = '-------------';
                app.EditField_L3_Upper.Value = '';
                app.EditField_L3.Value = '';
                app.EditField_L4_Upper.Value = '';
                app.EditField_L4.Value = '';
                app.EditField_L5_Upper.Value = '';
                app.EditField_L5.Value = '';
                app.EditField_L6_Upper.Value = 'DEST';
                app.EditField_L6.Value = app.destination;
                
                app.EditField_M1_Upper.Value = 'TIME';
                app.EditField_M1.Value = '0000';
                app.EditField_M2_Upper.Value = '';
                app.EditField_M2.Value = 'F-PLAN DISCONTINUITY';
                app.EditField_M3_Upper.Value = '';
                app.EditField_M3.Value = '';
                app.EditField_M4_Upper.Value = '';
                app.EditField_M4.Value = '';
                app.EditField_M5_Upper.Value = '';
                app.EditField_M5.Value = '';
                app.EditField_M6_Upper.Value = 'TIME';
                app.EditField_M6.Value = '';
                
                app.EditField_R1_Upper.Value = 'SPD/ALT';
                app.EditField_R1.Value = '---/---';
                app.EditField_R2_Upper.Value = '';
                app.EditField_R2.Value = '-------------';
                app.EditField_R3_Upper.Value = '';
                app.EditField_R3.Value = '';
                app.EditField_R4_Upper.Value = '';
                app.EditField_R4.Value = '';
                app.EditField_R5_Upper.Value = '';
                app.EditField_R5.Value = '';
                app.EditField_R6_Upper.Value = 'DIST    EFOB';
                app.EditField_R6.Value = '';
                
                app.FPlanIndex = 1;
                app.WPTIndex = 1;
                
            elseif app.FPlanIndex == 7
                
                result = ['LAT REV FROM ' app.destination];
                app.EditField_Title.Value = result;
                
                app.EditField_L1_Upper.Value = '';
                app.EditField_L1.Value = '';
                app.EditField_L2_Upper.Value = '';
                app.EditField_L2.Value = '';
                app.EditField_L3_Upper.Value = '';
                app.EditField_L3.Value = '';
                app.EditField_L4_Upper.Value = '';
                app.EditField_L4.Value = '';
                app.EditField_L5_Upper.Value = '';
                app.EditField_L5.Value = '';
                app.EditField_L6_Upper.Value = '';
                app.EditField_L6.Value = '<RETURN';
                
                app.EditField_M1_Upper.Value = '';
                app.EditField_M1.Value = '';
                app.EditField_M2_Upper.Value = '';
                app.EditField_M2.Value = '';
                app.EditField_M3_Upper.Value = '';
                app.EditField_M3.Value = '';
                app.EditField_M4_Upper.Value = '';
                app.EditField_M4.Value = '';
                app.EditField_M5_Upper.Value = '';
                app.EditField_M5.Value = '';
                app.EditField_M6_Upper.Value = '';
                app.EditField_M6.Value = '';
                
                app.EditField_R1_Upper.Value = '';
                app.EditField_R1.Value = 'ARRIVAL>';
                app.EditField_R2_Upper.Value = '';
                app.EditField_R2.Value = '';
                app.EditField_R3_Upper.Value = 'NEXT WPT';
                app.EditField_R3.Value = '[ ]';
                app.EditField_R4_Upper.Value = '';
                app.EditField_R4.Value = '';
                app.EditField_R5_Upper.Value = '';
                app.EditField_R5.Value = '';
                app.EditField_R6_Upper.Value = '';
                app.EditField_R6.Value = '';
                
                app.FPlanIndex = 6;
                
            elseif app.FPlanIndex == 8
                
                result = ['ARRIVAL TO ' app.destination];
                app.EditField_Title.Value = result;
                
                %, Anzahl der möglichen RWYs
                app.anzahl_RWY_arrival = length(app.approaches.rwys);
                
                app.EditField_L1_Upper.Value = 'APPR';
                if (isempty(app.approach_selected) == 0) && app.OrgDes_counter == 1 || (isempty(app.approach_selected) == 0) && app.OrgDes_counter == 2 || (isempty(app.approach_selected) == 0) && app.OrgDes_counter == 3
                    app.EditField_L1.Value = app.approach_selected;
                else
                    app.EditField_L1.Value = '------';
                end
                
                app.EditField_L2_Upper.Value = '';
                app.EditField_L3_Upper.Value = '';
                app.EditField_L4_Upper.Value = '';
                app.EditField_L5_Upper.Value = '';
                app.EditField_L6_Upper.Value = '';
                
                % Clear the EditFields
                for i = 3:5
                    app.c{i}.Value = '';
                end
                
                for i = 1:app.anzahl_RWY_arrival
                    
                    yyy(i) = app.approaches.full(i);
                    ppp(i) = convertCharsToStrings(yyy(i));
                    
                    app.d{i} = convertStringsToChars(ppp(i));
                    app.c{i+2}.Value = ['<' app.d{i}];
                end
                
                app.EditField_L6.Value = '<RETURN';
                
                app.EditField_M1_Upper.Value = 'VIA';
                if (isempty(app.TRANS_selected) == 0) && app.OrgDes_counter == 3
                    app.EditField_M1.Value = app.TRANS_selected;
                else
                    app.EditField_M1.Value = '------';
                end
                app.EditField_M2_Upper.Value = '';
                app.EditField_M2.Value = '';
                app.EditField_M3_Upper.Value = 'APPR AVAILABLE';
                app.EditField_M3.Value = '';
                app.EditField_M4_Upper.Value = '';
                app.EditField_M4.Value = '';
                app.EditField_M5_Upper.Value = '';
                app.EditField_M5.Value = '';
                app.EditField_M6_Upper.Value = '';
                app.EditField_M6.Value = '';
                
                app.EditField_R1_Upper.Value = 'STAR';
                if (isempty(app.STAR_selected) == 0) && app.OrgDes_counter == 2 || (isempty(app.approach_selected) == 0) && app.OrgDes_counter == 3
                    app.EditField_R1.Value = app.STAR_selected;
                else
                    app.EditField_R1.Value = '------';
                end
                app.EditField_R2_Upper.Value = '';
                app.EditField_R2.Value = '';
                app.EditField_R3_Upper.Value = '';
                app.EditField_R3.Value = '';
                app.EditField_R4_Upper.Value = '';
                app.EditField_R4.Value = '';
                app.EditField_R5_Upper.Value = '';
                app.EditField_R5.Value = '';
                app.EditField_R6_Upper.Value = '';
                app.EditField_R6.Value = '';
                
                app.FPlanIndex = 7;
                
            elseif app.FPlanIndex == 9
                
                % Clear the EditFields
                for i = 3:5
                    app.c{i}.Value = '';
                end
                
                for i = 1:app.anzahl_STAR
                    
                    app.c{i+2}.Value = ['<' app.e{i}];
                end
                app.EditField_L1_Upper.Value = 'APPR';
                if (isempty(app.approach_selected) == 0) && app.OrgDes_counter == 1 || (isempty(app.approach_selected) == 0) && app.OrgDes_counter == 2 || (isempty(app.approach_selected) == 0) && app.OrgDes_counter == 3
                    app.EditField_L1.Value = app.approach_selected;
                else
                    app.EditField_L1.Value = '------';
                end
                
                app.EditField_M1_Upper.Value = 'VIA';
                if (isempty(app.TRANS_selected) == 0) && app.OrgDes_counter == 3
                    app.EditField_M1.Value = app.TRANS_selected;
                else
                    app.EditField_M1.Value = '------';
                end
                
                app.EditField_M3_Upper.Value = 'STARS AVAILABLE';
                
                app.EditField_R1_Upper.Value = 'STAR';
                if (isempty(app.STAR_selected) == 0) && app.OrgDes_counter == 2 || (isempty(app.approach_selected) == 0) && app.OrgDes_counter == 3
                    app.EditField_R1.Value = app.STAR_selected;
                else
                    app.EditField_R1.Value = '------';
                end
                
                app.FPlanIndex = 8;
                
            elseif app.FPlanIndex == 10
                
                result = ['ARRIVAL TO ' app.destination];
                app.EditField_Title.Value = result;
                
                app.EditField_L1_Upper.Value = 'APPR';
                app.EditField_L1.Value = '------';
                
                % Clear the EditFields
                for i = 3:5
                    app.c{i}.Value = '';
                end
                
                for i = 1:app.anzahl_RWY_arrival
                    app.c{i+2}.Value = ['<' app.d{i}];
                end
                
                app.EditField_L6.Value = '<RETURN';
                
                app.EditField_M1_Upper.Value = 'VIA';
                app.EditField_M1.Value = '------';
                app.EditField_M3_Upper.Value = 'APPR AVAILABLE';
                
                app.EditField_R1_Upper.Value = 'STAR';
                app.EditField_R1.Value = '------';
                
                app.FPlanIndex = 7;
            end
            
        end

        % Button pushed function: R1_Button
        function R1_ButtonPushed(app, event)
            
            value = app.EditField_Scratch.Value;
            app.EditField_R1.Value = value;
            
            
            % Clear the Scratch Pad after Value has been transmitted
            app.EditField_Scratch.Value = '';
            app.integerNumber = 0;
            app.dotNumber = '';
            app.enableDot = 0; % disable dot number
            
            if app.FPlanIndex == 6
                
                result = ['ARRIVAL TO ' app.destination];
                app.EditField_Title.Value = result;
                
                %, Anzahl der möglichen RWYs
                app.anzahl_RWY_arrival = length(app.approaches.rwys);
                
                app.EditField_L1_Upper.Value = 'APPR';
                if (isempty(app.approach_selected) == 0) && app.OrgDes_counter == 1 || (isempty(app.approach_selected) == 0) && app.OrgDes_counter == 2 || (isempty(app.approach_selected) == 0) && app.OrgDes_counter == 3
                    app.EditField_L1.Value = app.approach_selected;
                else
                    app.EditField_L1.Value = '------';
                end
                
                app.EditField_L2_Upper.Value = '';
                app.EditField_L3_Upper.Value = '';
                app.EditField_L4_Upper.Value = '';
                app.EditField_L5_Upper.Value = '';
                app.EditField_L6_Upper.Value = '';
                
                % Clear the EditFields & FontColor
                for i = 3:5
                    app.c{i}.Value = '';
                    app.c{i}.FontColor = [0.3010 0.7450 0.9330];
                end
                app.c{1}.FontColor = [1 1 1];
                app.c{2}.FontColor = [1 1 1];
                
                for i = 1:app.anzahl_RWY_arrival
                    
                    yyy(i) = app.approaches.full(i);
                    ppp(i) = convertCharsToStrings(yyy(i));
                    
                    app.d{i} = convertStringsToChars(ppp(i));
                    app.c{i+2}.Value = ['<' app.d{i}];
                end
                
                app.EditField_L6.Value = '<RETURN';
                
                app.EditField_M1_Upper.Value = 'VIA';
                if (isempty(app.STAR_selected) == 0) && app.OrgDes_counter == 3
                    app.EditField_M1.Value = app.TRANS_selected;
                else
                    app.EditField_M1.Value = '------';
                end
                app.EditField_M2_Upper.Value = '';
                app.EditField_M2.Value = '';
                app.EditField_M3_Upper.Value = 'APPR AVAILABLE';
                app.EditField_M3.Value = '';
                app.EditField_M4_Upper.Value = '';
                app.EditField_M4.Value = '';
                app.EditField_M5_Upper.Value = '';
                app.EditField_M5.Value = '';
                app.EditField_M6_Upper.Value = '';
                app.EditField_M6.Value = '';
                
                app.EditField_R1_Upper.Value = 'STAR';
                if (isempty(app.approach_selected) == 0) && app.OrgDes_counter == 2 || (isempty(app.STAR_selected) == 0) && app.OrgDes_counter == 3
                    app.EditField_R1.Value = app.STAR_selected;
                else
                    app.EditField_R1.Value = '------';
                end
                app.EditField_R2_Upper.Value = '';
                app.EditField_R2.Value = '';
                app.EditField_R3_Upper.Value = '';
                app.EditField_R3.Value = '';
                app.EditField_R4_Upper.Value = '';
                app.EditField_R4.Value = '';
                app.EditField_R5_Upper.Value = '';
                app.EditField_R5.Value = '';
                app.EditField_R6_Upper.Value = '';
                app.EditField_R6.Value = '';
                
                app.FPlanIndex = 7;
                
            end
            
            if app.PageIndex == 1
                
                % Seperate Origin / Destination
                newvalue = string(value);
                code = strsplit(newvalue,'/');
                app.input = value; % Origin/Destination
                app.origin = convertStringsToChars(code(1));
                app.destination = convertStringsToChars(code(2));
                
                % Make filenames
                app.origin_filename = ['Airports' filesep app.origin '.txt'];
                app.destination_filename = ['Airports' filesep app.destination '.txt'];
                
                % Load the arrival trajectory
                [app.approaches, app.transitions, app.stars] = load_arrivals(app.destination_filename);
                
                % Load the depature trajectory
                [app.departure_rwys, app.sids_rwys, app.sids, app.rwys_llh] = load_departures(app.origin_filename);
                
                % Co RTE & ALTN become NONE
                app.EditField_L1.Value = 'NONE';
                app.EditField_L2.Value = 'NONE';
                
                % ALIGN IRS
                app.EditField_R3.FontColor = [0.9290 0.6940 0.1250];
                app.EditField_R3.Value = 'ALIGN IRS>';
                app.IRScounter = 0;
                app.OrgDes_counter = 0;
                
                % Set Latitude & Longitude from first RWY. Location of
                % Airport needed
                app.origin_lat = num2str(app.rwys_llh(1,1));
                app.origin_lon = num2str(app.rwys_llh(2,1));
                app.EditField_L4.Value = app.origin_lat;
                app.EditField_R4.Value = app.origin_lon;
                
                if app.PhaseIndex == 2
                    
                    app.RWY = value;
                    
                end
                
                if app.PageIndex == 2
                    
                    app.ZFWCG_ZFW = value;
                end
            end
        end

        % Button pushed function: R2_Button
        function R2_ButtonPushed(app, event)
            value = app.EditField_Scratch.Value;
            app.EditField_R2.Value = value;
            
            % Clear the Scratch Pad after Value has been transmitted
            app.EditField_Scratch.Value = '';
            app.integerNumber = 0;
            app.dotNumber = '';
            app.enableDot = 0; % disable dot number
            
            if app.PhaseIndex == 2
                
                app.shift = value;
                
            end
            
            if app.PageIndex == 2
                
                app.Block = value;
                
            end
        end

        % Button pushed function: R3_Button
        function R3_ButtonPushed(app, event)
            if app.PageIndex == 1
                
                if (isempty(app.input) == 0)
                app.EditField_R3.Value = '';
                app.IRScounter = 1;
                end
                
            end
            
            if app.PhaseIndex == 2
            value = app.EditField_Scratch.Value;
            app.EditField_R3.Value = value;
            
            % Clear the Scratch Pad after Value has been transmitted
            app.EditField_Scratch.Value = '';
            app.integerNumber = 0;
            app.dotNumber = '';
            app.enableDot = 0; % disable dot number
            
            app.Flaps_THS = value;
               
            end
        end

        % Button pushed function: R4_Button
        function R4_ButtonPushed(app, event)
            value = app.EditField_Scratch.Value;
            app.EditField_R4.Value = value;
            
            % Clear the Scratch Pad after Value has been transmitted
            app.EditField_Scratch.Value = '';
            app.integerNumber = 0;
            app.dotNumber = '';
            app.enableDot = 0; % disable dot number
            
            if app.PhaseIndex == 2
                
                app.Flex_to_Temp = value;
                
            end
        end

        % Button pushed function: R5_Button
        function R5_ButtonPushed(app, event)
            value = app.EditField_Scratch.Value;
            app.EditField_R5.Value = value;
            
            % Clear the Scratch Pad after Value has been transmitted
            app.EditField_Scratch.Value = '';
            app.integerNumber = 0;
            app.dotNumber = '';
            app.enableDot = 0; % disable dot number
            
            if app.PhaseIndex == 2
                
                app.Eng_out_Acc = value;
                
            end
        end

        % Button pushed function: R6_Button
        function R6_ButtonPushed(app, event)
            if app.DataIndex == 3 && app.aircraft_choice_index == 1 % Wenn DATA-Page ausgewählt ist
                
                % Reset EditFields
                for i = 1:5
                app.c{i}.Value = '';
                app.m{i}.Value = '';
                app.r{i}.Value = '';
                app.cu{i}.Value = '';
                app.mu{i}.Value = '';
                app.ru{i}.Value = '';
                end
                app.EditField_L1.Value = '';            
                app.EditField_M1.Value = '';
                app.EditField_R1.Value = '';            
                app.EditField_L6_Upper.Value = '';
                app.EditField_M6_Upper.Value = '';
                app.EditField_R6_Upper.Value = '';
                
                app.EditField_Title.Value = 'Choose the Aircraft Type';
                
                %   Load the aircraft for which BADA performance files are available
                app.aircraft_bada = load_aircraft('Aircraft');
                
                for i = 1:length(app.aircraft_bada)
                    app.c{i}.Value = ['< ' app.aircraft_bada{i}];
                end                
                app.DataIndex = 2;
            end
            
            if app.FPlanIndex == 10 % Abfrage ob TEMP INSERT* in FPLAN-Page nach Arrival
                
                % FontColor
                for i = 1:5
                    app.c{i}.FontColor = [0 0.6 0];
                    app.cu{i+1}.FontSize = 12;
                end
                
                % Counter, dass alle Waypoints in Vertical Trajectory
                % geladen werden
                app.Counter = 2;
                
                %   Extract the APPROACH-related waypoints
                ii = find(contains(app.approaches.full,app.approach_selected));
                [app.approach_wpts] = load_approach_waypoints(app.destination_filename, app.approaches.types{ii});
                
                %   Extract the TRANSITION-related waypoints
                [app.transition_wpts] = load_transition_waypoints(app.destination_filename, app.TRANS_selected, app.approaches.types{ii});
                
                %   Extract the STAR-related waypoints
                [app.star_wpts] = load_star_waypoints(app.destination_filename, app.STAR_selected, app.approaches.rwys{ii});
                               
                %   Combine all waypoints
                app.all_wpts = combine_wpt_structs(app.rwy_wpts,app.sid_wpts);
                app.all_wpts = combine_wpt_structs(app.all_wpts, app.star_wpts);
                app.all_wpts = combine_wpt_structs(app.all_wpts, app.transition_wpts);
                app.all_wpts = combine_wpt_structs(app.all_wpts, app.approach_wpts);
                
                %   Get rid off duplicates
                app.all_wpts = remove_duplicates(app.all_wpts);
                app.anzahl_all_wpts = length(app.all_wpts.name);
                
                % -------------------------------------------------------
                %   INSERT YOUR LATERAL PROFILE ROUTINE HERE
                % -------------------------------------------------------
                
                % Get the latitudes and longitude for the lateral trajectory
                [app.all_wpts, app.lat, app.lon, app.lat_wpts, app.lon_wpts] = process_lateral_profile(app.all_wpts);
                
                % -------------------------------------------------------
                %   INSERT YOUR VERTICAL PROFILE ROUTINE HERE
                % -------------------------------------------------------
                
                app.aircraft = app.ac; % weil 'ac' variable sonst global überschrieben wird
                %[app.all_wpts,t,V,h,r,tx,x,w,Vcas] = vertical_trajectory(app.aircraft,app.all_wpts,app.Counter);
                                            
                app.EditField_Title.Value = 'F-PLAN       <-- -->';
                
                app.EditField_L1_Upper.Value = 'FROM';
                app.EditField_L1.Value = app.origin;
                
                app.EditField_L6_Upper.Value = 'DEST';
                app.EditField_L6.Value = app.destination;
                
                app.EditField_M1_Upper.Value = 'TIME';
                app.EditField_M1.Value = '0000';
                
                app.EditField_M6_Upper.Value = 'TIME';
                app.EditField_M6.Value = '';
                
                app.EditField_R1_Upper.Value = 'SPD/ALT';
                app.EditField_R1.Value = '---/---';
                
                app.EditField_R6_Upper.Value = 'DIST';
                app.EditField_R6.Value = 'EFOB';
                
                if exist('app.all_wpts.dist')
                    for i = 1:app.anzahl_all_wpts
                        [wpt_name,result,course,time,distance] = get_FPlan_data(app.all_wpts,i);
                        if strcmp(wpt_name,result) == 1
                            app.c{i}.FontColor = [1 1 1];                     
                        else
                            app.c{i}.FontColor = [0 0.6 0];                    
                        end
                        app.cu{i+1}.Value = course;
                        app.c{i}.Value = wpt_name;
                        app.m{1}.Value = '';
                        app.m{i}.Value = distance;
                        %app.m{i}.Value = time;
                       
                        app.r{i}.FontColor = [0.75 0 0.75];
                        app.r{i}.Value = result;                           
                    end
                end
                
                plot(app.NavigationDisplay_LFT,app.lon_wpts,app.lat_wpts, 'b*');
                hold(app.NavigationDisplay_LFT,"on");
                plot(app.NavigationDisplay_LFT,app.lon,app.lat,'m', 'LineWidth',2);
                hold(app.NavigationDisplay_LFT,"off");
                                
                app.FPlanIndex = 1;
                app.WPTIndex = 1;
                
            end
            
            if app.FPlanIndex == 5 % Abfrage ob TEMP INSERT* in FPLAN-Page nach Departure
                
                % FontColor
                for i = 1:5
                    app.c{i}.FontColor = [0 0.6 0];
                    app.cu{i+1}.FontSize = 12;
                end
                
                % Counter, dass nur SID Waypoints in Vertical Trajectory
                % geladen werden
                app.Counter = 1;
                
                %   Obtain take-off runway as a first point 
                [app.rwy_wpts] = generate_takeoff_rwy_wpt(app.depature_RWY_selected, app.departure_rwys, app.rwys_llh);
                app.rwy_wpts.name = [app.origin app.depature_RWY_selected];
                
                %   Extract the SID-related waypoints
                [app.sid_wpts] = load_sid_waypoints(app.origin_filename, app.SID_selected, app.depature_RWY_selected);
                                
                %   Combine rwy & sid waypoints
                app.sid_wpts = combine_wpt_structs(app.rwy_wpts,app.sid_wpts);
                app.all_wpts = app.sid_wpts;
                app.anzahl_sid_wpts = length(app.sid_wpts.line);
                app.anzahl_all_wpts = app.anzahl_sid_wpts;
                
                % -------------------------------------------------------
                %   INSERT YOUR LATERAL PROFILE ROUTINE HERE
                % -------------------------------------------------------
                [app.all_wpts, app.lat, app.lon, app.lat_wpts, app.lon_wpts] = process_lateral_profile(app.all_wpts);
                
                % -------------------------------------------------------
                %   INSERT YOUR VERTICAL PROFILE ROUTINE HERE
                % -------------------------------------------------------
                app.aircraft = app.ac; % weil 'ac' variable sonst global überschrieben wird
                %[app.all_wpts,t,V,h,r,tx,x,w,Vcas] = vertical_trajectory(app.aircraft,app.all_wpts,app.Counter);                
                
                app.EditField_Title.Value = 'F-PLAN       <-- -->';
                
                app.EditField_L1_Upper.Value = 'FROM';
                app.EditField_L1.Value = app.origin;
                
                app.EditField_L6_Upper.Value = 'DEST';
                app.EditField_L6.Value = app.destination;
                
                app.EditField_M1_Upper.Value = 'TIME';
                app.EditField_M1.Value = '0000';
                
                app.EditField_M6_Upper.Value = 'TIME';
                app.EditField_M6.Value = '';
                
                app.EditField_R1_Upper.Value = 'SPD/ALT';
                app.EditField_R1.Value = '---/---';
                
                app.EditField_R6_Upper.Value = 'DIST';
                app.EditField_R6.Value = 'EFOB';
                
                if exist('app.all_wpts.dist')
                    for i = 1:app.anzahl_sid_wpts
                       
                        [wpt_name,result,course,time,distance] = get_FPlan_data(app.all_wpts,i);
                        if strcmp(wpt_name,result) == 1
                            app.c{i}.FontColor = [1 1 1];                     
                        else
                            app.c{i}.FontColor = [0 0.6 0];                    
                        end
                        app.cu{i+1}.Value = course;
                        app.c{i}.Value = wpt_name;
                        app.m{1}.Value = '';                                            
                        app.m{i}.Value = distance;
                        %app.m{i}.Value = time;
                        
                        app.r{i}.FontColor = [0.75 0 0.75];
                        app.r{i}.Value = result; 
                    end
                end
                % Get the latitudes and longitude for the lateral trajectory
                %[app.lat_wpts, app.lon_wpts, app.lat, app.lon] = process_lateral_trajectory(app.all_wpts);
            
                %[app.all_wpts, app.lat, app.lon] = process_lateral_profile(app.all_wpts);
                
                % Lateral FLight Trajectory Plot
                plot(app.NavigationDisplay_LFT,app.lon_wpts,app.lat_wpts, 'b*');
                hold(app.NavigationDisplay_LFT,"on");
                plot(app.NavigationDisplay_LFT,app.lon,app.lat,'m', 'LineWidth',2);
                hold(app.NavigationDisplay_LFT,"off");                
                
                
                % Vertical Flight Trajectory Plot
                if exist('app.all_wpts.dist_sum')
                    dist_sum    = cell2mat(app.all_wpts.dist_sum);                                
                    alt_top     = cell2mat(app.all_wpts.alt_top);
                    constraint  = cell2mat(app.all_wpts.alt_constraint);
                    limit_none  = find(constraint == 0);
                    limit_at    = find(constraint == 1);
                    limit_above = find(constraint == 2);
                    limit_below = find(constraint == 3);
                    constraint_none  = alt_top(limit_none);
                    dist_sum_none    = dist_sum(limit_none);               
                    constraint_at    = alt_top(limit_at);
                    dist_sum_at      = dist_sum(limit_at);
                    constraint_above = alt_top(limit_above);
                    dist_sum_above   = dist_sum(limit_above);
                    constraint_below = alt_top(limit_below);
                    dist_sum_below   = dist_sum(limit_below);
                    spd_value   = cell2mat(app.all_wpts.spd_value);
                                   
                    plot(app.NavigationDisplay_VFT,dist_sum_at*app.M2NM,constraint_at, 'r*');
                    hold(app.NavigationDisplay_VFT,"on");
                    plot(app.NavigationDisplay_VFT,dist_sum_none*app.M2NM,constraint_none, 'k*');
                    hold(app.NavigationDisplay_VFT,"on");
                    plot(app.NavigationDisplay_VFT,dist_sum_above*app.M2NM,constraint_above, 'g*');
                    hold(app.NavigationDisplay_VFT,"on");
                    plot(app.NavigationDisplay_VFT,dist_sum_below*app.M2NM,constraint_below, 'b*');
                    hold(app.NavigationDisplay_VFT,"on");
                    plot(app.NavigationDisplay_VFT,r*app.M2NM,h*app.M2FT,'m', 'LineWidth',2);
                    
                    % Speed Indicator Plot
                    % TAS mus in CAS umgewandlet werden 
                    plot(app.SpeedDisplay,r*app.M2NM,Vcas*app.MPS2KTS,'LineWidth',2);
                    hold(app.SpeedDisplay,"on");
                    plot(app.SpeedDisplay,dist_sum*app.M2NM,spd_value, 'r*');
                    
                    % Weight Distribution
                    plot(app.WeightDisplay,r*app.M2NM,w*app.G_CONST,'LineWidth',2);
                    
                end
                app.FPlanIndex = 1;
                app.WPTIndex= 1;
                
            end
            if app.PhaseIndex == 1 % Abfrage ob UPDATE DATA Phase vorliegt
                
                app.EditField_Title.Value = 'TAKE OFF';
                
                app.EditField_L1_Upper.Value = 'V1';
                if (isempty(app.v1) == 0)
                    app.EditField_L1.Value = app.v1;
                else
                    app.EditField_L1.Value = '';
                end
                
                app.EditField_L2_Upper.Value = 'VR';
                if (isempty(app.vR) == 0)
                    app.EditField_L2.Value = app.vR;
                else
                    app.EditField_L2.Value = '';
                end
                
                app.EditField_L3_Upper.Value = 'V2';
                if (isempty(app.v2) == 0)
                    app.EditField_L3.Value = app.v2;
                else
                    app.EditField_L3.Value = '';
                end
                
                app.EditField_L4_Upper.Value = 'TRANS ALT';
                if (isempty(app.trans_ALT) == 0)
                    app.EditField_L4.Value = app.trans_ALT;
                else
                    app.EditField_L4.Value = '';
                end
                
                app.EditField_L5_Upper.Value = 'THR RED/ACC';
                if (isempty(app.THR_RED_ACC) == 0)
                    app.EditField_L5.Value = app.THR_RED_ACC;
                else
                    app.EditField_L5.Value = '';
                end
                
                app.EditField_L6_Upper.Value = 'UPLINK';
                app.EditField_L6.Value = '<TO DATA';
                
                app.EditField_M1_Upper.Value = 'FLP RETR';
                app.EditField_M1.Value = 'F=160';
                app.EditField_M2_Upper.Value = 'SLT RETR';
                app.EditField_M2.Value = 'F=192';
                app.EditField_M3_Upper.Value = 'CLEAN';
                app.EditField_M3.Value = 'F=216';
                app.EditField_M5_Upper.Value = '';
                app.EditField_M5.Value = '';
                
                app.EditField_R1_Upper.Value = 'RWY';
                if (isempty(app.RWY) == 0)
                    app.EditField_R1.Value = app.RWY;
                else
                    app.EditField_R1.Value = '';
                end
                
                app.EditField_R2_Upper.Value = 'TO SHIFT';
                if (isempty(app.shift) == 0)
                    app.EditField_R2.Value = app.shift;
                else
                    app.EditField_R2.Value = '';
                end
                
                app.EditField_R3_Upper.Value = 'FLAPS/THS';
                if (isempty(app.Flaps_THS) == 0)
                    app.EditField_R3.Value = app.Flaps_THS;
                else
                    app.EditField_R3.Value = '';
                end
                
                app.EditField_R4_Upper.Value = 'FLEX TO TEMP';
                if (isempty(app.Flex_to_Temp) == 0)
                    app.EditField_R4.Value = app.Flex_to_Temp;
                else
                    app.EditField_R4.Value = '';
                end
                
                app.EditField_R5_Upper.Value = 'ENG OUT ACC';
                if (isempty(app.Eng_out_Acc) == 0)
                    app.EditField_R5.Value = app.Eng_out_Acc;
                else
                    app.EditField_R5.Value = '';
                end
                
                app.EditField_R6_Upper.Value = 'NEXT';
                app.EditField_R6.Value = 'PHASE>';
                
                app.PhaseIndex = 2;
                
            elseif app.PhaseIndex == 2 % Abfrage ob PERF Phase vorliegt
                
                app.EditField_Title.Value = 'CLB';
                app.EditField_L1_Upper.Value = 'ACT MODE';
                app.EditField_L1.Value = 'MANAGED';
                app.EditField_L2_Upper.Value = 'CI';
                app.EditField_L2.Value = '';
                app.EditField_L3_Upper.Value = 'MANAGED';
                app.EditField_L3.Value = '';
                app.EditField_L4_Upper.Value = 'FRESEL';
                app.EditField_L4.Value = '';
                app.EditField_L5_Upper.Value = '';
                app.EditField_L5.Value = '';
                app.EditField_L6_Upper.Value = 'PREV';
                app.EditField_L6.Value = '<PHASE';
                
                app.EditField_M1_Upper.Value = 'TIME DEST';
                app.EditField_M1.Value = '';
                app.EditField_M2_Upper.Value = '';
                app.EditField_M2.Value = '';
                app.EditField_M3_Upper.Value = '';
                app.EditField_M3.Value = '';
                app.EditField_M5_Upper.Value = '';
                app.EditField_M5.Value = '';
                
                app.EditField_R1_Upper.Value = 'EFOB';
                app.EditField_R1.Value = '';
                app.EditField_R2_Upper.Value = '';
                app.EditField_R2.Value = '';
                app.EditField_R3_Upper.Value = '';
                app.EditField_R3.Value = '';
                app.EditField_R4_Upper.Value = '';
                app.EditField_R4.Value = '';
                app.EditField_R5_Upper.Value = '';
                app.EditField_R5.Value = '';
                app.EditField_R6_Upper.Value = 'NEXT';
                app.EditField_R6.Value = 'PHASE>';
                
                app.PhaseIndex = 3;
                
            elseif app.PhaseIndex == 3 % Abfrage ob CLB Phase vorliegt
                
                app.EditField_Title.Value = 'CRZ';
                app.EditField_L1_Upper.Value = 'ACT MODE';
                app.EditField_L1.Value = 'MANAGED';
                app.EditField_L2_Upper.Value = 'CI';
                app.EditField_L2.Value = '';
                app.EditField_L3_Upper.Value = 'MANAGED';
                app.EditField_L3.Value = '';
                app.EditField_L4_Upper.Value = 'FRESEL';
                app.EditField_L4.Value = '';
                app.EditField_L5_Upper.Value = '';
                app.EditField_L5.Value = '';
                app.EditField_L6_Upper.Value = 'PREV';
                app.EditField_L6.Value = '<PHASE';
                
                app.EditField_M1_Upper.Value = 'TIME DEST';
                app.EditField_M1.Value = '';
                app.EditField_M2_Upper.Value = '';
                app.EditField_M2.Value = '';
                app.EditField_M3_Upper.Value = '';
                app.EditField_M3.Value = '';
                app.EditField_M5_Upper.Value = '';
                app.EditField_M5.Value = '';
                
                app.EditField_R1_Upper.Value = 'EFOB';
                app.EditField_R1.Value = '';
                app.EditField_R2_Upper.Value = '';
                app.EditField_R2.Value = '';
                app.EditField_R3_Upper.Value = '';
                app.EditField_R3.Value = '';
                app.EditField_R4_Upper.Value = '';
                app.EditField_R4.Value = '';
                app.EditField_R5_Upper.Value = 'DES CABIN RATE';
                app.EditField_R5.Value = '-350 FT/MIN';
                app.EditField_R6_Upper.Value = 'NEXT';
                app.EditField_R6.Value = 'PHASE>';
                
                app.PhaseIndex = 4;
                
            elseif app.PhaseIndex == 4 % Abfrage ob CRZ Phase vorliegt
                
                app.EditField_Title.Value = 'DES';
                app.EditField_L1_Upper.Value = 'ACT MODE';
                app.EditField_L1.Value = 'MANAGED';
                app.EditField_L2_Upper.Value = 'CI';
                app.EditField_L2.Value = '';
                app.EditField_L3_Upper.Value = 'MANAGED';
                app.EditField_L3.Value = '';
                app.EditField_L4_Upper.Value = 'FRESEL';
                app.EditField_L4.Value = '';
                app.EditField_L5_Upper.Value = '';
                app.EditField_L5.Value = '';
                app.EditField_L6_Upper.Value = 'PREV';
                app.EditField_L6.Value = '<PHASE';
                
                app.EditField_M1_Upper.Value = 'TIME DEST';
                app.EditField_M1.Value = '';
                app.EditField_M2_Upper.Value = '';
                app.EditField_M2.Value = '';
                app.EditField_M3_Upper.Value = '';
                app.EditField_M3.Value = '';
                app.EditField_M5_Upper.Value = '';
                app.EditField_M5.Value = '';
                
                app.EditField_R1_Upper.Value = 'EFOB';
                app.EditField_R1.Value = '';
                app.EditField_R2_Upper.Value = '';
                app.EditField_R2.Value = '';
                app.EditField_R3_Upper.Value = '';
                app.EditField_R3.Value = '';
                app.EditField_R4_Upper.Value = '';
                app.EditField_R4.Value = '';
                app.EditField_R5_Upper.Value = '';
                app.EditField_R5.Value = '';
                app.EditField_R6_Upper.Value = 'NEXT';
                app.EditField_R6.Value = 'PHASE>';
                
                app.PhaseIndex = 5;
                
            elseif app.PhaseIndex == 5 % Abfrage ob DES Phase vorliegt
                
                app.EditField_Title.Value = 'APPR';
                app.EditField_L1_Upper.Value = 'DEST QNH';
                app.EditField_L1.Value = '';
                app.EditField_L2_Upper.Value = 'TEMP';
                app.EditField_L2.Value = '';
                app.EditField_L3_Upper.Value = 'MAG WIND';
                app.EditField_L3.Value = '';
                app.EditField_L4_Upper.Value = 'TRANS ALT';
                app.EditField_L4.Value = '';
                app.EditField_L5_Upper.Value = '    VAPP';
                app.EditField_L5.Value = '';
                app.EditField_L6_Upper.Value = 'PREV';
                app.EditField_L6.Value = '<PHASE';
                
                app.EditField_M1_Upper.Value = 'FLP RETR';
                app.EditField_M1.Value = 'F=160';
                app.EditField_M2_Upper.Value = 'SLT RETR';
                app.EditField_M2.Value = 'F=192';
                app.EditField_M3_Upper.Value = 'CLEAN';
                app.EditField_M3.Value = 'F=216';
                app.EditField_M5_Upper.Value = 'VLS';
                app.EditField_M5.Value = '133';
                
                app.EditField_R1_Upper.Value = 'FINAL';
                app.EditField_R1.Value = '';
                app.EditField_R2_Upper.Value = 'MDA';
                app.EditField_R2.Value = '';
                app.EditField_R3_Upper.Value = 'DH';
                app.EditField_R3.Value = '';
                app.EditField_R4_Upper.Value = 'LDG CONF';
                app.EditField_R4.Value = '';
                app.EditField_R5_Upper.Value = '';
                app.EditField_R5.Value = '';
                app.EditField_R6_Upper.Value = 'NEXT';
                app.EditField_R6.Value = 'PHASE>';
                
                app.PhaseIndex = 6;
                
            elseif app.PhaseIndex == 6 % Abfrage ob DES Phase vorliegt
                
                app.EditField_R6_Upper.Value = 'NEXT';
                app.EditField_R6.Value = 'PHASE>';
                
            else % Wert aus dem Sratch Pad wird übernommen
                
                value = app.EditField_Scratch.Value;
                app.EditField_R6.Value = value;
                
                % Clear the Scratch Pad after Value has been transmitted
                app.EditField_Scratch.Value = '';
                app.integerNumber = 0;
                app.dotNumber = '';
                app.enableDot = 0; % disable dot number
                
            end
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            result = [app.EditField_Scratch.Value '1'];
            app.EditField_Scratch.Value = result;
            app.PlusMinusIndex = 0;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            result = [app.EditField_Scratch.Value '2'];
            app.EditField_Scratch.Value = result;
            app.PlusMinusIndex = 0;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            result = [app.EditField_Scratch.Value '3'];
            app.EditField_Scratch.Value = result;
            app.PlusMinusIndex = 0;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            result = [app.EditField_Scratch.Value '4'];
            app.EditField_Scratch.Value = result;
            app.PlusMinusIndex = 0;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            result = [app.EditField_Scratch.Value '5'];
            app.EditField_Scratch.Value = result;
            app.PlusMinusIndex = 0;
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            result = [app.EditField_Scratch.Value '6'];
            app.EditField_Scratch.Value = result;
            app.PlusMinusIndex = 0;
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            result = [app.EditField_Scratch.Value '7'];
            app.EditField_Scratch.Value = result;
            app.PlusMinusIndex = 0;
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            result = [app.EditField_Scratch.Value '8'];
            app.EditField_Scratch.Value = result;
            app.PlusMinusIndex = 0;
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            result = [app.EditField_Scratch.Value '9'];
            app.EditField_Scratch.Value = result;
            app.PlusMinusIndex = 0;
        end

        % Button pushed function: Button_dot
        function Button_dotPushed(app, event)
            %app.enableDot = 1;
            %app.integerNumber = str2double(app.EditField_Scratch.Value);
            result = [app.EditField_Scratch.Value '.'];
            app.EditField_Scratch.Value = result;
        end

        % Button pushed function: Button_0
        function Button_0Pushed(app, event)
            result = [app.EditField_Scratch.Value '0'];
            app.EditField_Scratch.Value = result;
        end

        % Button pushed function: AButton
        function AButtonPushed(app, event)
            result = [app.EditField_Scratch.Value 'A'];
            app.EditField_Scratch.Value = result;
        end

        % Button pushed function: BButton
        function BButtonPushed(app, event)
            result = [app.EditField_Scratch.Value 'B'];
            app.EditField_Scratch.Value = result;
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            result = [app.EditField_Scratch.Value 'C'];
            app.EditField_Scratch.Value = result;
        end

        % Button pushed function: DButton
        function DButtonPushed(app, event)
            result = [app.EditField_Scratch.Value 'D'];
            app.EditField_Scratch.Value = result;
        end

        % Button pushed function: EButton
        function EButtonPushed(app, event)
            result = [app.EditField_Scratch.Value 'E'];
            app.EditField_Scratch.Value = result;
        end

        % Button pushed function: FButton
        function FButtonPushed(app, event)
            result = [app.EditField_Scratch.Value 'F'];
            app.EditField_Scratch.Value = result;
        end

        % Button pushed function: GButton
        function GButtonPushed(app, event)
            result = [app.EditField_Scratch.Value 'G'];
            app.EditField_Scratch.Value = result;
        end

        % Button pushed function: HButton
        function HButtonPushed(app, event)
            result = [app.EditField_Scratch.Value 'H'];
            app.EditField_Scratch.Value = result;
        end

        % Button pushed function: IButton
        function IButtonPushed(app, event)
            result = [app.EditField_Scratch.Value 'I'];
            app.EditField_Scratch.Value = result;
        end

        % Button pushed function: JButton
        function JButtonPushed(app, event)
            result = [app.EditField_Scratch.Value 'J'];
            app.EditField_Scratch.Value = result;
        end

        % Button pushed function: KButton
        function KButtonPushed(app, event)
            result = [app.EditField_Scratch.Value 'K'];
            app.EditField_Scratch.Value = result;
        end

        % Button pushed function: LButton
        function LButtonPushed(app, event)
            result = [app.EditField_Scratch.Value 'L'];
            app.EditField_Scratch.Value = result;
        end

        % Button pushed function: MButton
        function MButtonPushed(app, event)
            result = [app.EditField_Scratch.Value 'M'];
            app.EditField_Scratch.Value = result;
        end

        % Button pushed function: NButton
        function NButtonPushed(app, event)
            result = [app.EditField_Scratch.Value 'N'];
            app.EditField_Scratch.Value = result;
        end

        % Button pushed function: OButton
        function OButtonPushed(app, event)
            result = [app.EditField_Scratch.Value 'O'];
            app.EditField_Scratch.Value = result;
        end

        % Button pushed function: PButton
        function PButtonPushed(app, event)
            result = [app.EditField_Scratch.Value 'P'];
            app.EditField_Scratch.Value = result;
        end

        % Button pushed function: QButton
        function QButtonPushed(app, event)
            result = [app.EditField_Scratch.Value 'Q'];
            app.EditField_Scratch.Value = result;
        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
            result = [app.EditField_Scratch.Value 'R'];
            app.EditField_Scratch.Value = result;
        end

        % Button pushed function: SButton
        function SButtonPushed(app, event)
            result = [app.EditField_Scratch.Value 'S'];
            app.EditField_Scratch.Value = result;
        end

        % Button pushed function: TButton
        function TButtonPushed(app, event)
            result = [app.EditField_Scratch.Value 'T'];
            app.EditField_Scratch.Value = result;
        end

        % Button pushed function: UButton
        function UButtonPushed(app, event)
            result = [app.EditField_Scratch.Value 'U'];
            app.EditField_Scratch.Value = result;
        end

        % Button pushed function: VButton
        function VButtonPushed(app, event)
            result = [app.EditField_Scratch.Value 'V'];
            app.EditField_Scratch.Value = result;
        end

        % Button pushed function: WButton
        function WButtonPushed(app, event)
            result = [app.EditField_Scratch.Value 'W'];
            app.EditField_Scratch.Value = result;
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            result = [app.EditField_Scratch.Value 'X'];
            app.EditField_Scratch.Value = result;
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            result = [app.EditField_Scratch.Value 'Y'];
            app.EditField_Scratch.Value = result;
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            result = [app.EditField_Scratch.Value 'Z'];
            app.EditField_Scratch.Value = result;
        end

        % Button pushed function: SPButton
        function SPButtonPushed(app, event)
            
        end

        % Button pushed function: DELButton
        function DELButtonPushed(app, event)
            result = app.EditField_Scratch.Value(1:end-1);
            app.EditField_Scratch.Value = result;
        end

        % Button pushed function: Button_FWslash
        function Button_FWslashPushed(app, event)
            result = [app.EditField_Scratch.Value '/'];
            app.EditField_Scratch.Value = result;
        end

        % Button pushed function: CLRButton
        function CLRButtonPushed(app, event)
            app.EditField_Scratch.Value = '';
            app.integerNumber = 0;
            app.dotNumber = '';
            app.enableDot = 0; % disable dot number
            
        end

        % Button pushed function: DIRButton
        function DIRButtonPushed(app, event)
            
        end

        % Button pushed function: PROGButton_2
        function PROGButton_2Pushed(app, event)
            
        end

        % Button pushed function: PERFButton
        function PERFButtonPushed(app, event)
            % Background Color of the Input Parameters
            for i = 1:6
            app.c{i}.FontColor = [1 1 0];
            end
            
            app.EditField_Title.Value = 'TAKE OFF';
            
            app.EditField_L1_Upper.Value = 'V1';
            if (isempty(app.v1) == 0)
                app.EditField_L1.Value = app.v1;
            else
                app.EditField_L1.Value = '';
            end
            
            app.EditField_L2_Upper.Value = 'VR';
            if (isempty(app.vR) == 0)
                app.EditField_L2.Value = app.vR;
            else
                app.EditField_L2.Value = '';
            end
            
            app.EditField_L3_Upper.Value = 'V2';
            if (isempty(app.v2) == 0)
                app.EditField_L3.Value = app.v2;
            else
                app.EditField_L3.Value = '';
            end
            
            app.EditField_L4_Upper.Value = 'TRANS ALT';
            if (isempty(app.trans_ALT) == 0)
                app.EditField_L4.Value = app.trans_ALT;
            else
                app.EditField_L4.Value = '';
            end
            
            app.EditField_L5_Upper.Value = 'THR RED/ACC';
            if (isempty(app.THR_RED_ACC) == 0)
                app.EditField_L5.Value = app.THR_RED_ACC;
            else
                app.EditField_L5.Value = '';
            end
            
            app.EditField_L6_Upper.Value = 'UPLINK';
            app.EditField_L6.Value = '<TO DATA';
            
            
            app.EditField_M1_Upper.Value = 'FLP RETR';
            app.EditField_M1.Value = 'F=160';
            app.EditField_M2_Upper.Value = 'SLT RETR';
            app.EditField_M2.Value = 'F=192';
            app.EditField_M3_Upper.Value = 'CLEAN';
            app.EditField_M3.Value = 'F=216';
            app.EditField_M5_Upper.Value = '';
            app.EditField_M5.Value = '';
            
            
            app.EditField_R1_Upper.Value = 'RWY';
            if (isempty(app.RWY) == 0)
                app.EditField_R1.Value = app.RWY;
            else
                app.EditField_R1.Value = '';
            end
            
            app.EditField_R2_Upper.Value = 'TO SHIFT';
            if (isempty(app.shift) == 0)
                app.EditField_R2.Value = app.shift;
            else
                app.EditField_R2.Value = '';
            end
            
            app.EditField_R3_Upper.Value = 'FLAPS/THS';
            if (isempty(app.Flaps_THS) == 0)
                app.EditField_R3.Value = app.Flaps_THS;
            else
                app.EditField_R3.Value = '';
            end
            
            app.EditField_R4_Upper.Value = 'FLEX TO TEMP';
            if (isempty(app.Flex_to_Temp) == 0)
                app.EditField_R4.Value = app.Flex_to_Temp;
            else
                app.EditField_R4.Value = '';
            end
            
            app.EditField_R5_Upper.Value = 'ENG OUT ACC';
            if (isempty(app.Eng_out_Acc) == 0)
                app.EditField_R5.Value = app.Eng_out_Acc;
            else
                app.EditField_R5.Value = '';
            end
            
            app.EditField_R6_Upper.Value = 'NEXT';
            app.EditField_R6.Value = 'PHASE>';
            
            app.PhaseIndex = 2; % Beginn bei 2, weil es Data Uplink Page gibt (1)
            app.PageIndex = 0; % Reset der INIT - Page
            app.FPlanIndex = 0; % Reset der FPLAN - Page
            app.MCDUMenueIndex = 0; % Reset der MCDUMenue - Page
            app.DataIndex = 0; % Reset der DATA - Page
        end

        % Button pushed function: INITButton
        function INITButtonPushed(app, event)
            
%             % Initialize EditFields
%                 % Left Side
%                 app.c{1} = app.EditField_L1;
%                 app.c{2} = app.EditField_L2;
%                 app.c{3} = app.EditField_L3;
%                 app.c{4} = app.EditField_L4;
%                 app.c{5} = app.EditField_L5;
%                 app.cu{1} = app.EditField_L1_Upper;
%                 app.cu{2} = app.EditField_L2_Upper;
%                 app.cu{3} = app.EditField_L3_Upper;
%                 app.cu{4} = app.EditField_L4_Upper;
%                 app.cu{5} = app.EditField_L5_Upper;
%                 % Middle 
%                 app.m{1} = app.EditField_M1;
%                 app.m{2} = app.EditField_M2;
%                 app.m{3} = app.EditField_M3;
%                 app.m{4} = app.EditField_M4;
%                 app.m{5} = app.EditField_M5;
%                 app.mu{1} = app.EditField_M1_Upper;
%                 app.mu{2} = app.EditField_M2_Upper;
%                 app.mu{3} = app.EditField_M3_Upper;
%                 app.mu{4} = app.EditField_M4_Upper;
%                 app.mu{5} = app.EditField_M5_Upper;
%                 % Right Side
%                 app.r{1} = app.EditField_R1;
%                 app.r{2} = app.EditField_R2;
%                 app.r{3} = app.EditField_R3;
%                 app.r{4} = app.EditField_R4;
%                 app.r{5} = app.EditField_R5;
%                 app.ru{1} = app.EditField_R1_Upper;
%                 app.ru{2} = app.EditField_R2_Upper;
%                 app.ru{3} = app.EditField_R3_Upper;
%                 app.ru{4} = app.EditField_R4_Upper;
%                 app.ru{5} = app.EditField_R5_Upper;
                
            % Background Color of the Input Parameters
            for i = 1:5
            app.c{i}.FontColor = [0.3010 0.7450 0.9330];
            end            
            app.EditField_L6.FontColor = [0.3010 0.7450 0.9330];
            
            app.EditField_R1.FontColor = [0.3010 0.7450 0.9330];
            app.EditField_R2.FontColor = [0.3010 0.7450 0.9330];
            app.EditField_R4.FontColor = [0.3010 0.7450 0.9330];
            app.EditField_R6.FontColor = [0.3010 0.7450 0.9330];
            
            
            app.EditField_Title.Value = 'INIT       <-- -->';
            
            app.EditField_L1_Upper.Value = 'CO RTE';
            if (isempty(app.input) == 0)
                app.EditField_L1.Value = 'NONE';
            else
                app.EditField_L1.Value = '';
            end
            
            app.EditField_L2_Upper.Value = 'ALTN/CO RTE';
            if (isempty(app.input) == 0)
                app.EditField_L2.Value = 'NONE';
            else
                app.EditField_L2.Value = '';
            end
            
            app.EditField_L3_Upper.Value = 'FLT NBR';
            if (isempty(app.FLnumber) == 0)
                app.EditField_L3.Value = app.FLnumber;
            else
                app.EditField_L3.Value = '';
            end
            
            app.EditField_L4_Upper.Value = 'LAT';
            if (isempty(app.origin_lat) == 0)
            app.EditField_L4.Value = app.origin_lat;
            else
                app.EditField_L4.Value = '';
            end
            
            app.EditField_L5_Upper.Value = 'Cost Index';
            if (isempty(app.costindex) == 0)
                app.EditField_L5.Value = app.costindex;
            else
                app.EditField_L5.Value = '';
            end
            
            app.EditField_L6_Upper.Value = 'CRZ FL/TEMP';
            if (isempty(app.FLTemperatur) == 0)
                app.EditField_L6.Value = app.FLTemperatur;
            else
                app.EditField_L6.Value = '';
            end
            
            app.EditField_M1_Upper.Value = '';
            app.EditField_M1.Value = '';
            app.EditField_M2_Upper.Value = '';
            app.EditField_M2.Value = '';
            app.EditField_M3_Upper.Value = '';
            app.EditField_M3.Value = '';
            app.EditField_M5_Upper.Value = '';
            app.EditField_M5.Value = '';
            
            app.EditField_R1_Upper.Value = 'FROM/TO';
            if (isempty(app.input) == 0)
                app.EditField_R1.Value = app.input;
            else
                app.EditField_R1.Value = '';
            end
            
            app.EditField_R2_Upper.Value = '';
            app.EditField_R2.Value = '';
            
            app.EditField_R3_Upper.Value = '';
            if (isempty(app.input) == 0) && app.IRScounter == 0
                app.EditField_R3.Value = 'ALIGN IRS>';
            else
                app.EditField_R3.Value = '';
            end
            
            app.EditField_R4_Upper.Value = 'LONG';
            if (isempty(app.origin_lon) == 0)
            app.EditField_R4.Value = app.origin_lon;
            else
                app.EditField_R4.Value = '';
            end            
            app.EditField_R5_Upper.Value = '';
            app.EditField_R5.Value = 'WIND>';
            app.EditField_R6_Upper.Value = 'Tropo';
            app.EditField_R6.Value = '';
            
            app.PageIndex = 1;
            app.PhaseIndex = 0; % Reset der PERF - Page
            app.FPlanIndex = 0; % Reset der FPLAN - Page
            app.MCDUMenueIndex = 0; % Reset der MCDUMenue - Page
            app.DataIndex = 0; % Reset der DATA - Page 
            
        end

        % Button pushed function: DATAButton
        function DATAButtonPushed(app, event)
            % Reset EditFields
            for i = 1:5
            app.c{i}.Value = '';
            app.m{i}.Value = '';
            app.r{i}.Value = '';
            app.cu{i}.Value = '';
            app.mu{i}.Value = '';
            app.ru{i}.Value = '';
            end
            app.EditField_L1.Value = '';            
            app.EditField_M1.Value = '';
            app.EditField_R1.Value = '';            
            app.EditField_L6_Upper.Value = '';
            app.EditField_M6_Upper.Value = '';
            app.EditField_R6_Upper.Value = '';
            
            % Background Color of the Input Parameters
            for i = 1:5
            app.c{i}.FontColor = [1 1 1];
            app.m{i}.FontColor = [1 1 1];
            app.r{i}.FontColor = [1 1 1];
            end            
            app.EditField_L6.FontColor = [1 1 1];
            app.EditField_M6.FontColor = [1 1 1];
            app.EditField_R6.FontColor = [1 1 1];
            
            app.EditField_Title.Value = 'DATA INDEX       1/2 <-- -->';
            
            app.EditField_L1_Upper.Value = '  Position';
            app.EditField_L1.Value = '< MONITOR';
            app.EditField_L2_Upper.Value = '  Irs';
            app.EditField_L2.Value = '< MONITOR';
            app.EditField_L3_Upper.Value = '  GPS';
            app.EditField_L3.Value = '< MONITOR';
            app.EditField_L4.Value = '< A/C STATUS';
            
            app.EditField_R5_Upper.Value = 'PRINT';
            app.EditField_R5.Value = 'FUNCTION >';
            app.EditField_R6_Upper.Value = 'AOC';
            app.EditField_R6.Value = 'FUNCTION >';
            
            app.DataIndex = 1;
            app.PageIndex = 0; % Reset der INIT - Page
            app.PhaseIndex = 0; % Reset der PERF - Page
            app.FPlanIndex = 0; % Reset der FPLAN - Page
            app.MCDUMenueIndex = 0; % Reset der MCDUMenue - Page
        end

        % Button pushed function: FPLNButton
        function FPLNButtonPushed(app, event)
            if (isempty(app.input) == 0)
                
                % Background Color of the Input Parameters
                for i = 2:5
                app.c{i}.FontColor = [1 1 1];
                end
                app.EditField_L1.FontColor = [0 0.6 0];
                app.EditField_L6.FontColor = [1 1 1];
                
                app.EditField_R1.FontColor = [1 1 1];
                app.EditField_R2.FontColor = [1 1 1];
                app.EditField_R3.FontColor = [1 1 1];
                app.EditField_R4.FontColor = [1 1 1];
                app.EditField_R6.FontColor = [1 1 1];
                                        
                app.EditField_Title.Value = 'F-PLAN       <-- -->';
                
                app.EditField_L1_Upper.Value = 'FROM';
                app.EditField_L1.Value = app.origin;
                app.EditField_L2_Upper.Value = '';
                app.EditField_L2.Value = '-------------';
                app.EditField_L3_Upper.Value = '';
                app.EditField_L3.Value = '';
                app.EditField_L4_Upper.Value = '';
                app.EditField_L4.Value = '';
                app.EditField_L5_Upper.Value = '';
                app.EditField_L5.Value = '';
                app.EditField_L6_Upper.Value = 'DEST';
                app.EditField_L6.Value = app.destination;
                
                app.EditField_M1_Upper.Value = 'TIME';
                app.EditField_M1.Value = '0000';
                app.EditField_M2_Upper.Value = '';
                app.EditField_M2.Value = 'F-PLAN DISCONTINUITY';
                app.EditField_M3_Upper.Value = '';
                app.EditField_M3.Value = '';
                app.EditField_M4_Upper.Value = '';
                app.EditField_M4.Value = '';
                app.EditField_M5_Upper.Value = '';
                app.EditField_M5.Value = '';
                app.EditField_M6_Upper.Value = '';
                app.EditField_M6.Value = 'TIME';
                
                app.EditField_R1_Upper.Value = 'SPD/ALT';
                app.EditField_R1.Value = '---/---';
                app.EditField_R2_Upper.Value = '';
                app.EditField_R2.Value = '-------------';
                app.EditField_R3_Upper.Value = '';
                app.EditField_R3.Value = '';
                app.EditField_R4_Upper.Value = '';
                app.EditField_R4.Value = '';
                app.EditField_R5_Upper.Value = '';
                app.EditField_R5.Value = '';
                app.EditField_R6_Upper.Value = 'DIST    EFOB';
                app.EditField_R6.Value = '';
                                                
                app.FPlanIndex = 1;
                app.WPTIndex = 1;
                app.PageIndex = 0; % Reset der INIT - Page
                app.PhaseIndex = 0; % Reset der PERF - Page
                app.MCDUMenueIndex = 0; % Reset der MCDUMenue - Page
                app.DataIndex = 0; % Reset der DATA - Page
                
            else
                
                app.EditField_Scratch.Value = 'ERROR: INITIALIZE ROUTE';
                
            end
        end

        % Button pushed function: RADNAVButton
        function RADNAVButtonPushed(app, event)
            
        end

        % Button pushed function: FUELPREDButton
        function FUELPREDButtonPushed(app, event)
            
        end

        % Button pushed function: SECFPLNButton
        function SECFPLNButtonPushed(app, event)
            
        end

        % Button pushed function: ATCCOMMButton
        function ATCCOMMButtonPushed(app, event)
            
        end

        % Button pushed function: MCDUMENUButton
        function MCDUMENUButtonPushed(app, event)
            app.EditField_Title.Value = 'MCDU MENUE';
            app.EditField_L1_Upper.Value = '';
            app.EditField_L1.Value = ' <FMGC';
            app.EditField_L2_Upper.Value = '';
            app.EditField_L2.Value = ' <ATSU';
            app.EditField_L3_Upper.Value = '';
            app.EditField_L3.Value = ' <ACSM';
            app.EditField_L4_Upper.Value = '';
            app.EditField_L4.Value = '';
            app.EditField_L5_Upper.Value = '';
            app.EditField_L5.Value = ' <SAT';
            app.EditField_L6_Upper.Value = '';
            app.EditField_L6.Value = '';
            
            app.EditField_M1_Upper.Value = '';
            app.EditField_M1.Value = '';
            app.EditField_M2_Upper.Value = '';
            app.EditField_M2.Value = '';
            app.EditField_M3_Upper.Value = '';
            app.EditField_M3.Value = '';
            app.EditField_M5_Upper.Value = '';
            app.EditField_M5.Value = '';
            
            app.EditField_R1_Upper.Value = '';
            app.EditField_R1.Value = '';
            app.EditField_R2_Upper.Value = '';
            app.EditField_R2.Value = '';
            app.EditField_R3_Upper.Value = '';
            app.EditField_R3.Value = '';
            app.EditField_R4_Upper.Value = '';
            app.EditField_R4.Value = '';
            app.EditField_R5.Value = '';
            app.EditField_R5.Value = '';
            app.EditField_R6_Upper.Value = '';
            app.EditField_R6.Value = 'RETURN>';
            
            app.MCDUMenueIndex = 1;
            app.PageIndex = 0; % Reset der INIT - Page
            app.PhaseIndex = 0; % Reset der PERF - Page
            app.FPlanIndex = 0; % Reset der FPLAN - Page
            app.DataIndex = 0; % Reset der DATA - Page
        end

        % Button pushed function: AIRPORTButton
        function AIRPORTButtonPushed(app, event)
            
        end

        % Button pushed function: LEFTButton
        function LEFTButtonPushed(app, event)
            
        end

        % Button pushed function: RIGHTButton
        function RIGHTButtonPushed(app, event)
            if app.PageIndex == 1 % Abfrage ob INIT Page A vorliegt
                
                app.EditField_Title.Value = 'INIT       <-- -->';
                
                app.EditField_L1_Upper.Value = 'TAXI';
                if (isempty(app.taxi) == 0)
                    app.EditField_L1.Value = app.taxi;
                else
                    app.EditField_L1.Value = '0.2';
                end
                
                app.EditField_L2_Upper.Value = 'TRIP/TIME';
                app.EditField_L2.Value = '';
                app.EditField_L3_Upper.Value = 'RTE RSV/%';
                app.EditField_L3.Value = '';
                app.EditField_L4_Upper.Value = 'ALTN/TIME';
                app.EditField_L4.Value = '';
                app.EditField_L5_Upper.Value = 'FINAL/TIME';
                app.EditField_L5.Value = '';
                app.EditField_L6_Upper.Value = 'EXTRA/TIME';
                app.EditField_L6.Value = '';
                
                app.EditField_M1_Upper.Value = '';
                app.EditField_M1.Value = '';
                app.EditField_M2_Upper.Value = '';
                app.EditField_M2.Value = '';
                app.EditField_M3_Upper.Value = '';
                app.EditField_M3.Value = '';
                
                app.EditField_R1_Upper.Value = 'ZFWCG/ZFW';
                if (isempty(app.ZFWCG_ZFW) == 0)
                    app.EditField_R1.Value = app.ZFWCG_ZFW;
                else
                    app.EditField_R1.Value = '';
                end
                
                app.EditField_R2_Upper.Value = 'BLOCK';
                if (isempty(app.Block) == 0)
                    app.EditField_R2.Value = app.Block;
                else
                    app.EditField_R2.Value = '';
                end
                
                app.EditField_R3_Upper.Value = '';
                app.EditField_R3.Value = '';
                app.EditField_R4_Upper.Value = 'TOW';
                app.EditField_R4.Value = '';
                app.EditField_R5.Value = 'LW';
                app.EditField_R5.Value = '';
                app.EditField_R6_Upper.Value = '';
                app.EditField_R6.Value = '';
                
                app.PageIndex = 2; % INIT Page B initialisieren
                
            elseif app.PageIndex == 2 % Abfrage ob INIT Page B vorliegt
                
                app.EditField_Title.Value = 'INIT       <-- -->';
                
                app.EditField_L1_Upper.Value = 'CO RTE';
                if (isempty(app.input) == 0)
                    app.EditField_L1.Value = 'NONE';
                else
                    app.EditField_L1.Value = '';
                end
                
                app.EditField_L2_Upper.Value = 'ALTN/CO RTE';
                if (isempty(app.input) == 0)
                    app.EditField_L2.Value = 'NONE';
                else
                    app.EditField_L2.Value = '';
                end
                
                app.EditField_L3_Upper.Value = 'FLT NBR';
                if (isempty(app.FLnumber) == 0)
                    app.EditField_L3.Value = app.FLnumber;
                else
                    app.EditField_L3.Value = '';
                end
                
                app.EditField_L4_Upper.Value = 'LAT';
                app.EditField_L4.Value = '';
                
                app.EditField_L5_Upper.Value = 'Cost Index';
                if (isempty(app.costindex) == 0)
                    app.EditField_L5.Value = app.costindex;
                else
                    app.EditField_L5.Value = '';
                end
                
                app.EditField_L6_Upper.Value = 'CRZ FL/TEMP';
                if (isempty(app.FLTemperatur) == 0)
                    app.EditField_L6.Value = app.FLTemperatur;
                else
                    app.EditField_L6.Value = '';
                end
                
                app.EditField_R1_Upper.Value = 'FROM/TO';
                if (isempty(app.input) == 0)
                    app.EditField_R1.Value = app.input;
                else
                    app.EditField_R1.Value = '';
                end
                
                app.EditField_R2_Upper.Value = '';
                app.EditField_R2.Value = '';
                
                app.EditField_R3_Upper.Value = '';
                if (isempty(app.input) == 0) && app.IRScounter == 0
                    app.EditField_R3.Value = 'ALIGN IRS>';
                else
                    app.EditField_R3.Value = '';
                end
                
                app.EditField_R4_Upper.Value = 'LAT';
                app.EditField_R4.Value = '';
                app.EditField_R5_Upper.Value = '';
                app.EditField_R5.Value = 'WIND>';
                app.EditField_R6_Upper.Value = 'Tropo';
                app.EditField_R6.Value = '';
                
                app.PageIndex = 1; % INIT Page A initialisieren
            end
        end

        % Button pushed function: UPButton
        function UPButtonPushed(app, event)
            if app.DataIndex == 2
                
                if length(app.aircraft_bada) <= 5
                    % Nothing happens
                else
                
                    % Clear the EditFields
                        for i = 1:5
                            app.c{i}.Value = '';
                        end
                        
                    % Show available A/C Types on 2nd Page
                    for i = 1:(length(app.aircraft_bada)-5)
                        app.c{i}.Value = ['< ' app.aircraft_bada{i+5}];
                    end
                    app.DataIndex = 21;
                end
            
            elseif app.DataIndex == 21
                
                if length(app.aircraft_bada) <= 10
                    
                    % Go back to first Page
                    for i = 1:5
                        app.c{i}.Value = ['< ' app.aircraft_bada{i}];
                    end
                    app.DataIndex = 2;
                    
                else
                    
                    % Clear the EditFields
                    for i = 1:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available A/C Types on 3rd page
                    for i = 1:(length(app.aircraft_bada)-10)
                        app.c{i}.Value = ['< ' app.aircraft_bada{i+10}];
                    end
                    app.DataIndex = 22;
                    
                end
                
            end
            
            if app.WPTIndex== 1
                   
                   % Clear the EditFields
                    for i = 1:5
                        app.c{i}.Value = '';
                        app.cu{i}.Value = '';
                        app.r{i}.Value = '';
                        app.m{i}.Value = '';
                    end
                    
                    % Show available WPTs 
                    for i = 1:(app.anzahl_all_wpts-5)                        
                        [wpt_name,result,course,time,distance] = get_FPlan_data(app.all_wpts,i+5);
                        app.cu{i+1}.Value = course;
                        app.c{i}.Value = wpt_name;                    
                        app.r{i}.Value = result;
                        app.m{i}.Value = distance;
                        %app.m{i}.Value = time;
                        
                    end
                    app.WPTIndex = 11;
              
               
            elseif app.WPTIndex== 11 
                
                % Clear the EditFields
                    for i = 1:5
                        app.c{i}.Value = '';
                        app.cu{i}.Value = '';
                        app.r{i}.Value = '';
                        app.m{i}.Value = '';
                    end
                    
                    % Show available WPTs 
                    for i = 1:(app.anzahl_all_wpts-10)
                        [wpt_name,result,course,time,distance] = get_FPlan_data(app.all_wpts,i+10);
                        app.cu{i+1}.Value = course;
                        app.c{i}.Value = wpt_name;                    
                        app.r{i}.Value = result;
                        app.m{i}.Value = distance;
                        %app.m{i}.Value = time;
                     
                    end
                    app.WPTIndex = 12;
                    
            elseif app.WPTIndex== 12
                
                % Clear the EditFields
                    for i = 1:5
                        app.c{i}.Value = '';
                        app.cu{i}.Value = '';
                        app.r{i}.Value = '';
                        app.m{i}.Value = '';
                    end
                    
                    % Show available WPTs 
                    for i = 1:(app.anzahl_all_wpts-15)
                        [wpt_name,result,course,time,distance] = get_FPlan_data(app.all_wpts,i+15);
                        app.cu{i+1}.Value = course;
                        app.c{i}.Value = wpt_name;                    
                        app.r{i}.Value = result;
                        app.m{i}.Value = distance;
                        %app.m{i}.Value = time;
                    end
                    app.WPTIndex = 13;
                    
            elseif app.WPTIndex== 13 
                
                % Clear the EditFields
                    for i = 1:5
                        app.c{i}.Value = '';
                        app.cu{i}.Value = '';
                        app.r{i}.Value = '';
                        app.m{i}.Value = '';
                    end
                    
                    % Show available WPTs 
                    for i = 1:(app.anzahl_all_wpts-20)
                        [wpt_name,result,course,time,distance] = get_FPlan_data(app.all_wpts,i+20);
                        app.cu{i+1}.Value = course;
                        app.c{i}.Value = wpt_name;                    
                        app.r{i}.Value = result;
                        app.m{i}.Value = distance;
                        %app.m{i}.Value = time;
                    end
                    app.WPTIndex = 14;
                    
            elseif app.WPTIndex== 14 
                
                % Clear the EditFields
                    for i = 1:5
                        app.c{i}.Value = '';
                        app.cu{i}.Value = '';
                        app.r{i}.Value = '';
                        app.m{i}.Value = '';
                    end
                    
                    % Show available WPTs 
                    for i = 1:(app.anzahl_all_wpts-25)
                        [wpt_name,result,course,time,distance] = get_FPlan_data(app.all_wpts,i+25);
                        app.cu{i+1}.Value = course;
                        app.c{i}.Value = wpt_name;                    
                        app.r{i}.Value = result;
                        app.m{i}.Value = distance;
                        %app.m{i}.Value = time;
                    end
                    app.WPTIndex = 15;
            
            elseif app.WPTIndex== 15
                
                % Clear the EditFields
                    for i = 1:5
                        app.c{i}.Value = '';
                        app.cu{i}.Value = '';
                        app.r{i}.Value = '';
                        app.m{i}.Value = '';
                    end
                    
                    % Show available WPTs 
                    for i = 1:(app.anzahl_all_wpts-30)
                        [wpt_name,result,course,time,distance] = get_FPlan_data(app.all_wpts,i+30);
                        app.cu{i+1}.Value = course;
                        app.c{i}.Value = wpt_name;                    
                        app.r{i}.Value = result;
                        app.m{i}.Value = distance;
                        %app.m{i}.Value = time;
                    end
                    app.WPTIndex = 16;
                    
            elseif app.WPTIndex== 16
                
                % Clear the EditFields
                    for i = 1:5
                        app.c{i}.Value = '';
                        app.cu{i}.Value = '';
                        app.r{i}.Value = '';
                        app.m{i}.Value = '';
                    end
                    
                    % Show available WPTs 
                    for i = 1:(app.anzahl_all_wpts-35)
                        [wpt_name,result,course,time,distance] = get_FPlan_data(app.all_wpts,i+35);
                        app.cu{i+1}.Value = course;
                        app.c{i}.Value = wpt_name;                    
                        app.r{i}.Value = result;
                        app.m{i}.Value = distance;
                        %app.m{i}.Value = time;
                    end
                    app.WPTIndex = 17;
                    
            elseif app.WPTIndex== 17
                
                % Clear the EditFields
                    for i = 1:5
                        app.c{i}.Value = '';
                        app.cu{i}.Value = '';
                        app.r{i}.Value = '';
                        app.m{i}.Value = '';
                    end
                    
                    % Show available WPTs 
                    for i = 1:(app.anzahl_all_wpts-40)
                        [wpt_name,result,course,time,distance] = get_FPlan_data(app.all_wpts,i+40);
                        app.cu{i+1}.Value = course;
                        app.c{i}.Value = wpt_name;                    
                        app.r{i}.Value = result;
                        app.m{i}.Value = distance;
                        %app.m{i}.Value = time;
                    end
                    app.WPTIndex = 18;
                    
            elseif app.WPTIndex== 18
                
                % Clear the EditFields
                    for i = 1:5
                        app.c{i}.Value = '';
                        app.cu{i}.Value = '';
                        app.r{i}.Value = '';
                        app.m{i}.Value = '';
                    end
                    
                    % Show available WPTs 
                    for i = 1:(app.anzahl_all_wpts-45)
                        [wpt_name,result,course,time,distance] = get_FPlan_data(app.all_wpts,i+45);
                        app.cu{i+1}.Value = course;
                        app.c{i}.Value = wpt_name;                    
                        app.r{i}.Value = result;
                        app.m{i}.Value = distance;
                        %app.m{i}.Value = time;
                    end
                    app.WPTIndex = 19;
                
            elseif app.WPTIndex== 19
                
                % Clear the EditFields
                    for i = 1:5
                        app.c{i}.Value = '';
                        app.cu{i}.Value = '';
                        app.r{i}.Value = '';
                        app.m{i}.Value = '';
                    end
                    
                    % Show available WPTs 
                    for i = 1:(app.anzahl_all_wpts-50)
                        [wpt_name,result,course,time,distance] = get_FPlan_data(app.all_wpts,i+50);
                        app.cu{i+1}.Value = course;
                        app.c{i}.Value = wpt_name;                    
                        app.r{i}.Value = result;
                        app.m{i}.Value = distance;
                        %app.m{i}.Value = time;
                    end
                    app.WPTIndex = 191;
                    
%             elseif app.WPTIndex== 191
%                 
%                 % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-11)
%                         app.c{i}.Value = app.all_wpts.name{i+11};
%                         if num2str(app.all_wpts.alt_top{i+11}) == '0'
%                             if num2str(app.all_wpts.spd_value{i+11}) == '0' 
%                                 app.r{i}.Value = '---/-----' ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+11}) '/-----'];
%                                 app.r{i}.Value = result;
%                             end
%                         else
%                             if num2str(app.all_wpts.spd_value{i+11}) == '0' 
%                                 result = ['---/ ' num2str(app.all_wpts.alt_top{i+11})];
%                                 app.r{i}.Value = result ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+11}) '/ ' num2str(app.all_wpts.alt_top{i+11})];
%                                 app.r{i}.Value = result ;
%                             end
%                         end
%                     end
%                     app.WPTIndex = 192;        
%             
%             elseif app.WPTIndex== 192
%                 
%                 % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-12)
%                         app.c{i}.Value = app.all_wpts.name{i+12};
%                         % Speed and ALtitude Constraints
%                         if num2str(app.all_wpts.alt_top{i+12}) == '0'
%                             if num2str(app.all_wpts.spd_value{i+12}) == '0' 
%                                 app.r{i}.Value = '---/-----' ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+12}) '/-----'];
%                                 app.r{i}.Value = result;
%                             end
%                         else
%                             if num2str(app.all_wpts.spd_value{i+12}) == '0' 
%                                 result = ['---/ ' num2str(app.all_wpts.alt_top{i+12})];
%                                 app.r{i}.Value = result ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+12}) '/ ' num2str(app.all_wpts.alt_top{i+12})];
%                                 app.r{i}.Value = result ;
%                             end
%                         end
%                     end
%                     app.WPTIndex = 193;
%             
%             elseif app.WPTIndex== 193
%                 
%                 % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-13)
%                         app.c{i}.Value = app.all_wpts.name{i+13};
%                         % Speed and ALtitude Constraints
%                         if num2str(app.all_wpts.alt_top{i+13}) == '0'
%                             if num2str(app.all_wpts.spd_value{i+13}) == '0' 
%                                 app.r{i}.Value = '---/-----' ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+13}) '/-----'];
%                                 app.r{i}.Value = result;
%                             end
%                         else
%                             if num2str(app.all_wpts.spd_value{i+13}) == '0' 
%                                 result = ['---/ ' num2str(app.all_wpts.alt_top{i+13})];
%                                 app.r{i}.Value = result ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+13}) '/ ' num2str(app.all_wpts.alt_top{i+13})];
%                                 app.r{i}.Value = result ;
%                             end
%                         end
%                     end
%                     app.WPTIndex = 194;
%                     
%             elseif app.WPTIndex== 194
%                 
%                 % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-14)
%                         app.c{i}.Value = app.all_wpts.name{i+14};
%                         % Speed and ALtitude Constraints
%                         if num2str(app.all_wpts.alt_top{i+14}) == '0'
%                             if num2str(app.all_wpts.spd_value{i+14}) == '0' 
%                                 app.r{i}.Value = '---/-----' ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+14}) '/-----'];
%                                 app.r{i}.Value = result;
%                             end
%                         else
%                             if num2str(app.all_wpts.spd_value{i+14}) == '0' 
%                                 result = ['---/ ' num2str(app.all_wpts.alt_top{i+14})];
%                                 app.r{i}.Value = result ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+14}) '/ ' num2str(app.all_wpts.alt_top{i+14})];
%                                 app.r{i}.Value = result ;
%                             end
%                         end
%                     end
%                     app.WPTIndex = 195;
%                  
%             elseif app.WPTIndex== 195
%                 
%                 % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-15)
%                         app.c{i}.Value = app.all_wpts.name{i+15};
%                         % Speed and ALtitude Constraints
%                         if num2str(app.all_wpts.alt_top{i+15}) == '0'
%                             if num2str(app.all_wpts.spd_value{i+15}) == '0' 
%                                 app.r{i}.Value = '---/-----' ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+15}) '/-----'];
%                                 app.r{i}.Value = result;
%                             end
%                         else
%                             if num2str(app.all_wpts.spd_value{i+15}) == '0' 
%                                 result = ['---/ ' num2str(app.all_wpts.alt_top{i+15})];
%                                 app.r{i}.Value = result ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+15}) '/ ' num2str(app.all_wpts.alt_top{i+15})];
%                                 app.r{i}.Value = result ;
%                             end
%                         end
%                     end
%                     app.WPTIndex = 196;
%                     
%             elseif app.WPTIndex== 196
%                 
%                 % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-16)
%                         app.c{i}.Value = app.all_wpts.name{i+16};
%                         % Speed and ALtitude Constraints
%                         if num2str(app.all_wpts.alt_top{i+16}) == '0'
%                             if num2str(app.all_wpts.spd_value{i+16}) == '0' 
%                                 app.r{i}.Value = '---/-----' ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+16}) '/-----'];
%                                 app.r{i}.Value = result;
%                             end
%                         else
%                             if num2str(app.all_wpts.spd_value{i+16}) == '0' 
%                                 result = ['---/ ' num2str(app.all_wpts.alt_top{i+16})];
%                                 app.r{i}.Value = result ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+16}) '/ ' num2str(app.all_wpts.alt_top{i+16})];
%                                 app.r{i}.Value = result ;
%                             end
%                         end
%                     end
%                     app.WPTIndex = 197;
%                  
%             elseif app.WPTIndex== 197
%                 
%                 % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-17)
%                         app.c{i}.Value = app.all_wpts.name{i+17};
%                         % Speed and ALtitude Constraints
%                         if num2str(app.all_wpts.alt_top{i+17}) == '0'
%                             if num2str(app.all_wpts.spd_value{i+17}) == '0' 
%                                 app.r{i}.Value = '---/-----' ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+17}) '/-----'];
%                                 app.r{i}.Value = result;
%                             end
%                         else
%                             if num2str(app.all_wpts.spd_value{i+17}) == '0' 
%                                 result = ['---/ ' num2str(app.all_wpts.alt_top{i+17})];
%                                 app.r{i}.Value = result ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+17}) '/ ' num2str(app.all_wpts.alt_top{i+17})];
%                                 app.r{i}.Value = result ;
%                             end
%                         end
%                     end
%                     app.WPTIndex = 198;
%                
%             elseif app.WPTIndex== 198
%                 
%                 % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-18)
%                         app.c{i}.Value = app.all_wpts.name{i+18};
%                         % Speed and ALtitude Constraints
%                         if num2str(app.all_wpts.alt_top{i+18}) == '0'
%                             if num2str(app.all_wpts.spd_value{i+18}) == '0' 
%                                 app.r{i}.Value = '---/-----' ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+18}) '/-----'];
%                                 app.r{i}.Value = result;
%                             end
%                         else
%                             if num2str(app.all_wpts.spd_value{i+18}) == '0' 
%                                 result = ['---/ ' num2str(app.all_wpts.alt_top{i+18})];
%                                 app.r{i}.Value = result ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+18}) '/ ' num2str(app.all_wpts.alt_top{i+18})];
%                                 app.r{i}.Value = result ;
%                             end
%                         end
%                     end
%                     app.WPTIndex = 199;
%                   
%             elseif app.WPTIndex== 199
%                 
%                 % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-19)
%                         app.c{i}.Value = app.all_wpts.name{i+19};
%                         % Speed and ALtitude Constraints
%                         if num2str(app.all_wpts.alt_top{i+19}) == '0'
%                             if num2str(app.all_wpts.spd_value{i+19}) == '0' 
%                                 app.r{i}.Value = '---/-----' ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+19}) '/-----'];
%                                 app.r{i}.Value = result;
%                             end
%                         else
%                             if num2str(app.all_wpts.spd_value{i+19}) == '0' 
%                                 result = ['---/ ' num2str(app.all_wpts.alt_top{i+19})];
%                                 app.r{i}.Value = result ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+19}) '/ ' num2str(app.all_wpts.alt_top{i+19})];
%                                 app.r{i}.Value = result ;
%                             end
%                         end
%                     end
%                     app.WPTIndex = 1991;
%                   
%             elseif app.WPTIndex== 1991
%                 
%                 % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-20)
%                         app.c{i}.Value = app.all_wpts.name{i+20};
%                         % Speed and ALtitude Constraints
%                         if num2str(app.all_wpts.alt_top{i+20}) == '0'
%                             if num2str(app.all_wpts.spd_value{i+20}) == '0' 
%                                 app.r{i}.Value = '---/-----' ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+20}) '/-----'];
%                                 app.r{i}.Value = result;
%                             end
%                         else
%                             if num2str(app.all_wpts.spd_value{i+20}) == '0' 
%                                 result = ['---/ ' num2str(app.all_wpts.alt_top{i+20})];
%                                 app.r{i}.Value = result ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+20}) '/ ' num2str(app.all_wpts.alt_top{i+20})];
%                                 app.r{i}.Value = result ;
%                             end
%                         end
%                     end
%                     app.WPTIndex = 1992;        
%             
%             elseif app.WPTIndex== 1992
%                 
%                 % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-21)
%                         app.c{i}.Value = app.all_wpts.name{i+21};
%                         % Speed and ALtitude Constraints
%                         if num2str(app.all_wpts.alt_top{i+21}) == '0'
%                             if num2str(app.all_wpts.spd_value{i+21}) == '0' 
%                                 app.r{i}.Value = '---/-----' ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+21}) '/-----'];
%                                 app.r{i}.Value = result;
%                             end
%                         else
%                             if num2str(app.all_wpts.spd_value{i+21}) == '0' 
%                                 result = ['---/ ' num2str(app.all_wpts.alt_top{i+21})];
%                                 app.r{i}.Value = result ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+21}) '/ ' num2str(app.all_wpts.alt_top{i+21})];
%                                 app.r{i}.Value = result ;
%                             end
%                         end
%                     end
%                     app.WPTIndex = 1993;
%             
%             elseif app.WPTIndex== 1993
%                 
%                 % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-22)
%                         app.c{i}.Value = app.all_wpts.name{i+22};
%                         % Speed and ALtitude Constraints
%                         if num2str(app.all_wpts.alt_top{i+22}) == '0'
%                             if num2str(app.all_wpts.spd_value{i+22}) == '0' 
%                                 app.r{i}.Value = '---/-----' ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+22}) '/-----'];
%                                 app.r{i}.Value = result;
%                             end
%                         else
%                             if num2str(app.all_wpts.spd_value{i+22}) == '0' 
%                                 result = ['---/ ' num2str(app.all_wpts.alt_top{i+22})];
%                                 app.r{i}.Value = result ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+22}) '/ ' num2str(app.all_wpts.alt_top{i+22})];
%                                 app.r{i}.Value = result ;
%                             end
%                         end
%                     end
%                     app.WPTIndex = 1994;
%                     
%             elseif app.WPTIndex== 1994
%                 
%                 % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-23)
%                         app.c{i}.Value = app.all_wpts.name{i+23};
%                         % Speed and ALtitude Constraints
%                         if num2str(app.all_wpts.alt_top{i+23}) == '0'
%                             if num2str(app.all_wpts.spd_value{i+23}) == '0' 
%                                 app.r{i}.Value = '---/-----' ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+23}) '/-----'];
%                                 app.r{i}.Value = result;
%                             end
%                         else
%                             if num2str(app.all_wpts.spd_value{i+23}) == '0' 
%                                 result = ['---/ ' num2str(app.all_wpts.alt_top{i+23})];
%                                 app.r{i}.Value = result ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+23}) '/ ' num2str(app.all_wpts.alt_top{i+23})];
%                                 app.r{i}.Value = result ;
%                             end
%                         end
%                     end
%                     app.WPTIndex = 1995;
%             
%             elseif app.WPTIndex== 1995
%                 
%                 % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-24)
%                         app.c{i}.Value = app.all_wpts.name{i+24};
%                         % Speed and ALtitude Constraints
%                         if num2str(app.all_wpts.alt_top{i+24}) == '0'
%                             if num2str(app.all_wpts.spd_value{i+24}) == '0' 
%                                 app.r{i}.Value = '---/-----' ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+24}) '/-----'];
%                                 app.r{i}.Value = result;
%                             end
%                         else
%                             if num2str(app.all_wpts.spd_value{i+24}) == '0' 
%                                 result = ['---/ ' num2str(app.all_wpts.alt_top{i+24})];
%                                 app.r{i}.Value = result ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+24}) '/ ' num2str(app.all_wpts.alt_top{i+24})];
%                                 app.r{i}.Value = result ;
%                             end
%                         end
%                     end
%                     app.WPTIndex = 1996;
%             
%             elseif app.WPTIndex== 1996
%                 
%                 % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-25)
%                         app.c{i}.Value = app.all_wpts.name{i+25};
%                         % Speed and ALtitude Constraints
%                         if num2str(app.all_wpts.alt_top{i+25}) == '0'
%                             if num2str(app.all_wpts.spd_value{i+25}) == '0' 
%                                 app.r{i}.Value = '---/-----' ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+25}) '/-----'];
%                                 app.r{i}.Value = result;
%                             end
%                         else
%                             if num2str(app.all_wpts.spd_value{i+25}) == '0' 
%                                 result = ['---/ ' num2str(app.all_wpts.alt_top{i+25})];
%                                 app.r{i}.Value = result ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+25}) '/ ' num2str(app.all_wpts.alt_top{i+25})];
%                                 app.r{i}.Value = result ;
%                             end
%                         end
%                     end
%                     app.WPTIndex = 1997;
%             
%             elseif app.WPTIndex== 1997
%                 
%                 % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-26)
%                         app.c{i}.Value = app.all_wpts.name{i+26};
%                         % Speed and ALtitude Constraints
%                         if num2str(app.all_wpts.alt_top{i+26}) == '0'
%                             if num2str(app.all_wpts.spd_value{i+26}) == '0' 
%                                 app.r{i}.Value = '---/-----' ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+26}) '/-----'];
%                                 app.r{i}.Value = result;
%                             end
%                         else
%                             if num2str(app.all_wpts.spd_value{i+26}) == '0' 
%                                 result = ['---/ ' num2str(app.all_wpts.alt_top{i+26})];
%                                 app.r{i}.Value = result ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+26}) '/ ' num2str(app.all_wpts.alt_top{i+26})];
%                                 app.r{i}.Value = result ;
%                             end
%                         end
%                     end
%                     app.WPTIndex = 1998;
%             
%             elseif app.WPTIndex== 1998
%                 
%                 % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-27)
%                         app.c{i}.Value = app.all_wpts.name{i+27};
%                         % Speed and ALtitude Constraints
%                         if num2str(app.all_wpts.alt_top{i+27}) == '0'
%                             if num2str(app.all_wpts.spd_value{i+27}) == '0' 
%                                 app.r{i}.Value = '---/-----' ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+27}) '/-----'];
%                                 app.r{i}.Value = result;
%                             end
%                         else
%                             if num2str(app.all_wpts.spd_value{i+27}) == '0' 
%                                 result = ['---/ ' num2str(app.all_wpts.alt_top{i+27})];
%                                 app.r{i}.Value = result ;
%                             else
%                                 result = [num2str(app.all_wpts.spd_value{i+27}) '/ ' num2str(app.all_wpts.alt_top{i+27})];
%                                 app.r{i}.Value = result ;
%                             end
%                         end
%                     end
%                     app.WPTIndex = 1999;
            end
            
            if app.FPlanIndex == 3 % Wenn 'Departure' ausgewählt wurde
                
                if app.anzahl_RWY <= 4
                    % less than 4 RWYs, nothing happens
                else
                    
                    % Clear the EditFields
                    for i = 2:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available RWYs on the 2nd page
                    for i = 1:(app.anzahl_RWY-4)
                        app.c{i+1}.Value = ['<' app.a{i+4}];
                    end
                    app.FPlanIndex = 31;
                    
                end
                
            elseif app.FPlanIndex == 31
                
                if app.anzahl_RWY <= 8
                    
                    % Go back to first Page
                    for i = 2:5
                        app.c{i}.Value = ['<' app.a{i}];
                    end
                    app.FPlanIndex = 3;
                    
                else
                    
                    % Clear the EditFields
                    for i = 2:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available RWYs on the 3nd page
                    for i = 1:(app.anzahl_RWY-8)
                        app.c{i+1}.Value = ['<' app.a{i+8}];
                    end
                    app.FPlanIndex = 32;
                    
                end
                
            elseif app.FPlanIndex == 32
                
                if app.anzahl_RWY <= 12
                    
                    % Go back to first Page
                    for i = 2:5
                        app.c{i}.Value = ['<' app.a{i}];
                    end
                    app.FPlanIndex = 3;
                    
                else
                    
                    % Clear the EditFields
                    for i = 2:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available RWYs on the 2nd page
                    for i = 1:(app.anzahl_RWY-12)
                        app.c{i+1}.Value = ['<' app.a{i+12}];
                    end
                    app.FPlanIndex = 33;
                    
                end
                
            elseif app.FPlanIndex == 33
                
                if app.anzahl_RWY <= 16
                    
                    % Go back to first Page
                    for i = 2:5
                        app.c{i}.Value = ['<' app.a{i}];
                    end
                    app.FPlanIndex = 3;
                    
                else
                    
                    % Clear the EditFields
                    for i = 2:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available RWYs on the 2nd page
                    for i = 1:(app.anzahl_RWY-16)
                        app.c{i+1}.Value = ['<' app.a{i+16}];
                    end
                    app.FPlanIndex = 34;
                    
                end
            end
            
            if app.FPlanIndex == 4 % Wenn Departure RWY ausgewählt wurde
                
                if app.anzahl_SID <= 4
                    % less than 4 SIDs, nothing happens
                else
                    
                    % Clear the EditFields
                    for i = 2:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available SIDs on the 2nd page
                    for i = 1:(app.anzahl_SID-4)
                        app.c{i+1}.Value = ['<' app.b{i+4}];
                    end
                    app.FPlanIndex = 41;
                    
                end
                
            elseif app.FPlanIndex == 41
                
                if app.anzahl_SID <= 8
                    
                    % Go back to first Page
                    for i = 2:5
                        app.c{i}.Value = ['<' app.b{i}];
                    end
                    app.FPlanIndex = 4;
                    
                else
                    
                    % Clear the EditFields
                    for i = 2:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available SIDs on the 3nd page
                    for i = 1:(app.anzahl_SID-8)
                        app.c{i+1}.Value = ['<' app.b{i+8}];
                    end
                    app.FPlanIndex = 42;
                    
                end
                
            elseif app.FPlanIndex == 42
                
                if app.anzahl_SID <= 12
                    
                    % Go back to first Page
                    for i = 2:5
                        app.c{i}.Value = ['<' app.b{i}];
                    end
                    app.FPlanIndex = 4;
                    
                else
                    
                    % Clear the EditFields
                    for i = 2:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available SIDs on the 4th page
                    for i = 1:(app.anzahl_SID-12)
                        app.c{i+1}.Value = ['<' app.b{i+12}];
                    end
                    app.FPlanIndex = 43;
                    
                end
                
            elseif app.FPlanIndex == 43
                
                if app.anzahl_SID <= 16
                    
                    % Go back to first Page
                    for i = 2:5
                        app.c{i}.Value = ['<' app.b{i}];
                    end
                    app.FPlanIndex = 4;
                    
                else
                    
                    % Clear the EditFields
                    for i = 2:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available SIDs on the 5th page
                    for i = 1:(app.anzahl_SID-16)
                        app.c{i+1}.Value = ['<' app.b{i+16}];
                    end
                    app.FPlanIndex = 44;
                    
                end
                
            elseif app.FPlanIndex == 44
                
                if app.anzahl_SID <= 20
                    
                    % Go back to first Page
                    for i = 2:5
                        app.c{i}.Value = ['<' app.b{i}];
                    end
                    app.FPlanIndex = 4;
                    
                else
                    
                    % Clear the EditFields
                    for i = 2:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available SIDs on the 6th page
                    for i = 1:(app.anzahl_SID-20)
                        app.c{i+1}.Value = ['<' app.b{i+20}];
                    end
                    app.FPlanIndex = 45;
                    
                end
                
            elseif app.FPlanIndex == 45
                
                if app.anzahl_SID <= 24
                    
                    % Go back to first Page
                    for i = 2:5
                        app.c{i}.Value = ['<' app.b{i}];
                    end
                    app.FPlanIndex = 4;
                    
                else
                    
                    % Clear the EditFields
                    for i = 2:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available SIDs on the 7th page
                    for i = 1:(app.anzahl_SID-24)
                        app.c{i+1}.Value = ['<' app.b{i+24}];
                    end
                    app.FPlanIndex = 46;
                    
                end
                
            elseif app.FPlanIndex == 46
                
                if app.anzahl_SID <= 28
                    
                    % Go back to first Page
                    for i = 2:5
                        app.c{i}.Value = ['<' app.b{i}];
                    end
                    app.FPlanIndex = 4;
                    
                else
                    
                    % Clear the EditFields
                    for i = 2:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available SIDs on the 8th page
                    for i = 1:(app.anzahl_SID-28)
                        app.c{i+1}.Value = ['<' app.b{i+28}];
                    end
                    app.FPlanIndex = 47;
                    
                end
                
            elseif app.FPlanIndex == 47
                
                if app.anzahl_SID <= 32
                    
                    % Go back to first Page
                    for i = 2:5
                        app.c{i}.Value = ['<' app.b{i}];
                    end
                    app.FPlanIndex = 4;
                    
                else
                    
                    % Clear the EditFields
                    for i = 2:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available SIDs on the 9th page
                    for i = 1:(app.anzahl_SID-32)
                        app.c{i+1}.Value = ['<' app.b{i+32}];
                    end
                    app.FPlanIndex = 48;
                    
                end
            end
            
            if app.FPlanIndex == 7 % Wenn 'Arrival' ausgewählt wurde
                
                if app.anzahl_RWY_arrival <= 3
                    % less than 4 RWYs, nothing happens
                else
                    
                    % Clear the EditFields
                    for i = 2:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available RWYs on the 2nd page
                    for i = 1:(app.anzahl_RWY_arrival-3)
                        app.c{i+2}.Value = ['<' app.d{i+3}];
                    end
                    app.FPlanIndex = 71;
                    
                end
                
            elseif app.FPlanIndex == 71
                
                if app.anzahl_RWY_arrival <= 6
                    
                    % Go back to first Page
                    for i = 1:3
                        app.c{i+2}.Value = ['<' app.d{i}];
                    end
                    app.FPlanIndex = 7;
                    
                else
                    
                    % Clear the EditFields
                    for i = 2:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available RWYs on the 3rd page
                    for i = 1:(app.anzahl_RWY_arrival-6)
                        app.c{i+2}.Value = ['<' app.d{i+6}];
                    end
                    app.FPlanIndex = 72;
                    
                end
                
            elseif app.FPlanIndex == 72
                
                if app.anzahl_RWY_arrival <= 9
                    
                    % Go back to first Page
                    for i = 1:3
                        app.c{i+2}.Value = ['<' app.d{i}];
                    end
                    app.FPlanIndex = 7;
                    
                else
                    
                    % Clear the EditFields
                    for i = 2:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available RWYs on the 4th page
                    for i = 1:(app.anzahl_RWY_arrival-9)
                        app.c{i+2}.Value = ['<' app.d{i+9}];
                    end
                    app.FPlanIndex = 73;
                    
                end
                
            elseif app.FPlanIndex == 73
                
                if app.anzahl_RWY_arrival <= 12
                    
                    % Go back to first Page
                    for i = 1:3
                        app.c{i+2}.Value = ['<' app.d{i}];
                    end
                    app.FPlanIndex = 7;
                    
                else
                    
                    % Clear the EditFields
                    for i = 2:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available RWYs on the 5th page
                    for i = 1:(app.anzahl_RWY_arrival-12)
                        app.c{i+2}.Value = ['<' app.d{i+12}];
                    end
                    app.FPlanIndex = 74;
                    
                end
                
            elseif app.FPlanIndex == 74
                
                if app.anzahl_RWY_arrival <= 15
                    
                    % Go back to first Page
                    for i = 1:3
                        app.c{i+2}.Value = ['<' app.d{i}];
                    end
                    app.FPlanIndex = 7;
                    
                else
                    
                    % Clear the EditFields
                    for i = 2:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available RWYs on the 6th page
                    for i = 1:(app.anzahl_RWY_arrival-15)
                        app.c{i+2}.Value = ['<' app.d{i+15}];
                    end
                    app.FPlanIndex = 75;
                    
                end
                
            elseif app.FPlanIndex == 75
                
                if app.anzahl_RWY_arrival <= 18
                    
                    % Go back to first Page
                    for i = 1:3
                        app.c{i+2}.Value = ['<' app.d{i}];
                    end
                    app.FPlanIndex = 7;
                    
                else
                    
                    % Clear the EditFields
                    for i = 2:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available RWYs on the 7th page
                    for i = 1:(app.anzahl_RWY_arrival-18)
                        app.c{i+2}.Value = ['<' app.d{i+18}];
                    end
                    app.FPlanIndex = 76;
                    
                end
                
            elseif app.FPlanIndex == 76
                
                if app.anzahl_RWY_arrival <= 21
                    
                    % Go back to first Page
                    for i = 1:3
                        app.c{i+2}.Value = ['<' app.d{i}];
                    end
                    app.FPlanIndex = 7;
                    
                else
                    
                    % Clear the EditFields
                    for i = 2:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available RWYs on the 8th page
                    for i = 1:(app.anzahl_RWY_arrival-21)
                        app.c{i+2}.Value = ['<' app.d{i+21}];
                    end
                    app.FPlanIndex = 77;
                    
                end
                
            elseif app.FPlanIndex == 77
                
                if app.anzahl_RWY_arrival <= 24
                    
                    % Go back to first Page
                    for i = 1:3
                        app.c{i+2}.Value = ['<' app.d{i}];
                    end
                    app.FPlanIndex = 7;
                    
                else
                    
                    % Clear the EditFields
                    for i = 2:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available RWYs on the 9th page
                    for i = 1:(app.anzahl_RWY_arrival-24)
                        app.c{i+2}.Value = ['<' app.d{i+24}];
                    end
                    app.FPlanIndex = 78;
                    
                end
                
            elseif app.FPlanIndex == 78
                
                if app.anzahl_RWY_arrival <= 27
                    
                    % Go back to first Page
                    for i = 1:3
                        app.c{i+2}.Value = ['<' app.d{i}];
                    end
                    app.FPlanIndex = 7;
                    
                else
                    
                    % Clear the EditFields
                    for i = 2:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available RWYs on the 10th page
                    for i = 1:(app.anzahl_RWY_arrival-27)
                        app.c{i+2}.Value = ['<' app.d{i+27}];
                    end
                    app.FPlanIndex = 79;
                    
                end
                
            elseif app.FPlanIndex == 79
                
                if app.anzahl_RWY_arrival <= 30
                    
                    % Go back to first Page
                    for i = 1:3
                        app.c{i+2}.Value = ['<' app.d{i}];
                    end
                    app.FPlanIndex = 7;
                    
                else
                    
                    % Clear the EditFields
                    for i = 2:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available RWYs on the 11th page
                    for i = 1:(app.anzahl_RWY_arrival-30)
                        app.c{i+2}.Value = ['<' app.d{i+30}];
                    end
                    app.FPlanIndex = 791;
                    
                end
                
            elseif app.FPlanIndex == 791
                
                if app.anzahl_RWY_arrival <= 33
                    
                    % Go back to first Page
                    for i = 1:3
                        app.c{i+2}.Value = ['<' app.d{i}];
                    end
                    app.FPlanIndex = 7;
                    
                else
                    
                    % Clear the EditFields
                    for i = 2:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available RWYs on the 12th page
                    for i = 1:(app.anzahl_RWY_arrival-33)
                        app.c{i+2}.Value = ['<' app.d{i+33}];
                    end
                    app.FPlanIndex = 792;
                    
                end
            end
            
            if app.FPlanIndex == 8 % Wenn Appraoch RWY ausgewählt wurde
                
                if app.anzahl_STAR <= 3
                    % less than 3 STARs, nothing happens
                else
                    
                    % Clear the EditFields
                    for i = 3:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available STARs on the 2nd page
                    for i = 1:(app.anzahl_STAR-3)
                        app.c{i+2}.Value = ['<' app.e{i+3}];
                    end
                    app.FPlanIndex = 81;
                    
                end
                
            elseif app.FPlanIndex == 81
                
                if app.anzahl_STAR <= 6
                    
                    % Go back to first Page
                    for i = 1:3
                        app.c{i+2}.Value = ['<' app.e{i}];
                    end
                    app.FPlanIndex = 8;
                    
                else
                    
                    % Clear the EditFields
                    for i = 3:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available STARs on the 3rd page
                    for i = 1:(app.anzahl_STAR-6)
                        app.c{i+2}.Value = ['<' app.e{i+6}];
                    end
                    app.FPlanIndex = 82;
                    
                end
                
            elseif app.FPlanIndex == 82
                
                if app.anzahl_STAR <= 9
                    
                    % Go back to first Page
                    for i = 1:3
                        app.c{i+2}.Value = ['<' app.e{i}];
                    end
                    app.FPlanIndex = 8;
                    
                else
                    
                    % Clear the EditFields
                    for i = 3:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available STARs on the 4th page
                    for i = 1:(app.anzahl_STAR-9)
                        app.c{i+2}.Value = ['<' app.e{i+9}];
                    end
                    app.FPlanIndex = 83;
                    
                end
                
            elseif app.FPlanIndex == 83
                
                if app.anzahl_STAR <= 12
                    
                    % Go back to first Page
                    for i = 1:3
                        app.c{i+2}.Value = ['<' app.e{i}];
                    end
                    app.FPlanIndex = 8;
                    
                else
                    
                    % Clear the EditFields
                    for i = 3:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available STARs on the 5th page
                    for i = 1:(app.anzahl_STAR-12)
                        app.c{i+2}.Value = ['<' app.e{i+12}];
                    end
                    app.FPlanIndex = 84;
                    
                end
                
            elseif app.FPlanIndex == 84
                
                if app.anzahl_STAR <= 15
                    
                    % Go back to first Page
                    for i = 1:3
                        app.c{i+2}.Value = ['<' app.e{i}];
                    end
                    app.FPlanIndex = 8;
                    
                else
                    
                    % Clear the EditFields
                    for i = 3:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available STARs on the 6th page
                    for i = 1:(app.anzahl_STAR-15)
                        app.c{i+2}.Value = ['<' app.e{i+15}];
                    end
                    app.FPlanIndex = 85;
                    
                end
                
            elseif app.FPlanIndex == 85
                
                if app.anzahl_STAR <= 18
                    
                    % Go back to first Page
                    for i = 1:3
                        app.c{i+2}.Value = ['<' app.e{i}];
                    end
                    app.FPlanIndex = 8;
                    
                else
                    
                    % Clear the EditFields
                    for i = 3:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available STARs on the 7th page
                    for i = 1:(app.anzahl_STAR-18)
                        app.c{i+2}.Value = ['<' app.e{i+18}];
                    end
                    app.FPlanIndex = 86;
                    
                end
                
            elseif app.FPlanIndex == 86
                
                if app.anzahl_STAR <= 21
                    
                    % Go back to first Page
                    for i = 1:3
                        app.c{i+2}.Value = ['<' app.e{i}];
                    end
                    app.FPlanIndex = 8;
                    
                else
                    
                    % Clear the EditFields
                    for i = 3:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available STARs on the 8th page
                    for i = 1:(app.anzahl_STAR-21)
                        app.c{i+2}.Value = ['<' app.e{i+21}];
                    end
                    app.FPlanIndex = 87;
                    
                end
                
            elseif app.FPlanIndex == 87
                
                if app.anzahl_STAR <= 24
                    
                    % Go back to first Page
                    for i = 1:3
                        app.c{i+2}.Value = ['<' app.e{i}];
                    end
                    app.FPlanIndex = 8;
                    
                else
                    
                    % Clear the EditFields
                    for i = 3:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available STARs on the 9th page
                    for i = 1:(app.anzahl_STAR-24)
                        app.c{i+2}.Value = ['<' app.e{i+24}];
                    end
                    app.FPlanIndex = 88;
                    
                end
                
            elseif app.FPlanIndex == 88
                
                if app.anzahl_STAR <= 27
                    
                    % Go back to first Page
                    for i = 1:3
                        app.c{i+2}.Value = ['<' app.e{i}];
                    end
                    app.FPlanIndex = 8;
                    
                else
                    
                    % Clear the EditFields
                    for i = 3:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available STARs on the 10th page
                    for i = 1:(app.anzahl_STAR-27)
                        app.c{i+2}.Value = ['<' app.e{i+27}];
                    end
                    app.FPlanIndex = 89;
                    
                end
                
            elseif app.FPlanIndex == 89
                
                if app.anzahl_STAR <= 30
                    
                    % Go back to first Page
                    for i = 1:3
                        app.c{i+2}.Value = ['<' app.e{i}];
                    end
                    app.FPlanIndex = 8;
                    
                else
                    
                    % Clear the EditFields
                    for i = 3:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available STARs on the 11th page
                    for i = 1:(app.anzahl_STAR-30)
                        app.c{i+2}.Value = ['<' app.e{i+30}];
                    end
                    app.FPlanIndex = 891;
                    
                end
                
            elseif app.FPlanIndex == 891
                
                if app.anzahl_STAR <= 33
                    
                    % Go back to first Page
                    for i = 1:3
                        app.c{i+2}.Value = ['<' app.e{i}];
                    end
                    app.FPlanIndex = 8;
                    
                else
                    
                    % Clear the EditFields
                    for i = 3:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available STARs on the 12th page
                    for i = 1:(app.anzahl_STAR-33)
                        app.c{i+2}.Value = ['<' app.e{i+33}];
                    end
                    app.FPlanIndex = 892;
                    
                end
            end
            
            if app.FPlanIndex == 9 % Wenn STAR ausgewählt wurde
                
                if app.anzahl_TRANS <= 3
                    % less than 3 TRANs, nothing happens
                else
                    
                    % Clear the EditFields
                    for i = 3:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available TRANs on the 2nd page
                    for i = 1:(app.anzahl_TRANS-3)
                        app.c{i+2}.Value = ['<' app.f{i+3}];
                    end
                    app.FPlanIndex = 91;
                    
                end
                
            elseif app.FPlanIndex == 91
                
                if app.anzahl_TRANS <= 6
                    
                    % Go back to first Page
                    for i = 1:3
                        app.c{i+2}.Value = ['<' app.f{i}];
                    end
                    app.FPlanIndex = 9;
                    
                else
                    
                    % Clear the EditFields
                    for i = 3:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available TRANs on the 3rd page
                    for i = 1:(app.anzahl_TRANS-6)
                        app.c{i+2}.Value = ['<' app.f{i+6}];
                    end
                    app.FPlanIndex = 92;
                    
                end
                
            elseif app.FPlanIndex == 92
                
                if app.anzahl_TRANS <= 9
                    
                    % Go back to first Page
                    for i = 1:3
                        app.c{i+2}.Value = ['<' app.f{i}];
                    end
                    app.FPlanIndex = 9;
                    
                else
                    
                    % Clear the EditFields
                    for i = 3:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available TRANs on the 4rd page
                    for i = 1:(app.anzahl_TRANS-9)
                        app.c{i+2}.Value = ['<' app.f{i+9}];
                    end
                    app.FPlanIndex = 93;
                    
                end
            end
        end

        % Button pushed function: DOWNButton
        function DOWNButtonPushed(app, event)
            if app.FPlanIndex == 3
                
                if app.anzahl_RWY <= 16
                    
                    if app.anzahl_RWY <= 12
                        
                        if app.anzahl_RWY <= 8
                            
                            if app.anzahl_RWY <= 4
                                
                            else
                                % more than= 4 RWYs less than 8 RWYs
                                for i = 2:5
                                    app.c{i}.Value = '';
                                end
                                
                                for i = 1:(app.anzahl_RWY-4)
                                    app.c{i+1}.Value = ['<' app.a{i+4}];
                                end
                                app.FPlanIndex = 31;
                            end
                            
                        else
                            % more than= 8 RWYs less than 12 RWYs
                            for i = 2:5
                                app.c{i}.Value = '';
                            end
                            
                            for i = 1:(app.anzahl_RWY-8)
                                app.c{i+1}.Value = ['<' app.a{i+8}];
                            end
                            app.FPlanIndex = 32;
                        end
                        
                    else
                        % more than=12 RWYs less than 16 RWYs
                        for i = 2:5
                            app.c{i}.Value = '';
                        end
                        
                        for i = 1:(app.anzahl_RWY-12)
                            app.c{i+1}.Value = ['<' app.a{i+12}];
                        end
                        app.FPlanIndex = 33;
                    end
                    
                else
                    % more than= 16 RWYs
                    for i = 2:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available RWYs on the 2nd page
                    for i = 1:(app.anzahl_RWY-16)
                        app.c{i+1}.Value = ['<' app.a{i+16}];
                    end
                    app.FPlanIndex = 34;
                end
                
            elseif app.FPlanIndex == 34
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Show available RWYs on the 2nd page
                for i = 1:(app.anzahl_RWY-12)
                    app.c{i+1}.Value = ['<' app.a{i+12}];
                end
                app.FPlanIndex = 33;
                
            elseif app.FPlanIndex == 33
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Show available RWYs on the 2nd page
                for i = 1:(app.anzahl_RWY-8)
                    app.c{i+1}.Value = ['<' app.a{i+8}];
                end
                app.FPlanIndex = 32;
                
                
            elseif app.FPlanIndex == 32
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Show available RWYs on the 2nd page
                for i = 1:(app.anzahl_RWY-4)
                    app.c{i+1}.Value = ['<' app.a{i+4}];
                end
                app.FPlanIndex = 31;
                
            elseif app.FPlanIndex == 31
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Show available RWYs on the 2nd page
                for i = 1:(app.anzahl_RWY)
                    app.c{i+1}.Value = ['<' app.a{i}];
                end
                app.FPlanIndex = 3;
                
            end
            
            if app.FPlanIndex == 4
                
                if app.anzahl_SID <= 32
                    
                    if app.anzahl_SID <= 28
                        
                        if app.anzahl_SID <= 24
                            
                            if app.anzahl_SID <= 20
                                
                                if app.anzahl_SID <= 16
                                    
                                    if app.anzahl_SID <= 12
                                        
                                        if app.anzahl_SID <= 8
                                            
                                            if app.anzahl_SID <= 4
                                                
                                            else
                                                % more than= 4 SIDs less than 8 SIDs
                                                for i = 2:5
                                                    app.c{i}.Value = '';
                                                end
                                                
                                                for i = 1:(app.anzahl_SID-4)
                                                    app.c{i+1}.Value = ['<' app.b{i+4}];
                                                end
                                                app.FPlanIndex = 41;
                                            end
                                            
                                        else
                                            % more than= 8 SIDs less than 12 SIDs
                                            for i = 2:5
                                                app.c{i}.Value = '';
                                            end
                                            
                                            for i = 1:(app.anzahl_SID-8)
                                                app.c{i+1}.Value = ['<' app.b{i+8}];
                                            end
                                            app.FPlanIndex = 42;
                                        end
                                        
                                    else
                                        % more than= 12 SIDs less than 16 SIDs
                                        for i = 2:5
                                            app.c{i}.Value = '';
                                        end
                                        
                                        for i = 1:(app.anzahl_SID-12)
                                            app.c{i+1}.Value = ['<' app.b{i+12}];
                                        end
                                        app.FPlanIndex = 43;
                                    end
                                    
                                else
                                    % more than= 16 SIDs less than 20 SIDs
                                    for i = 2:5
                                        app.c{i}.Value = '';
                                    end
                                    
                                    for i = 1:(app.anzahl_SID-16)
                                        app.c{i+1}.Value = ['<' app.b{i+16}];
                                    end
                                    app.FPlanIndex = 44;
                                end
                                
                            else
                                % more than= 20 SIDs less than 24 SIDs
                                for i = 2:5
                                    app.c{i}.Value = '';
                                end
                                
                                for i = 1:(app.anzahl_SID-20)
                                    app.c{i+1}.Value = ['<' app.b{i+20}];
                                end
                                app.FPlanIndex = 45;
                            end
                            
                        else
                            % more than= 24 SIDs less than 28 SIDs
                            for i = 2:5
                                app.c{i}.Value = '';
                            end
                            
                            for i = 1:(app.anzahl_SID-24)
                                app.c{i+1}.Value = ['<' app.b{i+24}];
                            end
                            app.FPlanIndex = 46;
                        end
                        
                    else
                        % more than= 28 SIDs less than 32 SIDs
                        for i = 2:5
                            app.c{i}.Value = '';
                        end
                        
                        for i = 1:(app.anzahl_SID-28)
                            app.c{i+1}.Value = ['<' app.b{i+28}];
                        end
                        app.FPlanIndex = 47;
                    end
                    
                else
                    % more than= 32 SIDs
                    for i = 2:5
                        app.c{i}.Value = '';
                    end
                    
                    for i = 1:(app.anzahl_SID-32)
                        app.c{i+1}.Value = ['<' app.b{i+32}];
                    end
                    app.FPlanIndex = 48;
                end
                
            elseif app.FPlanIndex == 48
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                for i = 1:(app.anzahl_SID-28)
                    app.c{i+1}.Value = ['<' app.b{i+28}];
                end
                app.FPlanIndex = 47;
                
            elseif app.FPlanIndex == 47
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                for i = 1:(app.anzahl_SID-24)
                    app.c{i+1}.Value = ['<' app.b{i+24}];
                end
                app.FPlanIndex = 46;
                
            elseif app.FPlanIndex == 46
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                for i = 1:(app.anzahl_SID-20)
                    app.c{i+1}.Value = ['<' app.b{i+20}];
                end
                app.FPlanIndex = 45;
                
            elseif app.FPlanIndex == 45
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                for i = 1:(app.anzahl_SID-16)
                    app.c{i+1}.Value = ['<' app.b{i+16}];
                end
                app.FPlanIndex = 44;
                
            elseif app.FPlanIndex == 44
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                for i = 1:(app.anzahl_SID-12)
                    app.c{i+1}.Value = ['<' app.b{i+12}];
                end
                app.FPlanIndex = 43;
                
            elseif app.FPlanIndex == 43
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                for i = 1:(app.anzahl_SID-8)
                    app.c{i+1}.Value = ['<' app.b{i+8}];
                end
                app.FPlanIndex = 42;
                
            elseif app.FPlanIndex == 42
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                for i = 1:(app.anzahl_SID-4)
                    app.c{i+1}.Value = ['<' app.b{i+4}];
                end
                app.FPlanIndex = 41;
                
            elseif app.FPlanIndex == 41
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                for i = 1:(app.anzahl_SID)
                    app.c{i+1}.Value = ['<' app.b{i}];
                end
                app.FPlanIndex = 4;
            end
            
            if app.FPlanIndex == 7
                
                if app.anzahl_RWY_arrival <= 33
                    
                    if app.anzahl_RWY_arrival <= 30
                        
                        if app.anzahl_RWY_arrival <= 27
                            
                            if app.anzahl_RWY_arrival <= 24
                                
                                if app.anzahl_RWY_arrival <= 21
                                    
                                    if app.anzahl_RWY_arrival <= 18
                                        
                                        if app.anzahl_RWY_arrival <= 15
                                            
                                            if app.anzahl_RWY_arrival <= 12
                                                
                                                if app.anzahl_RWY_arrival <= 9
                                                    
                                                    if app.anzahl_RWY_arrival <= 6
                                                        
                                                        if app.anzahl_RWY_arrival <= 3
                                                            
                                                        else
                                                            % more than= 3 RWYs less than 6 RWYs
                                                            for i = 2:5
                                                                app.c{i}.Value = '';
                                                            end
                                                            
                                                            for i = 1:(app.anzahl_RWY_arrival-3)
                                                                app.c{i+2}.Value = ['<' app.d{i+3}];
                                                            end
                                                            app.FPlanIndex = 71;
                                                        end
                                                        
                                                    else
                                                        % more than= 6 RWYs less than 9 RWYs
                                                        for i = 2:5
                                                            app.c{i}.Value = '';
                                                        end
                                                        
                                                        for i = 1:(app.anzahl_RWY_arrival-6)
                                                            app.c{i+2}.Value = ['<' app.d{i+6}];
                                                        end
                                                        app.FPlanIndex = 72;
                                                    end
                                                    
                                                else
                                                    % more than= 9 RWYs less than 12 RWYs
                                                    for i = 2:5
                                                        app.c{i}.Value = '';
                                                    end
                                                    
                                                    for i = 1:(app.anzahl_RWY_arrival-9)
                                                        app.c{i+2}.Value = ['<' app.d{i+9}];
                                                    end
                                                    app.FPlanIndex = 73;
                                                end
                                                
                                            else
                                                % more than= 12 RWYs less than 15 RWYs
                                                for i = 2:5
                                                    app.c{i}.Value = '';
                                                end
                                                
                                                % Show available RWYs on the 2nd page
                                                for i = 1:(app.anzahl_RWY_arrival-12)
                                                    app.c{i+2}.Value = ['<' app.d{i+12}];
                                                end
                                                app.FPlanIndex = 74;
                                            end
                                            
                                        else
                                            % more than= 15 RWYs less than 18 RWYs
                                            for i = 2:5
                                                app.c{i}.Value = '';
                                            end
                                            
                                            % Show available RWYs on the 2nd page
                                            for i = 1:(app.anzahl_RWY_arrival-15)
                                                app.c{i+2}.Value = ['<' app.d{i+15}];
                                            end
                                            app.FPlanIndex = 75;
                                        end
                                        
                                    else
                                        % more than= 18 RWYs less than 21 RWYs
                                        for i = 2:5
                                            app.c{i}.Value = '';
                                        end
                                        
                                        % Show available RWYs on the 2nd page
                                        for i = 1:(app.anzahl_RWY_arrival-18)
                                            app.c{i+2}.Value = ['<' app.d{i+18}];
                                        end
                                        app.FPlanIndex = 76;
                                    end
                                    
                                else
                                    % more than= 21 RWYs less than 24 RWYs
                                    for i = 2:5
                                        app.c{i}.Value = '';
                                    end
                                    
                                    % Show available RWYs on the 2nd page
                                    for i = 1:(app.anzahl_RWY_arrival-21)
                                        app.c{i+2}.Value = ['<' app.d{i+21}];
                                    end
                                    app.FPlanIndex = 77;
                                end
                                
                            else
                                % more than= 24 RWYs less than 27 RWYs
                                for i = 2:5
                                    app.c{i}.Value = '';
                                end
                                
                                % Show available RWYs on the 2nd page
                                for i = 1:(app.anzahl_RWY_arrival-24)
                                    app.c{i+2}.Value = ['<' app.d{i+24}];
                                end
                                app.FPlanIndex = 78;
                            end
                            
                        else
                            % more than= 27 RWYs less than 30 RWYs
                            for i = 2:5
                                app.c{i}.Value = '';
                            end
                            
                            % Show available RWYs on the 2nd page
                            for i = 1:(app.anzahl_RWY_arrival-27)
                                app.c{i+2}.Value = ['<' app.d{i+27}];
                            end
                            app.FPlanIndex = 79;
                        end
                        
                    else
                        % more than= 30 RWYs less than 33 RWYs
                        for i = 2:5
                            app.c{i}.Value = '';
                        end
                        
                        % Show available RWYs on the 2nd page
                        for i = 1:(app.anzahl_RWY_arrival-30)
                            app.c{i+2}.Value = ['<' app.d{i+30}];
                        end
                        app.FPlanIndex = 791;
                    end
                    
                else
                    % more than= 33 RWYs
                    for i = 2:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available RWYs on the 2nd page
                    for i = 1:(app.anzahl_RWY_arrival-33)
                        app.c{i+2}.Value = ['<' app.d{i+33}];
                    end
                    app.FPlanIndex = 792;
                end
                
            elseif app.FPlanIndex == 792
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Show available RWYs on the 2nd page
                for i = 1:(app.anzahl_RWY_arrival-30)
                    app.c{i+2}.Value = ['<' app.d{i+30}];
                end
                app.FPlanIndex = 791;
                
            elseif app.FPlanIndex == 791
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Show available RWYs on the 2nd page
                for i = 1:(app.anzahl_RWY_arrival-27)
                    app.c{i+2}.Value = ['<' app.d{i+27}];
                end
                app.FPlanIndex = 79;
                
                
            elseif app.FPlanIndex == 79
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Show available RWYs on the 2nd page
                for i = 1:(app.anzahl_RWY_arrival-24)
                    app.c{i+2}.Value = ['<' app.d{i+24}];
                end
                app.FPlanIndex = 78;
                
            elseif app.FPlanIndex == 78
               
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Show available RWYs on the 2nd page
                for i = 1:(app.anzahl_RWY_arrival-21)
                    app.c{i+2}.Value = ['<' app.d{i+21}];
                end
                app.FPlanIndex = 77;
                
            elseif app.FPlanIndex == 77
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Show available RWYs on the 2nd page
                for i = 1:(app.anzahl_RWY_arrival-18)
                    app.c{i+2}.Value = ['<' app.d{i+18}];
                end
                app.FPlanIndex = 76;
                
            elseif app.FPlanIndex == 76
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Show available RWYs on the 2nd page
                for i = 1:(app.anzahl_RWY_arrival-15)
                    app.c{i+2}.Value = ['<' app.d{i+15}];
                end
                app.FPlanIndex = 75;
                
            elseif app.FPlanIndex == 75
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Show available RWYs on the 2nd page
                for i = 1:(app.anzahl_RWY_arrival-12)
                    app.c{i+2}.Value = ['<' app.d{i+12}];
                end
                app.FPlanIndex = 74;
                
            elseif app.FPlanIndex == 74
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Show available RWYs on the 2nd page
                for i = 1:(app.anzahl_RWY_arrival-9)
                    app.c{i+2}.Value = ['<' app.d{i+9}];
                end
                app.FPlanIndex = 73;
                
            elseif app.FPlanIndex == 73
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Show available RWYs on the 2nd page
                for i = 1:(app.anzahl_RWY_arrival-6)
                    app.c{i+2}.Value = ['<' app.d{i+6}];
                end
                app.FPlanIndex = 72;
                
            elseif app.FPlanIndex == 72
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Show available RWYs on the 2nd page
                for i = 1:(app.anzahl_RWY_arrival-3)
                    app.c{i+2}.Value = ['<' app.d{i+3}];
                end
                app.FPlanIndex = 71;
                
            elseif app.FPlanIndex == 71
                
                for i = 2:5
                    app.c{i}.Value = '';
                end
                
                % Show available RWYs on the 2nd page
                for i = 1:(app.anzahl_RWY_arrival)
                    app.c{i+2}.Value = ['<' app.d{i}];
                end
                app.FPlanIndex = 7;
            end
            
            if app.FPlanIndex == 8
                
                if app.anzahl_STAR <= 33
                    
                    if app.anzahl_STAR <= 30
                        
                        if app.anzahl_STAR <= 27
                            
                            if app.anzahl_STAR <= 24
                                
                                if app.anzahl_STAR <= 21
                                    
                                    if app.anzahl_STAR <= 18
                                        
                                        if app.anzahl_STAR <= 15
                                            
                                            if app.anzahl_STAR <= 12
                                                
                                                if app.anzahl_STAR <= 9
                                                    
                                                    if app.anzahl_STAR <= 6
                                                        
                                                        if app.anzahl_STAR <= 3
                                                            
                                                        else
                                                            % more than= 3 STARs less than 6 STARs
                                                            for i = 3:5
                                                                app.c{i}.Value = '';
                                                            end
                                                            
                                                            for i = 1:(app.anzahl_STAR-3)
                                                                app.c{i+2}.Value = ['<' app.e{i+3}];
                                                            end
                                                            app.FPlanIndex = 81;
                                                        end
                                                        
                                                    else
                                                        % more than= 6 STARs less than 9 STARs
                                                        for i = 3:5
                                                            app.c{i}.Value = '';
                                                        end
                                                        
                                                        for i = 1:(app.anzahl_STAR-6)
                                                            app.c{i+2}.Value = ['<' app.e{i+6}];
                                                        end
                                                        app.FPlanIndex = 82;
                                                    end
                                                    
                                                else
                                                    % more than= 9 STARs less than 12 STARs
                                                    for i = 3:5
                                                        app.c{i}.Value = '';
                                                    end
                                                    
                                                    for i = 1:(app.anzahl_STAR-9)
                                                        app.c{i+2}.Value = ['<' app.e{i+9}];
                                                    end
                                                    app.FPlanIndex = 83;
                                                end
                                                
                                            else
                                                % more than= 12 STARs less than 15 STARs
                                                for i = 3:5
                                                    app.c{i}.Value = '';
                                                end
                                                
                                                % Show available RWYs on the 2nd page
                                                for i = 1:(app.anzahl_STAR-12)
                                                    app.c{i+2}.Value = ['<' app.e{i+12}];
                                                end
                                                app.FPlanIndex = 84;
                                            end
                                            
                                        else
                                            % more than= 15 STARs less than 18 STARs
                                            for i = 3:5
                                                app.c{i}.Value = '';
                                            end
                                            
                                            % Show available RWYs on the 2nd page
                                            for i = 1:(app.anzahl_STAR-15)
                                                app.c{i+2}.Value = ['<' app.e{i+15}];
                                            end
                                            app.FPlanIndex = 85;
                                        end
                                        
                                    else
                                        % more than= 18 STARs less than 21 STARs
                                        for i = 3:5
                                            app.c{i}.Value = '';
                                        end
                                        
                                        % Show available RWYs on the 2nd page
                                        for i = 1:(app.anzahl_STAR-18)
                                            app.c{i+2}.Value = ['<' app.e{i+18}];
                                        end
                                        app.FPlanIndex = 86;
                                    end
                                    
                                else
                                    % more than= 21 STARs less than 24 STARs
                                    for i = 3:5
                                        app.c{i}.Value = '';
                                    end
                                    
                                    % Show available RWYs on the 2nd page
                                    for i = 1:(app.anzahl_STAR-21)
                                        app.c{i+2}.Value = ['<' app.e{i+21}];
                                    end
                                    app.FPlanIndex = 87;
                                end
                                
                            else
                                % more than= 24 STARs less than 27 STARs
                                for i = 3:5
                                    app.c{i}.Value = '';
                                end
                                
                                % Show available RWYs on the 2nd page
                                for i = 1:(app.anzahl_STAR-24)
                                    app.c{i+2}.Value = ['<' app.e{i+24}];
                                end
                                app.FPlanIndex = 88;
                            end
                            
                        else
                            % more than= 27 STARs less than 30 STARs
                            for i = 3:5
                                app.c{i}.Value = '';
                            end
                            
                            % Show available RWYs on the 2nd page
                            for i = 1:(app.anzahl_STAR-27)
                                app.c{i+2}.Value = ['<' app.e{i+27}];
                            end
                            app.FPlanIndex = 89;
                        end
                        
                    else
                        % more than= 30 STARs less than 33 STARs
                        for i = 3:5
                            app.c{i}.Value = '';
                        end
                        
                        % Show available RWYs on the 2nd page
                        for i = 1:(app.anzahl_STAR-30)
                            app.c{i+2}.Value = ['<' app.e{i+30}];
                        end
                        app.FPlanIndex = 891;
                    end
                    
                else
                    % more than= 33 STARs
                    for i = 3:5
                        app.c{i}.Value = '';
                    end
                    
                    % Show available RWYs on the 2nd page
                    for i = 1:(app.anzahl_STAR-33)
                        app.c{i+2}.Value = ['<' app.e{i+33}];
                    end
                    app.FPlanIndex = 892;
                end
                
            elseif app.FPlanIndex == 892
                
                for i = 3:5
                    app.c{i}.Value = '';
                end
                
                % Show available RWYs on the 2nd page
                for i = 1:(app.anzahl_STAR-30)
                    app.c{i+2}.Value = ['<' app.e{i+30}];
                end
                app.FPlanIndex = 891;
                
            elseif app.FPlanIndex == 891
                
                for i = 3:5
                    app.c{i}.Value = '';
                end
                
                % Show available RWYs on the 2nd page
                for i = 1:(app.anzahl_STAR-27)
                    app.c{i+2}.Value = ['<' app.e{i+27}];
                end
                app.FPlanIndex = 89;
                
                
            elseif app.FPlanIndex == 89
                
                for i = 3:5
                    app.c{i}.Value = '';
                end
                
                % Show available RWYs on the 2nd page
                for i = 1:(app.anzahl_STAR-24)
                    app.c{i+2}.Value = ['<' app.e{i+24}];
                end
                app.FPlanIndex = 88;
                
            elseif app.FPlanIndex == 88
                
                for i = 3:5
                    app.c{i}.Value = '';
                end
                
                % Show available RWYs on the 2nd page
                for i = 1:(app.anzahl_STAR-21)
                    app.c{i+2}.Value = ['<' app.e{i+21}];
                end
                app.FPlanIndex = 87;
                
            elseif app.FPlanIndex == 87
                
                for i = 3:5
                    app.c{i}.Value = '';
                end
                
                % Show available RWYs on the 2nd page
                for i = 1:(app.anzahl_STAR-18)
                    app.c{i+2}.Value = ['<' app.e{i+18}];
                end
                app.FPlanIndex = 86;
                
            elseif app.FPlanIndex == 86
                
                for i = 3:5
                    app.c{i}.Value = '';
                end
                
                % Show available RWYs on the 2nd page
                for i = 1:(app.anzahl_STAR-15)
                    app.c{i+2}.Value = ['<' app.e{i+15}];
                end
                app.FPlanIndex = 85;
                
            elseif app.FPlanIndex == 85
                
                for i = 3:5
                    app.c{i}.Value = '';
                end
                
                % Show available RWYs on the 2nd page
                for i = 1:(app.anzahl_STAR-12)
                    app.c{i+2}.Value = ['<' app.e{i+12}];
                end
                app.FPlanIndex = 84;
                
            elseif app.FPlanIndex == 84
                
                for i = 3:5
                    app.c{i}.Value = '';
                end
                
                % Show available RWYs on the 2nd page
                for i = 1:(app.anzahl_STAR-9)
                    app.c{i+2}.Value = ['<' app.e{i+9}];
                end
                app.FPlanIndex = 83;
                
            elseif app.FPlanIndex == 83
                
                for i = 3:5
                    app.c{i}.Value = '';
                end
                
                % Show available RWYs on the 2nd page
                for i = 1:(app.anzahl_STAR-6)
                    app.c{i+2}.Value = ['<' app.e{i+6}];
                end
                app.FPlanIndex = 82;
                
            elseif app.FPlanIndex == 82
                
                for i = 3:5
                    app.c{i}.Value = '';
                end
                
                % Show available RWYs on the 2nd page
                for i = 1:(app.anzahl_STAR-3)
                    app.c{i+2}.Value = ['<' app.e{i+3}];
                end
                app.FPlanIndex = 81;
                
            elseif app.FPlanIndex == 81
                
                for i = 3:5
                    app.c{i}.Value = '';
                end
                
                % Show available RWYs on the 2nd page
                for i = 1:(app.anzahl_STAR)
                    app.c{i+2}.Value = ['<' app.e{i}];
                end
                app.FPlanIndex = 8;
                
            end
            
            if app.FPlanIndex == 9
                
                if app.anzahl_TRANS <= 9
                    
                    if app.anzahl_TRANS <= 6
                        
                        if app.anzahl_TRANS <= 3
                            
                        else
                            % more than= 3 TRANs less than 6 TRANs
                            for i = 3:5
                                app.c{i}.Value = '';
                            end
                            
                            for i = 1:(app.anzahl_TRANS-3)
                                app.c{i+1}.Value = ['<' app.f{i+3}];
                            end
                            app.FPlanIndex = 91;
                            
                        end
                        
                    else
                        % more than= 6 TRANs less than 9 TRANs
                        for i = 3:5
                            app.c{i}.Value = '';
                        end
                        
                        for i = 1:(app.anzahl_TRANS-6)
                            app.c{i+1}.Value = ['<' app.f{i+6}];
                        end
                        app.FPlanIndex = 92;
                        
                    end
                    
                else
                    % more than= 9 TRANs less than 12 TRANs
                    for i = 3:5
                        app.c{i}.Value = '';
                    end
                    
                    for i = 1:(app.anzahl_TRANS-9)
                        app.c{i+1}.Value = ['<' app.f{i+9}];
                    end
                    app.FPlanIndex = 93;
                end
                
            elseif app.FPlanIndex == 93
                
                for i = 3:5
                    app.c{i}.Value = '';
                end
                
                % Show available TRANs on the 2nd page
                for i = 1:(app.anzahl_TRANS-6)
                    app.c{i+1}.Value = ['<' app.f{i+6}];
                end
                app.FPlanIndex = 92;
                
            elseif app.FPlanIndex == 92
                
                for i = 3:5
                    app.c{i}.Value = '';
                end
                
                % Show available TRANs on the 2nd page
                for i = 1:(app.anzahl_TRANS-3)
                    app.c{i+1}.Value = ['<' app.f{i+3}];
                end
                app.FPlanIndex = 91;
                
            elseif app.FPlanIndex == 91
                
                for i = 3:5
                    app.c{i}.Value = '';
                end
                
                % Show available TRANs on the 2nd page
                for i = 1:(app.anzahl_TRANS)
                    app.c{i+1}.Value = ['<' app.f{i}];
                end
                app.FPlanIndex = 9;
            end
            
            if app.WPTIndex == 1
                
%             elseif app.WPTIndex == 1999
%                 
%                % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-26)
%                         app.c{i}.Value = app.all_wpts.name{i+26};
%                         if num2str(app.all_wpts.alt_top{i+26}) == '0'
%                         
%                         else
%                         app.r{i}.Value = num2str(app.all_wpts.alt_top{i+26});
%                         end
%                     end
%                     app.WPTIndex = 1998;    
%                 
%             elseif app.WPTIndex == 1998
%                 
%                % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-25)
%                         app.c{i}.Value = app.all_wpts.name{i+25};
%                         if num2str(app.all_wpts.alt_top{i+25}) == '0'
%                         
%                         else
%                         app.r{i}.Value = num2str(app.all_wpts.alt_top{i+25});
%                         end
%                     end
%                     app.WPTIndex = 1997;    
%                 
%             elseif app.WPTIndex == 1997
%                 
%                % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-24)
%                         app.c{i}.Value = app.all_wpts.name{i+24};
%                         if num2str(app.all_wpts.alt_top{i+24}) == '0'
%                         
%                         else
%                         app.r{i}.Value = num2str(app.all_wpts.alt_top{i+24});
%                         end
%                     end
%                     app.WPTIndex = 1996;    
%                 
%             elseif app.WPTIndex == 1996
%                 
%                % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-23)
%                         app.c{i}.Value = app.all_wpts.name{i+23};
%                         if num2str(app.all_wpts.alt_top{i+23}) == '0'
%                         
%                         else
%                         app.r{i}.Value = num2str(app.all_wpts.alt_top{i+23});
%                         end
%                     end
%                     app.WPTIndex = 1995;    
%                 
%             elseif app.WPTIndex == 1995
%                 
%                % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-22)
%                         app.c{i}.Value = app.all_wpts.name{i+22};
%                         if num2str(app.all_wpts.alt_top{i+22}) == '0'
%                         
%                         else
%                         app.r{i}.Value = num2str(app.all_wpts.alt_top{i+22});
%                         end
%                     end
%                     app.WPTIndex = 1994;    
%                 
%             elseif app.WPTIndex == 1994
%                 
%                % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-21)
%                         app.c{i}.Value = app.all_wpts.name{i+21};
%                         if num2str(app.all_wpts.alt_top{i+21}) == '0'
%                         
%                         else
%                         app.r{i}.Value = num2str(app.all_wpts.alt_top{i+21});
%                         end
%                     end
%                     app.WPTIndex = 1993;    
%             
%             elseif app.WPTIndex == 1993
%                 
%                % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-20)
%                         app.c{i}.Value = app.all_wpts.name{i+20};
%                         if num2str(app.all_wpts.alt_top{i+20}) == '0'
%                         
%                         else
%                         app.r{i}.Value = num2str(app.all_wpts.alt_top{i+20});
%                         end
%                     end
%                     app.WPTIndex = 1992; 
%                 
%             elseif app.WPTIndex == 1992
%                 
%                % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-19)
%                         app.c{i}.Value = app.all_wpts.name{i+19};
%                         if num2str(app.all_wpts.alt_top{i+19}) == '0'
%                         
%                         else
%                         app.r{i}.Value = num2str(app.all_wpts.alt_top{i+19});
%                         end
%                     end
%                     app.WPTIndex = 1991;
%                                 
%             elseif app.WPTIndex == 1991
%                 
%                % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-18)
%                         app.c{i}.Value = app.all_wpts.name{i+18};
%                         if num2str(app.all_wpts.alt_top{i+18}) == '0'
%                         
%                         else
%                         app.r{i}.Value = num2str(app.all_wpts.alt_top{i+18});
%                         end
%                     end
%                     app.WPTIndex = 199;    
%                 
%             elseif app.WPTIndex == 199
%                 
%                % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-17)
%                         app.c{i}.Value = app.all_wpts.name{i+17};
%                         if num2str(app.all_wpts.alt_top{i+17}) == '0'
%                         
%                         else
%                         app.r{i}.Value = num2str(app.all_wpts.alt_top{i+17});
%                         end
%                     end
%                     app.WPTIndex = 198;    
%                 
%             elseif app.WPTIndex == 198
%                 
%                % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-16)
%                         app.c{i}.Value = app.all_wpts.name{i+16};
%                         if num2str(app.all_wpts.alt_top{i+16}) == '0'
%                         
%                         else
%                         app.r{i}.Value = num2str(app.all_wpts.alt_top{i+16});
%                         end
%                     end
%                     app.WPTIndex = 197;    
%                 
%             elseif app.WPTIndex == 197
%                 
%                % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-15)
%                         app.c{i}.Value = app.all_wpts.name{i+15};
%                         if num2str(app.all_wpts.alt_top{i+15}) == '0'
%                         
%                         else
%                         app.r{i}.Value = num2str(app.all_wpts.alt_top{i+15});
%                         end
%                     end
%                     app.WPTIndex = 196;    
%                 
%             elseif app.WPTIndex == 196
%                 
%                % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-14)
%                         app.c{i}.Value = app.all_wpts.name{i+14};
%                         if num2str(app.all_wpts.alt_top{i+14}) == '0'
%                         
%                         else
%                         app.r{i}.Value = num2str(app.all_wpts.alt_top{i+14});
%                         end
%                     end
%                     app.WPTIndex = 195;    
%                
%             elseif app.WPTIndex == 195
%                 
%                % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-13)
%                         app.c{i}.Value = app.all_wpts.name{i+13};
%                         if num2str(app.all_wpts.alt_top{i+13}) == '0'
%                         
%                         else
%                         app.r{i}.Value = num2str(app.all_wpts.alt_top{i+13});
%                         end
%                     end
%                     app.WPTIndex = 194;    
%                 
%             elseif app.WPTIndex == 194
%                 
%                % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-12)
%                         app.c{i}.Value = app.all_wpts.name{i+12};
%                         if num2str(app.all_wpts.alt_top{i+12}) == '0'
%                         
%                         else
%                         app.r{i}.Value = num2str(app.all_wpts.alt_top{i+12});
%                         end
%                     end
%                     app.WPTIndex = 193;
%                     
%             elseif app.WPTIndex == 193
%                 
%                % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-11)
%                         app.c{i}.Value = app.all_wpts.name{i+11};
%                         if num2str(app.all_wpts.alt_top{i+11}) == '0'
%                         
%                         else
%                         app.r{i}.Value = num2str(app.all_wpts.alt_top{i+11});
%                         end
%                     end
%                     app.WPTIndex = 192;
%                     
%             elseif app.WPTIndex == 192
%                 
%                % Clear the EditFields
%                     for i = 1:5
%                         app.c{i}.Value = '';
%                         app.r{i}.Value = '';
%                     end
%                     
%                     % Show available WPTs 
%                     for i = 1:(app.anzahl_all_wpts-10)
%                         app.c{i}.Value = app.all_wpts.name{i+10};
%                         if num2str(app.all_wpts.alt_top{i+10}) == '0'
%                         
%                         else
%                         app.r{i}.Value = num2str(app.all_wpts.alt_top{i+10});
%                         end
%                     end
%                     app.WPTIndex = 191;    
                
            elseif app.WPTIndex == 191
                
               % Clear the EditFields
                    for i = 1:5
                        app.c{i}.Value = '';
                        app.cu{i}.Value = '';
                        app.r{i}.Value = '';
                        app.m{i}.Value = '';
                    end
                    
                    % Show available WPTs 
                    for i = 1:(app.anzahl_all_wpts-45)
                        [wpt_name,result,course,time,distance] = get_FPlan_data(app.all_wpts,i+45);
                        app.cu{i+1}.Value = course;
                        app.c{i}.Value = wpt_name;                    
                        app.r{i}.Value = result;
                        app.m{i}.Value = distance;
                        %app.m{i}.Value = time;
                    end
                    app.WPTIndex = 19;    
                
            elseif app.WPTIndex == 19
                
               % Clear the EditFields
                    for i = 1:5
                        app.c{i}.Value = '';
                        app.cu{i}.Value = '';
                        app.r{i}.Value = '';
                        app.m{i}.Value = '';
                    end
                    
                    % Show available WPTs 
                    for i = 1:(app.anzahl_all_wpts-40)
                        [wpt_name,result,course,time,distance] = get_FPlan_data(app.all_wpts,i+40);
                        app.cu{i+1}.Value = course;
                        app.c{i}.Value = wpt_name;                    
                        app.r{i}.Value = result;
                        app.m{i+1}.Value = distance;
                        %app.m{i+1}.Value = time;
                    end
                    app.WPTIndex = 18;    
                
            elseif app.WPTIndex == 18
                
               % Clear the EditFields
                    for i = 1:5
                        app.c{i}.Value = '';
                        app.cu{i}.Value = '';
                        app.r{i}.Value = '';
                        app.m{i}.Value = '';
                    end
                    
                    % Show available WPTs 
                    for i = 1:(app.anzahl_all_wpts-35)
                        [wpt_name,result,course,time,distance] = get_FPlan_data(app.all_wpts,i+35);
                        app.cu{i+1}.Value = course;
                        app.c{i}.Value = wpt_name;                    
                        app.r{i}.Value = result;
                        app.m{i}.Value = distance;
                        %app.m{i}.Value = time;
                    end
                    app.WPTIndex = 17;    
                
            elseif app.WPTIndex == 17
                
               % Clear the EditFields
                    for i = 1:5
                        app.c{i}.Value = '';
                        app.cu{i}.Value = '';
                        app.r{i}.Value = '';
                        app.m{i}.Value = '';
                    end
                    
                    % Show available WPTs 
                    for i = 1:(app.anzahl_all_wpts-30)
                        [wpt_name,result,course,time,distance] = get_FPlan_data(app.all_wpts,i+30);
                        app.cu{i+1}.Value = course;
                        app.c{i}.Value = wpt_name;                    
                        app.r{i}.Value = result;
                        app.m{i}.Value = distance;
                        %app.m{i}.Value = time;
                    end
                    app.WPTIndex = 16;    
                
            elseif app.WPTIndex == 16
                
               % Clear the EditFields
                    for i = 1:5
                        app.c{i}.Value = '';
                        app.cu{i}.Value = '';
                        app.r{i}.Value = '';
                        app.m{i}.Value = '';
                    end
                    
                    % Show available WPTs 
                    for i = 1:(app.anzahl_all_wpts-25)
                        [wpt_name,result,course,time,distance] = get_FPlan_data(app.all_wpts,i+25);
                        app.cu{i+1}.Value = course;
                        app.c{i}.Value = wpt_name;                    
                        app.r{i}.Value = result;
                        app.m{i}.Value = distance;
                        %app.m{i}.Value = time;
                    end
                    app.WPTIndex = 15;
                    
            elseif app.WPTIndex == 15
                
               % Clear the EditFields
                    for i = 1:5
                        app.c{i}.Value = '';
                        app.cu{i}.Value = '';
                        app.r{i}.Value = '';
                        app.m{i}.Value = '';
                    end
                    
                    % Show available WPTs 
                    for i = 1:(app.anzahl_all_wpts-20)
                        [wpt_name,result,course,time,distance] = get_FPlan_data(app.all_wpts,i+20);
                        app.cu{i+1}.Value = course;
                        app.c{i}.Value = wpt_name;                    
                        app.r{i}.Value = result;
                        app.m{i}.Value = distance;
                        %app.m{i}.Value = time;
                    end
                    app.WPTIndex = 14;    
                
            elseif app.WPTIndex == 14
                
               % Clear the EditFields
                    for i = 1:5
                        app.c{i}.Value = '';
                        app.cu{i}.Value = '';
                        app.r{i}.Value = '';
                        app.m{i}.Value = '';
                    end
                    
                    % Show available WPTs 
                    for i = 1:(app.anzahl_all_wpts-15)                        
                        [wpt_name,result,course,time,distance] = get_FPlan_data(app.all_wpts,i+15);
                        app.cu{i+1}.Value = course;
                        app.c{i}.Value = wpt_name;                    
                        app.r{i}.Value = result;
                        app.m{i}.Value = distance;
                        %app.m{i}.Value = time;
                    end
                    app.WPTIndex = 13;    
                
            elseif app.WPTIndex == 13
                
               % Clear the EditFields
                    for i = 1:5
                        app.c{i}.Value = '';
                        app.cu{i}.Value = '';
                        app.r{i}.Value = '';
                        app.m{i}.Value = '';
                    end
                    
                    % Show available WPTs 
                    for i = 1:(app.anzahl_all_wpts-10)
                        [wpt_name,result,course,time,distance] = get_FPlan_data(app.all_wpts,i+10);
                        app.cu{i+1}.Value = course;
                        app.c{i}.Value = wpt_name;                    
                        app.r{i}.Value = result;
                        app.m{i}.Value = distance;
                        %app.m{i}.Value = time;
                    end
                    app.WPTIndex = 12;    
                
            elseif app.WPTIndex == 12
                
               % Clear the EditFields
                    for i = 1:5
                        app.c{i}.Value = '';
                        app.cu{i}.Value = '';
                        app.r{i}.Value = '';
                        app.m{i}.Value = '';
                    end
                    
                    % Show available WPTs 
                    for i = 1:(app.anzahl_all_wpts-5)
                        [wpt_name,result,course,time,distance] = get_FPlan_data(app.all_wpts,i+5);
                        app.cu{i+1}.Value = course;
                        app.c{i}.Value = wpt_name;                    
                        app.r{i}.Value = result;
                        app.m{i}.Value = distance;
                        %app.m{i}.Value = time;
                    end
                    app.WPTIndex = 11;
                    
            elseif app.WPTIndex == 11
                
               % Clear the EditFields
                    for i = 1:5
                        app.c{i}.Value = '';
                        app.cu{i}.Value = '';
                        app.r{i}.Value = '';
                        app.m{i}.Value = '';
                    end
                    
                    % Show available WPTs 
                    for i = 1:(app.anzahl_all_wpts)
                        [wpt_name,result,course,time,distance] = get_FPlan_data(app.all_wpts,i);
                        app.cu{i+1}.Value = course;
                        app.c{i}.Value = wpt_name;                    
                        app.r{i}.Value = result;
                        app.m{i}.Value = distance;
                        %app.m{i}.Value = time;
                    end
                    app.WPTIndex = 1; 
            end
            
            
            if app.DataIndex == 2
                
                if length(app.aircraft_bada) <= 10
                    
                    if length(app.aircraft_bada) <= 5
                        % Nothing happens                            
                    else
                        % more than= 5 A/C Types less than 11 A/C Types
                        for i = 1:5
                            app.c{i}.Value = '';
                        end
                        
                        for i = 1:(length(app.aircraft_bada)-5)
                            app.c{i}.Value = ['< ' app.aircraft_bada{i+5}];
                        end
                        app.DataIndex = 21;
                    end
                    
                else
                    % more than= 12 A/C Types less than 16 A/C Types
                    for i = 1:5
                        app.c{i}.Value = '';
                    end
                    
                    for i = 1:(length(app.aircraft_bada)-10)
                        app.c{i+1}.Value = ['< ' app.aircraft_bada{i+10}];
                    end
                    app.DataIndex = 22; 
                end
           
            elseif app.DataIndex == 22   
                
                for i = 1:5
                    app.c{i}.Value = '';
                end
                
                % Show available A/C Types on the 2nd page
                for i = 1:(length(app.aircraft_bada)-5)
                    app.c{i}.Value = ['< ' app.aircraft_bada{i+5}];
                end
                app.DataIndex = 2;
                
           elseif app.DataIndex == 21 
                
                for i = 1:5
                    app.c{i}.Value = '';
                end
                
                % Show available A/C Types on the 2nd page
                for i = 1:length(app.aircraft_bada)
                    app.c{i}.Value = ['< ' app.aircraft_bada{i}];
                end
                app.DataIndex = 2;     
           end
                
        end

        % Button pushed function: Button_plusminus
        function Button_plusminusPushed(app, event)
      
            
            if app.PlusMinusIndex == 1 % Abfrage ob Minus Zeichen vorliegt
                
                result = [app.EditField_Scratch.Value(1:end-1) '-'];
                app.EditField_Scratch.Value = result;
                
                app.PlusMinusIndex = 2;
            elseif app.PlusMinusIndex == 2
                
                result = [app.EditField_Scratch.Value(1:end-1) '+'];
                app.EditField_Scratch.Value = result;
                
                app.PlusMinusIndex = 1;
            else
                result = [app.EditField_Scratch.Value '+'];
                app.EditField_Scratch.Value = result;
            
                app.PlusMinusIndex = 1;        
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FMCMatlabUIFigure = uifigure('Visible', 'off');
            app.FMCMatlabUIFigure.Name = 'FMC Matlab';
            app.TabGroup = uitabgroup(app.FMCMatlabUIFigure);
            app.MCDU = uitab(app.TabGroup);
            app.MCDU.Title = 'MCDU';
            app.EditField_Title = uieditfield(app.MCDU, 'text');
            app.EditField_Title.Value = 'MCDU MENUE';
            app.Label_2 = uilabel(app.MCDU);
            app.Label_2.Text = '';
            app.EditField_L1_Upper = uieditfield(app.MCDU, 'text');
            app.EditField_M1_Upper = uieditfield(app.MCDU, 'text');
            app.EditField_R1_Upper = uieditfield(app.MCDU, 'text');
            app.Label_3 = uilabel(app.MCDU);
            app.Label_3.Text = '';
            app.EditField_L1 = uieditfield(app.MCDU, 'text');
            app.EditField_L1.Value = ' <FMGC';
            app.EditField_M1 = uieditfield(app.MCDU, 'text');
            app.EditField_R1 = uieditfield(app.MCDU, 'text');
            app.EditField_L2_Upper = uieditfield(app.MCDU, 'text');
            app.EditField_M2_Upper = uieditfield(app.MCDU, 'text');
            app.EditField_R2_Upper = uieditfield(app.MCDU, 'text');
            app.Label_5 = uilabel(app.MCDU);
            app.Label_5.Text = '';
            app.Label_4 = uilabel(app.MCDU);
            app.Label_4.Text = '';
            app.L1_Button = uibutton(app.MCDU, 'push');
            app.L1_Button.ButtonPushedFcn = createCallbackFcn(app, @L1_ButtonPushed, true);
            app.L1_Button.Text = '---';
            app.L2_Button = uibutton(app.MCDU, 'push');
            app.L2_Button.ButtonPushedFcn = createCallbackFcn(app, @L2_ButtonPushed, true);
            app.L2_Button.Text = '---';
            app.L3_Button = uibutton(app.MCDU, 'push');
            app.L3_Button.ButtonPushedFcn = createCallbackFcn(app, @L3_ButtonPushed, true);
            app.L3_Button.Text = '---';
            app.L4_Button = uibutton(app.MCDU, 'push');
            app.L4_Button.ButtonPushedFcn = createCallbackFcn(app, @L4_ButtonPushed, true);
            app.L4_Button.Text = '---';
            app.R1_Button = uibutton(app.MCDU, 'push');
            app.R1_Button.ButtonPushedFcn = createCallbackFcn(app, @R1_ButtonPushed, true);
            app.R1_Button.Text = '---';
            app.R2_Button = uibutton(app.MCDU, 'push');
            app.R2_Button.ButtonPushedFcn = createCallbackFcn(app, @R2_ButtonPushed, true);
            app.R2_Button.Text = '---';
            app.R3_Button = uibutton(app.MCDU, 'push');
            app.R3_Button.ButtonPushedFcn = createCallbackFcn(app, @R3_ButtonPushed, true);
            app.R3_Button.Text = '---';
            app.R4_Button = uibutton(app.MCDU, 'push');
            app.R4_Button.ButtonPushedFcn = createCallbackFcn(app, @R4_ButtonPushed, true);
            app.R4_Button.Text = '---';
            app.EditField_L2 = uieditfield(app.MCDU, 'text');
            app.EditField_M2 = uieditfield(app.MCDU, 'text');
            app.EditField_R2 = uieditfield(app.MCDU, 'text');
            app.EditField_L3_Upper = uieditfield(app.MCDU, 'text');
            app.EditField_M3_Upper = uieditfield(app.MCDU, 'text');
            app.EditField_R3_Upper = uieditfield(app.MCDU, 'text');
            app.Label_17 = uilabel(app.MCDU);
            app.Label_17.Text = '';
            app.Label_6 = uilabel(app.MCDU);
            app.Label_6.Text = '';
            app.EditField_L3 = uieditfield(app.MCDU, 'text');
            app.EditField_L3.Value = ' <ACSM';
            app.EditField_M3 = uieditfield(app.MCDU, 'text');
            app.EditField_R3 = uieditfield(app.MCDU, 'text');
            app.Label_12 = uilabel(app.MCDU);
            app.Label_12.Text = '';
            app.EditField_L4_Upper = uieditfield(app.MCDU, 'text');
            app.EditField_M4_Upper = uieditfield(app.MCDU, 'text');
            app.EditField_R4_Upper = uieditfield(app.MCDU, 'text');
            app.Label_7 = uilabel(app.MCDU);
            app.Label_7.Text = '';
            app.EditField_L4 = uieditfield(app.MCDU, 'text');
            app.EditField_M4 = uieditfield(app.MCDU, 'text');
            app.EditField_R4 = uieditfield(app.MCDU, 'text');
            app.EditField_L5_Upper = uieditfield(app.MCDU, 'text');
            app.EditField_M5_Upper = uieditfield(app.MCDU, 'text');
            app.EditField_R5_Upper = uieditfield(app.MCDU, 'text');
            app.Label_8 = uilabel(app.MCDU);
            app.Label_8.Text = '';
            app.Label_13 = uilabel(app.MCDU);
            app.Label_13.Text = '';
            app.L5_Button = uibutton(app.MCDU, 'push');
            app.L5_Button.ButtonPushedFcn = createCallbackFcn(app, @L5_ButtonPushed, true);
            app.L5_Button.Text = '---';
            app.L6_Button = uibutton(app.MCDU, 'push');
            app.L6_Button.ButtonPushedFcn = createCallbackFcn(app, @L6_ButtonPushed, true);
            app.L6_Button.Text = '---';
            app.R5_Button = uibutton(app.MCDU, 'push');
            app.R5_Button.ButtonPushedFcn = createCallbackFcn(app, @R5_ButtonPushed, true);
            app.R5_Button.Text = '---';
            app.R6_Button = uibutton(app.MCDU, 'push');
            app.R6_Button.ButtonPushedFcn = createCallbackFcn(app, @R6_ButtonPushed, true);
            app.R6_Button.Text = '---';
            app.EditField_L5 = uieditfield(app.MCDU, 'text');
            app.EditField_L5.Value = ' <SAT';
            app.EditField_M5 = uieditfield(app.MCDU, 'text');
            app.EditField_R5 = uieditfield(app.MCDU, 'text');
            app.EditField_L6_Upper = uieditfield(app.MCDU, 'text');
            app.EditField_M6_Upper = uieditfield(app.MCDU, 'text');
            app.EditField_R6_Upper = uieditfield(app.MCDU, 'text');
            app.Label_14 = uilabel(app.MCDU);
            app.Label_14.Text = '';
            app.Label_9 = uilabel(app.MCDU);
            app.Label_9.Text = '';
            app.EditField_L6 = uieditfield(app.MCDU, 'text');
            app.EditField_M6 = uieditfield(app.MCDU, 'text');
            app.EditField_R6 = uieditfield(app.MCDU, 'text');
            app.EditField_R6.Value = 'RETURN> ';
            app.Label_15 = uilabel(app.MCDU);
            app.Label_15.Text = '';
            app.EditField_L_Scratch_Upper = uieditfield(app.MCDU, 'text');
            app.EditField_M_Sratch_Upper = uieditfield(app.MCDU, 'text');
            app.EditField_R_Scratch_Upper = uieditfield(app.MCDU, 'text');
            app.Label_10 = uilabel(app.MCDU);
            app.Label_10.Text = '';
            app.EditField_Scratch = uieditfield(app.MCDU, 'text');
            app.Label_18 = uilabel(app.MCDU);
            app.Label_18.Text = '';
            app.Label_16 = uilabel(app.MCDU);
            app.Label_16.Text = '';
            app.Label_19 = uilabel(app.MCDU);
            app.Label_19.Text = '';
            app.Label_20 = uilabel(app.MCDU);
            app.Label_20.Text = '';
            app.Label_21 = uilabel(app.MCDU);
            app.Label_21.Text = '';
            app.Label_22 = uilabel(app.MCDU);
            app.Label_22.Text = '';
            app.DIRButton = uibutton(app.MCDU, 'push');
            app.DIRButton.ButtonPushedFcn = createCallbackFcn(app, @DIRButtonPushed, true);
            app.DIRButton.Text = {'DIR'; ''};
            app.PROGButton_2 = uibutton(app.MCDU, 'push');
            app.PROGButton_2.ButtonPushedFcn = createCallbackFcn(app, @PROGButton_2Pushed, true);
            app.PROGButton_2.Text = {'PROG'; ''};
            app.PERFButton = uibutton(app.MCDU, 'push');
            app.PERFButton.ButtonPushedFcn = createCallbackFcn(app, @PERFButtonPushed, true);
            app.PERFButton.Text = {'PERF'; ''};
            app.INITButton = uibutton(app.MCDU, 'push');
            app.INITButton.ButtonPushedFcn = createCallbackFcn(app, @INITButtonPushed, true);
            app.INITButton.Text = 'INIT';
            app.DATAButton = uibutton(app.MCDU, 'push');
            app.DATAButton.ButtonPushedFcn = createCallbackFcn(app, @DATAButtonPushed, true);
            app.DATAButton.Text = {'DATA'; ''};
            app.FPLNButton = uibutton(app.MCDU, 'push');
            app.FPLNButton.ButtonPushedFcn = createCallbackFcn(app, @FPLNButtonPushed, true);
            app.FPLNButton.Text = 'F-PLN';
            app.RADNAVButton = uibutton(app.MCDU, 'push');
            app.RADNAVButton.ButtonPushedFcn = createCallbackFcn(app, @RADNAVButtonPushed, true);
            app.RADNAVButton.Text = {'RAD'; 'NAV'};
            app.FUELPREDButton = uibutton(app.MCDU, 'push');
            app.FUELPREDButton.ButtonPushedFcn = createCallbackFcn(app, @FUELPREDButtonPushed, true);
            app.FUELPREDButton.Text = {'FUEL'; 'PRED'; ''};
            app.SECFPLNButton = uibutton(app.MCDU, 'push');
            app.SECFPLNButton.ButtonPushedFcn = createCallbackFcn(app, @SECFPLNButtonPushed, true);
            app.SECFPLNButton.Text = {'SEC'; 'F-PLN'};
            app.ATCCOMMButton = uibutton(app.MCDU, 'push');
            app.ATCCOMMButton.ButtonPushedFcn = createCallbackFcn(app, @ATCCOMMButtonPushed, true);
            app.ATCCOMMButton.Text = {'ATC'; 'COMM'};
            app.MCDUMENUButton = uibutton(app.MCDU, 'push');
            app.MCDUMENUButton.ButtonPushedFcn = createCallbackFcn(app, @MCDUMENUButtonPushed, true);
            app.MCDUMENUButton.Text = {'MCDU'; 'MENU'};
            app.Label = uilabel(app.MCDU);
            app.Label.Text = '';
            app.EButton = uibutton(app.MCDU, 'push');
            app.EButton.ButtonPushedFcn = createCallbackFcn(app, @EButtonPushed, true);
            app.EButton.Text = 'E';
            app.JButton = uibutton(app.MCDU, 'push');
            app.JButton.ButtonPushedFcn = createCallbackFcn(app, @JButtonPushed, true);
            app.JButton.Text = 'J';
            app.OButton = uibutton(app.MCDU, 'push');
            app.OButton.ButtonPushedFcn = createCallbackFcn(app, @OButtonPushed, true);
            app.OButton.Text = 'O';
            app.TButton = uibutton(app.MCDU, 'push');
            app.TButton.ButtonPushedFcn = createCallbackFcn(app, @TButtonPushed, true);
            app.TButton.Text = 'T';
            app.YButton = uibutton(app.MCDU, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = 'Y';
            app.CLRButton = uibutton(app.MCDU, 'push');
            app.CLRButton.ButtonPushedFcn = createCallbackFcn(app, @CLRButtonPushed, true);
            app.CLRButton.Text = 'CLR';
            app.DButton = uibutton(app.MCDU, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @DButtonPushed, true);
            app.DButton.Text = 'D';
            app.IButton = uibutton(app.MCDU, 'push');
            app.IButton.ButtonPushedFcn = createCallbackFcn(app, @IButtonPushed, true);
            app.IButton.Text = 'I';
            app.NButton = uibutton(app.MCDU, 'push');
            app.NButton.ButtonPushedFcn = createCallbackFcn(app, @NButtonPushed, true);
            app.NButton.Text = 'N';
            app.SButton = uibutton(app.MCDU, 'push');
            app.SButton.ButtonPushedFcn = createCallbackFcn(app, @SButtonPushed, true);
            app.SButton.Text = 'S';
            app.XButton = uibutton(app.MCDU, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X';
            app.Button_FWslash = uibutton(app.MCDU, 'push');
            app.Button_FWslash.ButtonPushedFcn = createCallbackFcn(app, @Button_FWslashPushed, true);
            app.Button_FWslash.Text = '/';
            app.AIRPORTButton = uibutton(app.MCDU, 'push');
            app.AIRPORTButton.ButtonPushedFcn = createCallbackFcn(app, @AIRPORTButtonPushed, true);
            app.AIRPORTButton.Text = {'AIR'; 'PORT'};
            app.LEFTButton = uibutton(app.MCDU, 'push');
            app.LEFTButton.ButtonPushedFcn = createCallbackFcn(app, @LEFTButtonPushed, true);
            app.LEFTButton.Text = '←';
            app.UPButton = uibutton(app.MCDU, 'push');
            app.UPButton.ButtonPushedFcn = createCallbackFcn(app, @UPButtonPushed, true);
            app.UPButton.Text = {'↑'; ''};
            app.Button_10 = uibutton(app.MCDU, 'push');
            app.Button_10.Text = '';
            app.RIGHTButton = uibutton(app.MCDU, 'push');
            app.RIGHTButton.ButtonPushedFcn = createCallbackFcn(app, @RIGHTButtonPushed, true);
            app.RIGHTButton.Text = {'→'; ''};
            app.DOWNButton = uibutton(app.MCDU, 'push');
            app.DOWNButton.ButtonPushedFcn = createCallbackFcn(app, @DOWNButtonPushed, true);
            app.DOWNButton.Text = '↓';
            app.Button_1 = uibutton(app.MCDU, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.MCDU, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_4 = uibutton(app.MCDU, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.MCDU, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_7 = uibutton(app.MCDU, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '7';
            app.Button_8 = uibutton(app.MCDU, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '8';
            app.Button_dot = uibutton(app.MCDU, 'push');
            app.Button_dot.ButtonPushedFcn = createCallbackFcn(app, @Button_dotPushed, true);
            app.Button_dot.Text = '.';
            app.Button_0 = uibutton(app.MCDU, 'push');
            app.Button_0.ButtonPushedFcn = createCallbackFcn(app, @Button_0Pushed, true);
            app.Button_0.Text = '0';
            app.Button_3 = uibutton(app.MCDU, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_6 = uibutton(app.MCDU, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '6';
            app.Button_9 = uibutton(app.MCDU, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '9';
            app.Button_plusminus = uibutton(app.MCDU, 'push');
            app.Button_plusminus.ButtonPushedFcn = createCallbackFcn(app, @Button_plusminusPushed, true);
            app.Button_plusminus.Text = '+/-';
            app.AButton = uibutton(app.MCDU, 'push');
            app.AButton.ButtonPushedFcn = createCallbackFcn(app, @AButtonPushed, true);
            app.AButton.Text = 'A';
            app.FButton = uibutton(app.MCDU, 'push');
            app.FButton.ButtonPushedFcn = createCallbackFcn(app, @FButtonPushed, true);
            app.FButton.Text = 'F';
            app.KButton = uibutton(app.MCDU, 'push');
            app.KButton.ButtonPushedFcn = createCallbackFcn(app, @KButtonPushed, true);
            app.KButton.Text = 'K';
            app.PButton = uibutton(app.MCDU, 'push');
            app.PButton.ButtonPushedFcn = createCallbackFcn(app, @PButtonPushed, true);
            app.PButton.Text = 'P';
            app.UButton = uibutton(app.MCDU, 'push');
            app.UButton.ButtonPushedFcn = createCallbackFcn(app, @UButtonPushed, true);
            app.UButton.Text = 'U';
            app.ZButton = uibutton(app.MCDU, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = 'Z';
            app.BButton = uibutton(app.MCDU, 'push');
            app.BButton.ButtonPushedFcn = createCallbackFcn(app, @BButtonPushed, true);
            app.BButton.Text = 'B';
            app.GButton = uibutton(app.MCDU, 'push');
            app.GButton.ButtonPushedFcn = createCallbackFcn(app, @GButtonPushed, true);
            app.GButton.Text = 'G';
            app.LButton = uibutton(app.MCDU, 'push');
            app.LButton.ButtonPushedFcn = createCallbackFcn(app, @LButtonPushed, true);
            app.LButton.Text = 'L';
            app.QButton = uibutton(app.MCDU, 'push');
            app.QButton.ButtonPushedFcn = createCallbackFcn(app, @QButtonPushed, true);
            app.QButton.Text = 'Q';
            app.VButton = uibutton(app.MCDU, 'push');
            app.VButton.ButtonPushedFcn = createCallbackFcn(app, @VButtonPushed, true);
            app.VButton.Text = 'V';
            app.SPButton = uibutton(app.MCDU, 'push');
            app.SPButton.ButtonPushedFcn = createCallbackFcn(app, @SPButtonPushed, true);
            app.SPButton.Text = 'SP';
            app.CButton = uibutton(app.MCDU, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = 'C';
            app.HButton = uibutton(app.MCDU, 'push');
            app.HButton.ButtonPushedFcn = createCallbackFcn(app, @HButtonPushed, true);
            app.HButton.Text = 'H';
            app.MButton = uibutton(app.MCDU, 'push');
            app.MButton.ButtonPushedFcn = createCallbackFcn(app, @MButtonPushed, true);
            app.MButton.Text = 'M';
            app.RButton = uibutton(app.MCDU, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Text = 'R';
            app.WButton = uibutton(app.MCDU, 'push');
            app.WButton.ButtonPushedFcn = createCallbackFcn(app, @WButtonPushed, true);
            app.WButton.Text = 'W';
            app.DELButton = uibutton(app.MCDU, 'push');
            app.DELButton.ButtonPushedFcn = createCallbackFcn(app, @DELButtonPushed, true);
            app.DELButton.Text = 'DEL';
            app.NavDisplay = uitab(app.TabGroup);
            app.NavDisplay.Title = 'NavDisplay';
            app.NavigationDisplay_LFT = uiaxes(app.NavDisplay);
            title(app.NavigationDisplay_LFT, 'Lateral Flight Trajectory')
            xlabel(app.NavigationDisplay_LFT, 'Longitude [deg]')
            ylabel(app.NavigationDisplay_LFT, 'Latiitude [deg]')
            app.NavigationDisplay_VFT = uiaxes(app.NavDisplay);
            title(app.NavigationDisplay_VFT, 'Vertical Flight Trajectory')
            xlabel(app.NavigationDisplay_VFT, 'Range [NM]')
            ylabel(app.NavigationDisplay_VFT, 'Altitude [ft]')
            app.PerfDisplay = uitab(app.TabGroup);
            app.PerfDisplay.Title = 'PerfDisplay';
            app.SpeedDisplay = uiaxes(app.PerfDisplay);
            title(app.SpeedDisplay, 'Speed Indicator')
            xlabel(app.SpeedDisplay, 'Range [NM]')
            ylabel(app.SpeedDisplay, 'CAS [kts]')
            app.WeightDisplay = uiaxes(app.PerfDisplay);
            title(app.WeightDisplay, 'Weight Distribution')
            xlabel(app.WeightDisplay, 'Range [NM]')
            ylabel(app.WeightDisplay, 'Weight [kg]')
            % Show the figure after all components are created
            app.FMCMatlabUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FMCApp_Airbus_Performance

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FMCMatlabUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FMCMatlabUIFigure)
        end
    end
end