classdef fm_tool_V0_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FlightManagementToolTUBerlinFlightGuidanceandAirTrafficUIFigure  matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        ARRDEPTab                  matlab.ui.container.Tab
        LoadButton                 matlab.ui.control.Button
        DeparturePanel             matlab.ui.container.Panel
        TakeoffDropDownLabel       matlab.ui.control.Label
        TakeoffDropDown            matlab.ui.control.DropDown
        SIDDropDownLabel           matlab.ui.control.Label
        SIDDropDown                matlab.ui.control.DropDown
        ArrivalPanel               matlab.ui.container.Panel
        ApproachDropDownLabel      matlab.ui.control.Label
        ApproachDropDown           matlab.ui.control.DropDown
        TransitionDropDownLabel    matlab.ui.control.Label
        TransitionDropDown         matlab.ui.control.DropDown
        STARDropDownLabel          matlab.ui.control.Label
        STARDropDown               matlab.ui.control.DropDown
        FlightPlanButton           matlab.ui.control.Button
        FlghtplanListBoxLabel      matlab.ui.control.Label
        FlghtplanListBox           matlab.ui.control.ListBox
        OriginEditFieldLabel       matlab.ui.control.Label
        OriginEditField            matlab.ui.control.EditField
        DestinationEditFieldLabel  matlab.ui.control.Label
        DestinationEditField       matlab.ui.control.EditField
        FlightplanPlot             matlab.ui.control.UIAxes
        Button_3                   matlab.ui.control.Button
        EnroutePanel               matlab.ui.container.Panel
        Image                      matlab.ui.control.Image
        Image2                     matlab.ui.control.Image
        TRAJECTORYTab              matlab.ui.container.Tab
        LateralTrajectoryPlot      matlab.ui.control.UIAxes
        VerticalTrajectoryPlot     matlab.ui.control.UIAxes
        GenerateLateralTrajectoryButton  matlab.ui.control.Button
        Image_2                    matlab.ui.control.Image
        Image2_2                   matlab.ui.control.Image
        ACTRTELEGSTab              matlab.ui.container.Tab
        RTELEGSTextArea            matlab.ui.control.TextArea
        RefreshButton              matlab.ui.control.Button
        PERFINITTab                matlab.ui.container.Tab
        CostIndexPanel             matlab.ui.container.Panel
        ZFWEditFieldLabel_4        matlab.ui.control.Label
        ZFWEditField_4             matlab.ui.control.NumericEditField
        AircraftModelPanel         matlab.ui.container.Panel
        AircraftLabel              matlab.ui.control.Label
        AircraftDropDown           matlab.ui.control.DropDown
        LoadPerformanceDataButton  matlab.ui.control.Button
        WeightandBalancePanel      matlab.ui.container.Panel
        EmptyweightEditFieldLabel  matlab.ui.control.Label
        EmptyweightEditField       matlab.ui.control.NumericEditField
        tonnesLabel                matlab.ui.control.Label
        GrossweightEditFieldLabel  matlab.ui.control.Label
        GrossweightEditField       matlab.ui.control.NumericEditField
        ZFWEditFieldLabel          matlab.ui.control.Label
        ZFWEditField               matlab.ui.control.NumericEditField
        tonnesLabel_2              matlab.ui.control.Label
        tonnesLabel_3              matlab.ui.control.Label
        tonnesLabel_4              matlab.ui.control.Label
        FuelEditFieldLabel         matlab.ui.control.Label
        FuelEditField              matlab.ui.control.NumericEditField
        ZFWEditFieldLabel_2        matlab.ui.control.Label
        ZFWEditField_2             matlab.ui.control.NumericEditField
        tonnesLabel_5              matlab.ui.control.Label
        CruisingAltitudePanel      matlab.ui.container.Panel
        ZFWEditFieldLabel_3        matlab.ui.control.Label
        ZFWEditField_3             matlab.ui.control.NumericEditField
        TAKEOFFREFTab              matlab.ui.container.Tab
        VNAVTab                    matlab.ui.container.Tab
        Tab                        matlab.ui.container.Tab
    end

    
    properties (Access = private)
        approaches
        transitions
        stars
        arrival_rwys % Description
        app_types_full
        
        departure_rwys
        
        sids_rwys
        approach_wpts
        star_wpts
        transition_wpts
        rwy_wpts
        
        sids
        sid_wpts
        origin_filename
        destination_filename
        apptr_name
        apptr_rwys
        apptr_types
        
        rwys_llh
        table_fixes
        table_navaids
        
        % Flight plan
        all_wpts
        flight_plan
        
        %   Aircraft model
        aircraft_bada
        ac
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupActions(app)
            
            %   Get tables with navaids and fixes
            app.table_fixes = readtable('Waypoints.txt');
            app.table_navaids = readtable('Navaids.txt');
            
            %   Add the paths to teh subdirectories
            addpath Geodesics Tools_GUI Tools_AC
            
            %   Load the aircraft for which BADA performance files are available
            app.aircraft_bada = load_aircraft('Aircraft');
            
            app.AircraftDropDown.Items = app.aircraft_bada;
        end

        % Button pushed function: LoadButton
        function Load_SID_STAR_Final_Trans(app, event)
            
            % Make filenames
            app.origin_filename = ['Airports' filesep app.OriginEditField.Value '.txt'];
            app.destination_filename = ['Airports' filesep app.DestinationEditField.Value '.txt'];
            
            % Load the arrival trajectory
            [app.approaches, app.transitions, app.stars] = load_arrivals(app.destination_filename); 
            
            % Load the arrival trajectory
            [app.departure_rwys, app.sids_rwys, app.sids, app.rwys_llh] = load_departures(app.origin_filename); 
            
            % Add all takeoffs to the dropbox
            app.TakeoffDropDown.Items = app.departure_rwys;
            
            %   Find all SIDs that support this departure runway
            ind = find(contains(app.sids_rwys,{app.TakeoffDropDown.Value, 'ALL'}));
            
            %   Show the unique SIDs
            app.SIDDropDown.Items = unique(app.sids(ind));
            
            fprintf('Changed takeoff to: %s\n', app.TakeoffDropDown.Value);
            
            % Add all approaches to the dropbox
            app.ApproachDropDown.Items = app.approaches.full;
            
            %   Find all transitions that support this approach runway
            ii = find(contains(app.ApproachDropDown.Items,app.ApproachDropDown.Value));
            ind = find(contains(app.transitions.types,{app.approaches.types{ii}}));
            ind = [1 ind];
             
            % Add all transitions to the dropbox
            app.TransitionDropDown.Items = unique(app.transitions.name(ind));
            
            %   Find all transitions that support this approach runway
            ii = find(contains(app.ApproachDropDown.Items,app.ApproachDropDown.Value));
            ind = find(contains(app.stars.rwys,{app.approaches.rwys{ii}}));
            ind = [1 ind];
            
            % Add all stars to the dropbox
            app.STARDropDown.Items = unique(app.stars.name(ind));
            
        end

        % Value changed function: ApproachDropDown
        function ApproachDropDownValueChanged(app, event)
            value = app.ApproachDropDown.Value;
            
            fprintf('Changed approach to: %s\n', value);
            
            %   Find all transitions that support this approach runway
            ii = find(contains(app.ApproachDropDown.Items,value));
            ind = find(contains(app.transitions.types,{app.approaches.types{ii}}));
            ind = [1 ind];
            
            % Add all transitions to the dropbox
            app.TransitionDropDown.Items = unique(app.transitions.name(ind));
            
            %   Find all transitions that support this approach runway
            ii = find(contains(app.ApproachDropDown.Items,value));
            ind = find(contains(app.stars.rwys,{app.approaches.rwys{ii}}));
            ind = [1 ind];
            
            % Add all stars to the dropbox
            app.STARDropDown.Items = unique(app.stars.name(ind));
            
        end

        % Value changed function: TakeoffDropDown
        function TakeoffDropDownValueChanged(app, event)
            
            value = app.TakeoffDropDown.Value;
            
            %   Find all SIDs that support this departure runway
            ind = find(contains(app.sids_rwys,{value, 'ALL'}));
            
            %   Show the unique SIDs
            app.SIDDropDown.Items = unique(app.sids(ind));
            
            fprintf('Changed takeoff to: %s\n', value);
   
        end

        % Callback function
        function ShowSIDs(app, event)
  
        end

        % Button pushed function: Button_3
        function PlotFlightPlan(app, event)
            
            %   Concatenate the waypoints
            lat = [cell2mat(app.sid_wpts.lat) cell2mat(app.star_wpts.lat) cell2mat(app.transition_wpts.lat) cell2mat(app.approach_wpts.lat)];
            lon = [cell2mat(app.sid_wpts.lon) cell2mat(app.star_wpts.lon) cell2mat(app.transition_wpts.lon) cell2mat(app.approach_wpts.lon)];
            
            plot(app.FlightplanPlot,lon,lat,'*');
            hold(app.FlightplanPlot,"on") 
            plot(app.FlightplanPlot,lon,lat)
            hold(app.FlightplanPlot,"off")
            axis(app.FlightplanPlot,"equal") 
            v = axis(app.FlightplanPlot);
        end

        % Value changed function: SIDDropDown
        function SIDDropDownValueChanged(app, event)
            value = app.SIDDropDown.Value;
            
            fprintf('Changed SID to: %s\n', value);  
        end

        % Button pushed function: FlightPlanButton
        function ShowFlightplan(app, event)
            
            %   Extract the SID-related waypoints
            [app.sid_wpts] = load_sid_waypoints(app.origin_filename, app.SIDDropDown.Value, app.TakeoffDropDown.Value);
            
            %   Extract the APPROACH-related waypoints
            ii = find(contains(app.ApproachDropDown.Items,app.ApproachDropDown.Value));
            [app.approach_wpts] = load_approach_waypoints(app.destination_filename, app.approaches.types{ii});
            
            %   Extract the TRANSITION-related waypoints
            [app.transition_wpts] = load_transition_waypoints(app.destination_filename, app.TransitionDropDown.Value, app.approaches.types{ii});
            
            %   Extract the STAR-related waypoints
            [app.star_wpts] = load_star_waypoints(app.destination_filename, app.STARDropDown.Value, app.approaches.rwys{ii});
            
            %   Concatenate the lines so youcan show them
            alldashes{1} = '------------------------------------------------';
            all_lines = {app.sid_wpts.line alldashes app.star_wpts.line alldashes app.transition_wpts.line alldashes app.approach_wpts.line};
            app.FlghtplanListBox.Items = cat(2,all_lines{:});
            
            %   Obtain take-off runway as a first point 
            [app.rwy_wpts] = generate_takeoff_rwy_wpt(app.TakeoffDropDown.Value, app.departure_rwys, app.rwys_llh);
            
            %   Combine all waypoints
            app.all_wpts = combine_wpt_structs(app.rwy_wpts,app.sid_wpts);
            app.all_wpts = combine_wpt_structs(app.all_wpts, app.star_wpts);
            app.all_wpts = combine_wpt_structs(app.all_wpts, app.transition_wpts);
            app.all_wpts = combine_wpt_structs(app.all_wpts, app.approach_wpts);
            
            %   Get rid off duplicates
            app.all_wpts = remove_duplicates(app.all_wpts);
            
            %   Show the active route
%             [act_rte_legs] = generate_active_rte_legs(app.all_wpts);
%             app.RTELEGSTextArea.Value = act_rte_legs;
          
        end

        % Value changed function: TransitionDropDown
        function TransitionDropDownValueChanged(app, event)
            value = app.TransitionDropDown.Value;
            
            fprintf('Changed Transition to: %s\n', value);  
        end

        % Button pushed function: GenerateLateralTrajectoryButton
        function generate_lateral_trajectory(app, event)
            
            %   Get the latitudes and longitude for the lateral trajectory
            [lat_wpts, lon_wpts, lat, lon] = process_lateral_trajectory(app.all_wpts);
            
            [app.all_wpts, lat, lon] = process_lateral_profile(app.all_wpts)
            
            plot(app.LateralTrajectoryPlot,lon_wpts,lat_wpts, 'b*');
            hold(app.LateralTrajectoryPlot,"on")
            plot(app.LateralTrajectoryPlot,lon,lat,'m', 'LineWidth',2)
            hold(app.LateralTrajectoryPlot,"off")

            %   Show the updated active route
            [act_rte_legs] = generate_active_rte_legs(app.all_wpts);
            app.RTELEGSTextArea.Value = act_rte_legs;

        end

        % Button pushed function: LoadPerformanceDataButton
        function LoadPerfData(app, event)
            
            %   Load all the data for the selected aicraft
            filename = ['Aircraft' filesep app.AircraftDropDown.Value '__.OPF'];
            app.ac = load_aircraft_bada(filename);
            
            %   Load all the default values
            app.EmptyweightEditField.Value = app.ac.mass.m_min/1000.0;
            
        end

        % Button pushed function: RefreshButton
        function RefreshAction(app, event)
            %   Show the updated active route
            [act_rte_legs] = generate_active_rte_legs(app.all_wpts);
            app.RTELEGSTextArea.Value = act_rte_legs;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FlightManagementToolTUBerlinFlightGuidanceandAirTrafficUIFigure = uifigure('Visible', 'off');
            app.FlightManagementToolTUBerlinFlightGuidanceandAirTrafficUIFigure.Name = 'Flight Management Tool - TU Berlin - Flight Guidance and Air Traffic - V0.4';
            app.TabGroup = uitabgroup(app.FlightManagementToolTUBerlinFlightGuidanceandAirTrafficUIFigure);
            app.ARRDEPTab = uitab(app.TabGroup);
            app.ARRDEPTab.Title = 'ARR/DEP';
            app.LoadButton = uibutton(app.ARRDEPTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @Load_SID_STAR_Final_Trans, true);
            app.LoadButton.Text = 'Load';
            app.DeparturePanel = uipanel(app.ARRDEPTab);
            app.DeparturePanel.Title = 'Departure';
            app.TakeoffDropDownLabel = uilabel(app.DeparturePanel);
            app.TakeoffDropDownLabel.Text = 'Takeoff';
            app.TakeoffDropDown = uidropdown(app.DeparturePanel);
            app.TakeoffDropDown.ValueChangedFcn = createCallbackFcn(app, @TakeoffDropDownValueChanged, true);
            app.SIDDropDownLabel = uilabel(app.DeparturePanel);
            app.SIDDropDownLabel.Text = 'SID';
            app.SIDDropDown = uidropdown(app.DeparturePanel);
            app.SIDDropDown.ValueChangedFcn = createCallbackFcn(app, @SIDDropDownValueChanged, true);
            app.ArrivalPanel = uipanel(app.ARRDEPTab);
            app.ArrivalPanel.Title = 'Arrival';
            app.ApproachDropDownLabel = uilabel(app.ArrivalPanel);
            app.ApproachDropDownLabel.Text = 'Approach';
            app.ApproachDropDown = uidropdown(app.ArrivalPanel);
            app.ApproachDropDown.ValueChangedFcn = createCallbackFcn(app, @ApproachDropDownValueChanged, true);
            app.TransitionDropDownLabel = uilabel(app.ArrivalPanel);
            app.TransitionDropDownLabel.Text = 'Transition';
            app.TransitionDropDown = uidropdown(app.ArrivalPanel);
            app.TransitionDropDown.ValueChangedFcn = createCallbackFcn(app, @TransitionDropDownValueChanged, true);
            app.STARDropDownLabel = uilabel(app.ArrivalPanel);
            app.STARDropDownLabel.Text = 'STAR';
            app.STARDropDown = uidropdown(app.ArrivalPanel);
            app.FlightPlanButton = uibutton(app.ARRDEPTab, 'push');
            app.FlightPlanButton.ButtonPushedFcn = createCallbackFcn(app, @ShowFlightplan, true);
            app.FlightPlanButton.Text = '-->';
            app.FlghtplanListBoxLabel = uilabel(app.ARRDEPTab);
            app.FlghtplanListBoxLabel.Text = '';
            app.FlghtplanListBox = uilistbox(app.ARRDEPTab);
            app.OriginEditFieldLabel = uilabel(app.ARRDEPTab);
            app.OriginEditFieldLabel.Text = 'Origin';
            app.OriginEditField = uieditfield(app.ARRDEPTab, 'text');
            app.DestinationEditFieldLabel = uilabel(app.ARRDEPTab);
            app.DestinationEditFieldLabel.Text = 'Destination';
            app.DestinationEditField = uieditfield(app.ARRDEPTab, 'text');
            app.FlightplanPlot = uiaxes(app.ARRDEPTab);
            title(app.FlightplanPlot, 'Flightplan')
            xlabel(app.FlightplanPlot, 'Longitude [deg]')
            ylabel(app.FlightplanPlot, 'Latitude [deg]')
            app.Button_3 = uibutton(app.ARRDEPTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @PlotFlightPlan, true);
            app.Button_3.Text = '-->';
            app.EnroutePanel = uipanel(app.ARRDEPTab);
            app.EnroutePanel.Title = 'En-route';
            app.Image = uiimage(app.ARRDEPTab);
            app.Image.ImageSource = 'tub_logo.png';
            app.Image2 = uiimage(app.ARRDEPTab);
            app.Image2.ImageSource = 'csm_ff_logo_81858593cd.png';
            app.TRAJECTORYTab = uitab(app.TabGroup);
            app.TRAJECTORYTab.Title = 'TRAJECTORY';
            app.LateralTrajectoryPlot = uiaxes(app.TRAJECTORYTab);
            title(app.LateralTrajectoryPlot, 'Lateral Flight Trajectory')
            xlabel(app.LateralTrajectoryPlot, 'Longitude [deg]')
            ylabel(app.LateralTrajectoryPlot, 'Latitude [deg]')
            app.VerticalTrajectoryPlot = uiaxes(app.TRAJECTORYTab);
            title(app.VerticalTrajectoryPlot, 'Vertical Flight Trajectory')
            xlabel(app.VerticalTrajectoryPlot, 'Range [NM]')
            ylabel(app.VerticalTrajectoryPlot, 'Height [ft]')
            app.GenerateLateralTrajectoryButton = uibutton(app.TRAJECTORYTab, 'push');
            app.GenerateLateralTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @generate_lateral_trajectory, true);
            app.GenerateLateralTrajectoryButton.Text = 'Generate Lateral Trajectory';
            app.Image_2 = uiimage(app.TRAJECTORYTab);
            app.Image_2.ImageSource = 'tub_logo.png';
            app.Image2_2 = uiimage(app.TRAJECTORYTab);
            app.Image2_2.ImageSource = 'csm_ff_logo_81858593cd.png';
            app.ACTRTELEGSTab = uitab(app.TabGroup);
            app.ACTRTELEGSTab.Title = 'ACT RTE LEGS';
            app.RTELEGSTextArea = uitextarea(app.ACTRTELEGSTab);
            app.RefreshButton = uibutton(app.ACTRTELEGSTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshAction, true);
            app.RefreshButton.Text = 'Refresh';
            app.PERFINITTab = uitab(app.TabGroup);
            app.PERFINITTab.Title = 'PERF INIT';
            app.CostIndexPanel = uipanel(app.PERFINITTab);
            app.CostIndexPanel.Title = 'Cost Index';
            app.ZFWEditFieldLabel_4 = uilabel(app.CostIndexPanel);
            app.ZFWEditFieldLabel_4.Text = 'C/I';
            app.ZFWEditField_4 = uieditfield(app.CostIndexPanel, 'numeric');
            app.AircraftModelPanel = uipanel(app.PERFINITTab);
            app.AircraftModelPanel.Title = 'Aircraft Model';
            app.AircraftLabel = uilabel(app.AircraftModelPanel);
            app.AircraftLabel.Text = 'Aircraft';
            app.AircraftDropDown = uidropdown(app.AircraftModelPanel);
            app.LoadPerformanceDataButton = uibutton(app.AircraftModelPanel, 'push');
            app.LoadPerformanceDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPerfData, true);
            app.LoadPerformanceDataButton.Text = 'Load Performance Data';
            app.WeightandBalancePanel = uipanel(app.PERFINITTab);
            app.WeightandBalancePanel.Title = 'Weight and Balance';
            app.EmptyweightEditFieldLabel = uilabel(app.WeightandBalancePanel);
            app.EmptyweightEditFieldLabel.Text = 'Empty weight';
            app.EmptyweightEditField = uieditfield(app.WeightandBalancePanel, 'numeric');
            app.EmptyweightEditField.Editable = 'off';
            app.tonnesLabel = uilabel(app.WeightandBalancePanel);
            app.tonnesLabel.Text = 'tonnes';
            app.GrossweightEditFieldLabel = uilabel(app.WeightandBalancePanel);
            app.GrossweightEditFieldLabel.Text = 'Gross weight';
            app.GrossweightEditField = uieditfield(app.WeightandBalancePanel, 'numeric');
            app.ZFWEditFieldLabel = uilabel(app.WeightandBalancePanel);
            app.ZFWEditFieldLabel.Text = 'ZFW';
            app.ZFWEditField = uieditfield(app.WeightandBalancePanel, 'numeric');
            app.tonnesLabel_2 = uilabel(app.WeightandBalancePanel);
            app.tonnesLabel_2.Text = 'tonnes';
            app.tonnesLabel_3 = uilabel(app.WeightandBalancePanel);
            app.tonnesLabel_3.Text = 'tonnes';
            app.tonnesLabel_4 = uilabel(app.WeightandBalancePanel);
            app.tonnesLabel_4.Text = 'tonnes';
            app.FuelEditFieldLabel = uilabel(app.WeightandBalancePanel);
            app.FuelEditFieldLabel.Text = 'Fuel';
            app.FuelEditField = uieditfield(app.WeightandBalancePanel, 'numeric');
            app.ZFWEditFieldLabel_2 = uilabel(app.WeightandBalancePanel);
            app.ZFWEditFieldLabel_2.Text = 'Reserves';
            app.ZFWEditField_2 = uieditfield(app.WeightandBalancePanel, 'numeric');
            app.tonnesLabel_5 = uilabel(app.WeightandBalancePanel);
            app.tonnesLabel_5.Text = 'tonnes';
            app.CruisingAltitudePanel = uipanel(app.PERFINITTab);
            app.CruisingAltitudePanel.Title = 'Cruising Altitude';
            app.ZFWEditFieldLabel_3 = uilabel(app.CruisingAltitudePanel);
            app.ZFWEditFieldLabel_3.Text = 'CRZ ALT';
            app.ZFWEditField_3 = uieditfield(app.CruisingAltitudePanel, 'numeric');
            app.ZFWEditField_3.ValueDisplayFormat = '%.0f';
            app.TAKEOFFREFTab = uitab(app.TabGroup);
            app.TAKEOFFREFTab.Title = 'TAKEOFF REF';
            app.VNAVTab = uitab(app.TabGroup);
            app.VNAVTab.Title = 'VNAV';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            % Show the figure after all components are created
            app.FlightManagementToolTUBerlinFlightGuidanceandAirTrafficUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fm_tool_V0_4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FlightManagementToolTUBerlinFlightGuidanceandAirTrafficUIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupActions)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FlightManagementToolTUBerlinFlightGuidanceandAirTrafficUIFigure)
        end
    end
end